classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        toolGrid                        matlab.ui.container.GridLayout
        tool_nRowsGrid                  matlab.ui.container.GridLayout
        tool_resetButton                matlab.ui.control.Image
        tool_nRowsIcon                  matlab.ui.control.Image
        tool_nRows                      matlab.ui.control.Label
        tool_SelectedEmision            matlab.ui.control.Label
        tool_Serarator2                 matlab.ui.control.Image
        tool_mapPlotSize                matlab.ui.control.Slider
        tool_DensityButton              matlab.ui.control.Image
        tool_ScatterButton              matlab.ui.control.Image
        tool_Serarator1                 matlab.ui.control.Image
        tool_ExportButton               matlab.ui.control.Button
        Document                        matlab.ui.container.GridLayout
        axesPanel                       matlab.ui.container.Panel
        LeftPanelVisibility             matlab.ui.control.Image
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        Tab4_Panel                      matlab.ui.container.GridLayout
        layout_cartesianPlotPanel       matlab.ui.container.Panel
        layout_cartesianPlotGrid        matlab.ui.container.GridLayout
        layout_cartesianPlotSubPanel    matlab.ui.container.Panel
        layout_cartesianPlotSubGrid     matlab.ui.container.GridLayout
        chPowerSource_smoothedPanel     matlab.ui.container.Panel
        Smoothed_Grid                   matlab.ui.container.GridLayout
        Smoothed_Factor                 matlab.ui.control.Spinner
        Smoothed_FactorLabel            matlab.ui.control.Label
        Smoothed_Type                   matlab.ui.control.DropDown
        Smoothed_TypeLabel              matlab.ui.control.Label
        chPowerSource_smoothed          matlab.ui.control.CheckBox
        chPowerSource_binTable          matlab.ui.control.CheckBox
        chPowerSource_rawTable          matlab.ui.control.CheckBox
        layout_cartesianPlotLabel       matlab.ui.control.Label
        layout_cartesianPlotTitle       matlab.ui.control.Label
        layout_mapPlotPanel             matlab.ui.container.Panel
        layout_mapPlotGrid              matlab.ui.container.GridLayout
        mapPlot_Panel11                 matlab.ui.container.Panel
        mapPlot_Grid11                  matlab.ui.container.GridLayout
        Colormap                        matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        mapPlot_Property1Panel          matlab.ui.container.Panel
        mapPlot_Property1Grid           matlab.ui.container.GridLayout
        mapPlot_size1                   matlab.ui.control.Slider
        mapPlot_color1                  matlab.ui.control.Button
        mapPlot_Type                    matlab.ui.control.DropDown
        imgRoute_TypeLabel_2            matlab.ui.control.Label
        imgRoute_Panel                  matlab.ui.container.Panel
        imgRoute_Grid                   matlab.ui.container.GridLayout
        imgRoute_Size                   matlab.ui.control.Slider
        imgRoute_Color2                 matlab.ui.control.Button
        imgRoute_Color1                 matlab.ui.control.Button
        imgRoute_Type                   matlab.ui.control.DropDown
        imgRoute_TypeLabel              matlab.ui.control.Label
        mapPlot_Subtitle11              matlab.ui.control.Label
        layout_mapPlotSourcePanel       matlab.ui.container.ButtonGroup
        layout_mapPlotSource_binData    matlab.ui.control.RadioButton
        layout_mapPlotSource_rawData    matlab.ui.control.RadioButton
        layout_mapPlotSourceLabel       matlab.ui.control.Label
        layout_mapPlotLock              matlab.ui.control.Image
        layout_mapPlotLabel             matlab.ui.control.Label
        Tab3_Panel                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        binTable_Fcn                    matlab.ui.control.DropDown
        binTable_FcnLabel               matlab.ui.control.Label
        binTable_Factor                 matlab.ui.control.Spinner
        binTable_FactorLabel            matlab.ui.control.Label
        binTable_Grid                   matlab.ui.container.GridLayout
        binTable_Info                   matlab.ui.control.Label
        binTable_Col4                   matlab.ui.control.Label
        binTable_Col3                   matlab.ui.control.Label
        binTable_Col2                   matlab.ui.control.Label
        binTable_Col1                   matlab.ui.control.Label
        binTable_UITable                matlab.ui.control.Table
        binTable_Type                   matlab.ui.control.DropDown
        binTable_TypeLabel              matlab.ui.control.Label
        Tab2_Panel                      matlab.ui.container.GridLayout
        play_FindPeaks_add              matlab.ui.control.Image
        points_TreeLabel                matlab.ui.control.Label
        play_FindPeaks_ParametersPanel  matlab.ui.container.Panel
        play_FindPeaks_ParametersGrid   matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        play_FindPeaks_Trace_2          matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel_2     matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_RadioGroup       matlab.ui.container.ButtonGroup
        play_FindPeaks_ROI              matlab.ui.control.RadioButton
        play_FindPeaks_auto             matlab.ui.control.RadioButton
        points_Tree                     matlab.ui.container.Tree
        Tab1_Panel                      matlab.ui.container.GridLayout
        filter_Tree                     matlab.ui.container.Tree
        filter_addImageGrid             matlab.ui.container.GridLayout
        filter_addImage                 matlab.ui.control.Image
        filter_roiOptions               matlab.ui.container.Tree
        levelFilterNode                 matlab.ui.container.TreeNode
        levelFilter_thr                 matlab.ui.container.TreeNode
        geoFilterNode                   matlab.ui.container.TreeNode
        geoFilter_Circle                matlab.ui.container.TreeNode
        geoFilter_Rectangle             matlab.ui.container.TreeNode
        geoFilter_Polygon               matlab.ui.container.TreeNode
        filter_TreeLabel                matlab.ui.control.Label
        taskMetaData                    matlab.ui.control.TextArea
        emissionsList                   matlab.ui.control.DropDown
        emissionListGrid                matlab.ui.container.GridLayout
        emissionListRefresh             matlab.ui.control.Image
        emissionListLabel               matlab.ui.control.Label
        menu_MainGrid                   matlab.ui.container.GridLayout
        menu_Button4Grid                matlab.ui.container.GridLayout
        menu_Button4Icon                matlab.ui.control.Image
        menu_Button4Label               matlab.ui.control.Label
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menu_Image                      matlab.ui.control.Image
        filter_ContextMenu              matlab.ui.container.ContextMenu
        filter_delButton                matlab.ui.container.Menu
        filter_delAllButton             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        General = struct('hCar', struct('marker', 's', 'color', [1,0,0], 'size', 5))
        
        specData
        Index
        
        specTable                                                           % General table (instantaneous channel power)
        filterTable = table('Size', [0, 4],                                      ...
                            'VariableTypes', {'cell', 'cell', 'cell', 'double'}, ...
                            'VariableNames', {'type', 'subtype', 'handle', 'enable'})
        
        filtTable                                                           % Filtered specData
        binTable                                                            % Summarized filtTable (Data Binning Criterium)

        UIAxes1
        hRoute_inROI
        hRoute_outROI
        hCar
        hScatter
        hDensity

        UIAxes2
        hChannelPower
        hSmoothedData
        hPowerLabel
    end
    

    properties (Access = public)
        %-----------------------------------------------------------------%
        hTimeStamp
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function Fcn1_AxesCreation(app)
            t = tiledlayout(app.axesPanel, 5, 1, "Padding", "tight");
            
            % Eixo geográfico (MAPA)
            app.UIAxes1 = geoaxes(t, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater', 'Box', 'off');
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [4 1];
            app.UIAxes1.LatitudeLabel.String  = '';
            app.UIAxes1.LongitudeLabel.String = '';
            colormap(app.UIAxes1, app.Colormap.Value)
            hold(app.UIAxes1, 'on')

            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            % Eixo cartesiano
            app.UIAxes2 = uiaxes(t);
            app.UIAxes2.Layout.Tile = 5;
            xlabel(app.UIAxes2, 'Amostra')
            ylabel(app.UIAxes2, 'Potência canal (dBm)')

            set(app.UIAxes2, Color         = [0 0 0],     ...
                             ClippingStyle = 'rectangle', ...
                             XGrid         = 'on',        ...
                             XMinorGrid    = 'on',        ...
                             YGrid         = 'on',        ...
                             YMinorGrid    = 'on',        ...
                             Interactions  = [],          ...
                             Toolbar       = [],          ...
                             FontSize      = 9,           ...
                             GridColor     = [0.9412 0.9412 0.9412])

            startup_AxesInteractions(app)
        end


        %-----------------------------------------------------------------%
        function startup_AxesInteractions(app)
            t = axtoolbar(app.UIAxes1, "default");
            delete(findobj(t, Tag='export'))
            addToolbarMapButton(t, "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

            app.UIAxes2.Interactions = dataTipInteraction;

            enableDefaultInteractivity(app.UIAxes1)
            enableDefaultInteractivity(app.UIAxes2)
        end
        

        %-----------------------------------------------------------------%
        function Fcn2_General(app)
            Fcn2_General_Emissions(app)
            Fcn2_General_MetaData(app)
            Fcn2_General_AxesTitle(app)
            Fcn2_General_specTable(app)

            expand(app.filter_roiOptions, 'all')

            app.mapPlot_Type.Value             = app.General.hCar.marker;
            app.mapPlot_color1.BackgroundColor = app.General.hCar.color;
            app.mapPlot_size1.Value            = app.General.hCar.size;
        end


        %-----------------------------------------------------------------%
        function Fcn2_General_Emissions(app)
            emissionTable = app.specData.UserData.Emissions;

            if isempty(emissionTable)
                error('Tabela de emissões está vazia!')
            end
            
            app.emissionsList.Items = {};
            for ii = 1:height(emissionTable)
                app.emissionsList.Items{end+1} = sprintf('%d: %.3f MHz ⌂ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

            try
                app.emissionsList.Value = app.emissionsList.Items{app.Index(2)};
            catch
            end
        end


        %-----------------------------------------------------------------%
        function Fcn2_General_MetaData(app)
            metaData = '';
            for ii = 1:height(app.specData.RelatedFiles)
                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(ii), 'dd/mm/yyyy HH:MM:SS'), ...
                                                     datestr(app.specData.RelatedFiles.EndTime(ii),   'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita médio)\n\n'], ...
                                    metaData,                              ...
                                    app.specData.RelatedFiles.File{ii},    ...
                                    ObservationTime,                       ...
                                    app.specData.RelatedFiles.nSweeps(ii), ...
                                    app.specData.RelatedFiles.RevisitTime(ii));
            end

            app.taskMetaData.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'      ...
                                      'Pontos por traço: %.0f\n'               ...
                                      'Unidade: %s\n'                          ...
                                      'Resolução: %.3f kHz\n\n%s'],            ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.LevelUnit,         ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);
        end


        %-----------------------------------------------------------------%
        function Fcn2_General_AxesTitle(app)
            app.tool_SelectedEmision.Text = sprintf('%s\nID %d: %.3f - %.3f MHz @ %s', app.specData.Receiver,                 ...
                                                                                       app.specData.RelatedFiles.ID(1),       ...
                                                                                       app.specData.MetaData.FreqStart/1e+6,  ...
                                                                                       app.specData.MetaData.FreqStop /1e+6,  ...
                                                                                       extractAfter(app.emissionsList.Value, ': '));
        end
       

        %-----------------------------------------------------------------%
        function Fcn2_General_specTable(app)
            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:height(app.specData.RelatedFiles)
                Lat  = [Lat;  app.specData.RelatedFiles.GPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedFiles.GPS{ii}.Matrix(:,2)];
            end
            
            chPower = Fcn4_chPower(app);
            app.specTable = table(timestamp, Lat, Long, chPower);
        end


        %-----------------------------------------------------------------%
        function Fcn2_General_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            if ~isempty(app.filterTable)
                for ii = 1:height(app.filterTable)
                    uitreenode(app.filter_Tree, 'Text', app.filterTable.subtype{ii}, 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                end

                set(app.filter_Tree, 'SelectedNodes', app.filter_Tree.Children(end), 'Enable', 1)
                filter_Tree_SelectionChanged(app)

                set(app.filter_ContextMenu.Children, Enable=1)
            else
                app.filter_Tree.Enable = 1;
                set(app.filter_ContextMenu.Children, Enable=0)
            end

            if isempty(find(app.filterTable.subtype == "THRESHOLD", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.filter_roiOptions, s, 'node', app.levelFilter_thr)
        end


        %-----------------------------------------------------------------%
        function Fcn3_updateInfo(app)
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            Fcn4_geoTables(app)
            Fcn5_UITable(app)
            Fcn6_Plots(app)

            delete(d)
        end
        
        
        %-----------------------------------------------------------------%
        function Fcn4_geoTables(app)
            Fcn4_filtTable(app)
            Fcn4_binTable(app)
        end
        

        %-----------------------------------------------------------------%
        function Fcn4_filtTable(app)
            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.filterTable.type == "Level");
            idx2 = find(app.filterTable.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.handle{ii}.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.handle{ii}.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não foi obtida nenhuma amostra ao aplicar o filtro. Por essa razão, esse filtro será excluído automaticamente.');
                app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                filter_delFilter(app, struct('Source', app.filter_delButton))

                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            [app.filtTable.LatEq, app.filtTable.LongEq] = grn2eqa(app.filtTable.Lat, app.filtTable.Long);

            app.filtTable.FK1 = find(idy);
            app.filtTable.FK2 = -1*ones(height(app.filtTable), 1, 'int32');

            NN = sum(idy);
            MM = height(app.specTable);
            app.tool_nRows.Text = sprintf('%d de %d varreduras\n%.1f %%', NN, MM, (NN/MM)*100);

            if ~isempty(idx1)
                app.filterTable.handle{idx1}.Position(2) = height(app.filtTable);
            end
        end


        %-----------------------------------------------------------------%
        function Fcn4_binTable(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    [binLat, binLong, binCount] = hista(app.filtTable.Lat, app.filtTable.Long, (app.binTable_Factor.Value/1000)^2);            
                    [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
                    PK2 = int32(1:numel(binLat))';
                    
                    dist = pdist2([app.filtTable.LongEq, app.filtTable.LatEq], [binLongEq, binLatEq]);            
                    [~, app.filtTable.FK2] = min(dist, [], 2);
                    binPower = Fcn4_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Lat),  '\n')';
                    lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Long), '\n')';
                    latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));
        
                    [latlong_String, latlong_Index, app.filtTable.FK2] = unique(latlong, 'stable');
                    
                    PK2      = int32(1:numel(latlong_String))';
                    binLat   = round(app.filtTable.Lat(latlong_Index),  app.binTable_Factor.Value);
                    binLong  = round(app.filtTable.Long(latlong_Index), app.binTable_Factor.Value);
                    binCount = accumarray(app.filtTable.FK2, 1);
                    binPower = Fcn4_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);
            end
        end


        %-----------------------------------------------------------------%
        function chPower = Fcn4_chPower(app)
            idx = find(strcmp(app.emissionsList.Items, app.emissionsList.Value), 1);
            FreqCenter = app.specData.UserData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.UserData.Emissions.BW(idx)        * 1e+3;

            chPower = fcn.chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);
        end


        %-----------------------------------------------------------------%
        function binPower = Fcn4_binPower(app)
            switch app.binTable_Fcn.Value
                case 'Mínima';  binFcn = @min;
                case 'Média';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'Mediana'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  binFcn = @max;
            end
            binPower = splitapply(binFcn, app.filtTable.chPower, app.filtTable.FK2);
        end


        %-----------------------------------------------------------------%
        function Fcn5_UITable(app)
            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])            

            % Identificar as distâncias entre pontos adjacentes (ajustados
            % no grid definido pelo número das casas decimais da latitude e
            % longitude)
            locIndex = app.filtTable.FK2(1);
            for ii = 1:height(app.filtTable)
                if locIndex(end) ~= app.filtTable.FK2(ii)
                    locIndex(end+1,1) = app.filtTable.FK2(ii);
                end
            end

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            if numel(locIndex) >= 2
                ptDist = deg2km(distance('gc', [app.binTable.binLat(locIndex(1:end-1)), app.binTable.binLong(locIndex(1:end-1))], ...
                                               [app.binTable.binLat(locIndex(2:end)),   app.binTable.binLong(locIndex(2:end))]));
                [ptDist_min, ptDist_max] = bounds(ptDist*1000);
                ptDist_mean = mean(ptDist)*1000; 
    
                % Sumário:            
                auxString0 = sprintf('%.0f pontos', height(app.binTable));
                auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
                auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);
    
                app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);
            else
                app.binTable_Info.Text = '';
            end
        end


        %-----------------------------------------------------------------%
        function Fcn6_Plots(app)
            Fcn6_Plot_chPower(app)
            Fcn6_Plot_Route(app)
            Fcn6_Plot_Scatter(app)
            Fcn6_Plot_Density(app)
            Fcn6_Plot_Car(app)

            try
                idxROI = contains(arrayfun(@(x) x.Tag, app.UIAxes1.Children, 'UniformOutput', false), 'ROI');
                if any(idxROI)
                    uistack(app.UIAxes1.Children(idxROI), "top")
                end
            catch
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function Fcn6_Plot_chPower(app)
            colors = app.CallingApp.General.Colors;
            
            [~, idx] = min(abs(app.filtTable.FK1-app.CallingApp.timeIndex));
            
            h1 = findobj(app.UIAxes2.Children, '-not', 'Tag', 'FilterLevel_THRESHOLD');
            delete(h1)

            hold(app.UIAxes2, 'on')
            app.UIAxes2.XAxis.Exponent = 0;

            [minY, maxY] = bounds(app.specTable.chPower);
            set(app.UIAxes2, 'XLim', [1, height(app.filtTable)+.001], 'YLim', [minY, maxY+.001])
                                
            p1 = plot(app.UIAxes2, app.filtTable.chPower,                    'Tag', 'rawTable', 'Color', colors(4,:), 'Visible', app.chPowerSource_rawTable.Value);
            p2 = plot(app.UIAxes2, app.binTable.binPower(app.filtTable.FK2), 'Tag', 'binTable', 'Color', colors(2,:), 'Visible', app.chPowerSource_binTable.Value);

            smoothedTable = app.filtTable;
            smoothedTable.chPower = smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value);

            app.hSmoothedData = plot(app.UIAxes2, smoothedTable.chPower, 'Color', colors(3,:), 'Visible', app.chPowerSource_smoothed.Value);
            app.hTimeStamp    = plot(app.UIAxes2, [idx idx], [minY maxY], 'Color', 'red', 'Marker', '.', 'MarkerSize', 12, 'PickableParts', 'none');
            app.hPowerLabel   = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.filtTable.chPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on', 'PickableParts', 'none');

            legend(app.UIAxes2, {'Dados brutos', 'Data Binning', 'Curva suave'}, FontSize=7, ...
                                                                                 Location='south', ...
                                                                                 Box='on', ...
                                                                                 Color=[.98,.98,.98], ...
                                                                                 EdgeColor=[.98,.98,.98], ...
                                                                                 PickableParts='none', ...
                                                                                 NumColumns=3)
            hold(app.UIAxes2, 'off')

            plot_DataTipTemplate(app, 'ChannelPower',    p1,                app.filtTable)
            plot_DataTipTemplate(app, 'BinChannelPower', p2,                app.binTable(app.filtTable.FK2,:))
            plot_DataTipTemplate(app, 'ChannelPower',    app.hSmoothedData, smoothedTable)
        end


        %-----------------------------------------------------------------%
        function Fcn6_Plot_Route(app)
            delete([app.hRoute_outROI, app.hRoute_inROI])

            outROITable = app.specTable(~app.specTable.filtered,:);
            inROITable  = app.filtTable;

            app.hRoute_outROI = geoscatter(app.UIAxes1, outROITable.Lat, outROITable.Long, 'filled', 'Marker', '.', 'MarkerFaceColor', app.imgRoute_Color1.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color1.BackgroundColor, 'SizeData', 50);
            switch app.imgRoute_Type.Value
                case 'Ponto';     app.hRoute_inROI = geoscatter(app.UIAxes1, inROITable.Lat, inROITable.Long, 'filled', 'Marker', '.', 'MarkerFaceColor', app.imgRoute_Color2.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color2.BackgroundColor, 'SizeData', 50*app.imgRoute_Size.Value);
                case 'Linha';     app.hRoute_inROI = geoplot(app.UIAxes1,    inROITable.Lat, inROITable.Long, 'Color',     app.imgRoute_Color2.BackgroundColor, 'LineWidth', .5*app.imgRoute_Size.Value);
            end

            plot_DataTipTemplate(app, 'Geographic Position', app.hRoute_outROI, outROITable)
            plot_DataTipTemplate(app, 'Geographic Position', app.hRoute_inROI,  inROITable)
        end


        %-----------------------------------------------------------------%
        function Fcn6_Plot_Car(app)
            delete(app.hCar)

            idx = round(app.hTimeStamp.XData(1));
            app.hCar = geoscatter(app.UIAxes1, app.filtTable.Lat(idx), app.filtTable.Long(idx), 'filled', ...
                                  'Marker', app.General.hCar.marker, 'MarkerFaceColor', app.General.hCar.color, 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 10*app.General.hCar.size, 'PickableParts', 'none');
        end
        

        %-----------------------------------------------------------------%
        function Fcn6_Plot_Scatter(app)
            delete(app.hScatter)

            switch app.layout_mapPlotSourcePanel.SelectedObject
                case app.layout_mapPlotSource_rawData; tempTable = app.filtTable;
                case app.layout_mapPlotSource_binData; tempTable = app.binTable;
            end

            if app.tool_ScatterButton.ImageSource == "DriveTest_Scatter_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hScatter = geoscatter(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, [], tempTable{:,3}, 'filled', ...
                                      'SizeData', 20*app.tool_mapPlotSize.Value,                                    ...
                                      'Visible', Visibility);
            plot_DataTipTemplate(app, 'Geographic Position/Level', app.hScatter,  tempTable)
        end


        %-----------------------------------------------------------------%
        function Fcn6_Plot_Density(app)
            delete(app.hDensity)
            drawnow

            switch app.layout_mapPlotSourcePanel.SelectedObject
                case app.layout_mapPlotSource_rawData; tempTable = app.filtTable;
                case app.layout_mapPlotSource_binData; tempTable = app.binTable;
            end

            weights = tempTable{:,3};
            if min(weights) < 0
                weights = weights+abs(min(weights));
            end

            if app.tool_DensityButton.ImageSource == "DriveTest_Density_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hDensity = geodensityplot(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, weights, ...
                                          'FaceColor','interp',                                 ...
                                          'Radius', 100*app.tool_mapPlotSize.Value,                ...
                                          'Visible', Visibility, 'PickableParts', 'none');
        end


        %-----------------------------------------------------------------%
        function filterROICallback(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)
                    Fcn3_updateInfo(app)
            end            
        end


        %-----------------------------------------------------------------%
        function plot_DataTipTemplate(app, pType, pHandle, hTable)
            set(pHandle.DataTipTemplate, FontName='Calibri', FontSize=10)

            switch pType
                case 'Geographic Position'
                    pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Latitude:',  hTable.Lat,  '%.6f');
                    pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Longitude:', hTable.Long, '%.6f');
                    if numel(pHandle.DataTipTemplate.DataTipRows) > 2
                        pHandle.DataTipTemplate.DataTipRows(3:end) = [];
                    end

                case 'Geographic Position/Level'
                    if ismember('binLat', hTable.Properties.VariableNames)
                        latColumn = 'binLat';
                        lonColumn = 'binLong';
                        pwrColumn = 'binPower';
                    elseif ismember('Lat', hTable.Properties.VariableNames)
                        latColumn = 'Lat';
                        lonColumn = 'Long';
                        pwrColumn = 'chPower';
                    end

                    if exist('latColumn', 'var')
                        eval(sprintf('pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow("Latitude:",  hTable.%s, "%%.6f");',    latColumn))
                        eval(sprintf('pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow("Longitude:", hTable.%s, "%%.6f");',    lonColumn))
                        pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Potência:',  'CData', '%.1f dB');
    
                        if numel(pHandle.DataTipTemplate.DataTipRows) > 3
                            pHandle.DataTipTemplate.DataTipRows(4:end) = [];
                        end
                    end

                case 'ChannelPower'
                    pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Latitude:',  hTable.Lat,             '%.6f');
                    pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Longitude:', hTable.Long,            '%.6f');
                    pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Potência:',  double(hTable.chPower), '%.1f dB');

                case 'BinChannelPower'
                    pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Latitude:',  hTable.binLat,           '%.6f');
                    pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Longitude:', hTable.binLong,          '%.6f');
                    pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Potência:',  double(hTable.binPower), '%.1f dB');
            end
        end
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function TimeStampUpdatePosition(app, timeIndex)

            idx = find(app.filtTable.FK1 == timeIndex, 1);
            if ~isempty(idx)
                app.hTimeStamp.XData(1:2)   = idx;
                app.hPowerLabel.Position(1) = idx;
                app.hPowerLabel.String      = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                set(app.hCar, 'LatitudeData', app.filtTable.Lat(idx), 'LongitudeData', app.filtTable.Long(idx))
            else
                set(app.hCar, 'LatitudeData', NaN, 'LongitudeData', NaN)
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx1, idx2)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';

            layout_LeftPanelSelectedTabControl(app, struct('Source', app.menu_Button1Icon))
            play_FindPeaks_RadioGroupSelectionChanged(app)
            
            app.specData   = app.CallingApp.specData(idx1);
            app.Index      = [idx1, idx2];

            Fcn1_AxesCreation(app)
            Fcn2_General(app)
            Fcn3_updateInfo(app)

            set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
            app.CallingApp.play_Tree.Enable = 0;
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
            app.CallingApp.play_Tree.Enable = 1;

            app.CallingApp.auxiliarWin2 = [];
            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots e a exclusão de FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    pause(.1)

                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)

                    filter_Tree_SelectionChanged(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            rowHeight        = repmat({0}, 1, NN);
            rowHeight(idx)   = {'1x'};

            app.menu_MainGrid.ColumnWidth      = columnWidth;
            app.menu_Image.Layout.Column       = idx;
            app.LeftPanelGrid.RowHeight(2:end) = rowHeight;

        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: layout_mapPlotLock
        function PlotLink_ImageClicked(app, event)

            if app.CallingApp.play_Tree.Enable
                idx = [];
                for ii = 1:numel(app.CallingApp.specData)
                    if isequal(rmfield(struct(app.CallingApp.specData(ii)), 'UserData'), rmfield(struct(app.specData), 'UserData'))
                        idx = ii;
                        break
                    end
                end
    
                if isempty(idx)
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                    return
                end

                if app.CallingApp.play_Tree.SelectedNodes.NodeData ~= idx
                    if isempty(app.CallingApp.play_PlotPanel.UserData) || (app.CallingApp.play_PlotPanel.UserData.NodeData ~= idx)
                        hTreeNode      = findall(app.CallingApp.play_Tree, '-not', 'Type', 'uitree');

                        hTreeNodeData  = arrayfun(@(x) x.NodeData, hTreeNode, 'UniformOutput', false);
                        hTreeNodeIndex = find(cellfun(@(x) isequal(x, idx), hTreeNodeData), 1); 

                        if isempty(hTreeNodeIndex)
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                            return
                        end

                        app.CallingApp.auxiliarWinFunction(app, hTreeNode(hTreeNodeIndex))
                    end
                end

                app.CallingApp.play_Tree.Enable = 0;
                set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';

            else
                app.CallingApp.play_Tree.Enable = 1;
                set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockOpen.png';
            end

        end

        % Image clicked function: emissionListRefresh
        function Emissions_RefreshListClicked(app, event)
            
            preSelection = extractAfter(app.emissionsList.Value, ': ');
            
            try
                app.specData = app.CallingApp.specData(app.Index(1));
                Fcn2_General_Emissions(app)
    
                idx = find(contains(app.emissionsList.Items, preSelection), 1);
                if ~isempty(idx)
                    app.emissionsList.Value = app.emissionsList.Items(idx);
                end
                
                Fcn2_General_MetaData(app)
                Fcn2_General_AxesTitle(app)
                Fcn2_General_specTable(app)
                Fcn3_updateInfo(app)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end

        end

        % Value changed function: emissionsList
        function emissionsList_ValueChanged(app, event)

            Fcn2_General_AxesTitle(app)
            Fcn2_General_specTable(app)
            Fcn3_updateInfo(app)
            
        end

        % Selection changed function: filter_roiOptions
        function filter_roiOptions_SelectionChanged(app, event)
            
            if ~isempty(app.filter_roiOptions.SelectedNodes) && app.filter_roiOptions.SelectedNodes.NodeData
                app.filter_addImage.Enable = 1;
            else
                app.filter_addImage.Enable = 0;
            end
            
        end

        % Selection changed function: filter_Tree
        function filter_Tree_SelectionChanged(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx = app.filter_Tree.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.filterTable)
                    if ii ~= idx
                        set(app.filterTable.handle{ii}, 'InteractionsAllowed', 'none')
    
                    else
                        if app.filterTable.type == "Level"
                            set(app.filterTable.handle{ii}, 'InteractionsAllowed',  'translate')
                        else
                            set(app.filterTable.handle{ii}, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
                uistack(app.filterTable.handle{idx}, "top")
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addImageCallback(app, event)
            
            NodeData = app.filter_roiOptions.SelectedNodes.NodeData;
            
            app.filter_roiOptions.SelectedNodes = [];
            filter_roiOptions_SelectionChanged(app)

            set(app.filter_Tree, 'SelectedNodes', [], 'Enable', 0)
            set(app.filter_ContextMenu.Children, Enable=0)

            disableDefaultInteractivity(app.UIAxes1)
            disableDefaultInteractivity(app.UIAxes2)
            pause(.1)

            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'THRESHOLD';                        
                    THR  = min(app.filtTable.chPower);
                    hROI = images.roi.Line(app.UIAxes2, 'Position', [1 THR; height(app.filtTable) THR], ...
                                                        'Color', 'red',                     ...
                                                        'MarkerSize', 4,                    ...
                                                        'Deletable', 0,                     ...
                                                        'LineWidth', 1,                     ...
                                                        'InteractionsAllowed', 'translate', ...
                                                        'Tag', 'FilterLevel_THRESHOLD');
                otherwise
                    switch NodeData
                        case 2; Type = 'Geographic ROI'; SubType = 'CÍRCULO';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 3; Type = 'Geographic ROI'; SubType = 'RETÂNGULO'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 4; Type = 'Geographic ROI'; SubType = 'POLÍGONO';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                            
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI_%s");', roiFcn, roiNameArgument, SubType))
            end
            enableDefaultInteractivity(app.UIAxes1)
            enableDefaultInteractivity(app.UIAxes2)

            if isempty(hROI.Position)
                delete(hROI)
                
                app.filter_Tree.Enable = 1;
                if ~isempty(app.filter_Tree.Children)
                    app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                    filter_Tree_SelectionChanged(app)
                end

                return
            end

            addlistener(hROI, 'MovingROI', @app.filterROICallback);
            addlistener(hROI, 'ROIMoved',  @app.filterROICallback);
            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plotFcn.cleanupFcn(src));

            app.filterTable(end+1,:) = {Type, SubType, {hROI}, 1};
            Fcn2_General_TreeBuilding(app)
            Fcn3_updateInfo(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);

                case app.filter_delAllButton
                    idx = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx)
                cellfun(@(x) delete(x), app.filterTable.handle(idx)); 
                app.filterTable(idx,:) = [];

                Fcn2_General_TreeBuilding(app)
                Fcn3_updateInfo(app)
            end

        end

        % Value changed function: binTable_Type
        function binTable_TypeChanged(app, event)

            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    set(app.binTable_FactorLabel, 'Text', 'Comprimento <i>bin</i> (metros):')
                    set(app.binTable_Factor, 'Limits', [50, 1500], 'Value', 100, 'Step', 50)

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    set(app.binTable_FactorLabel, 'Text', 'Casas decimais:')
                    set(app.binTable_Factor, 'Limits', [1, 10], 'Value', 6, 'Step', 1)
            end

            binTable_ValueChanged(app)
            
        end

        % Value changed function: binTable_Factor, binTable_Fcn
        function binTable_ValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            Fcn4_binTable(app)
            Fcn5_UITable(app)

            if app.layout_mapPlotSource_binData.Value
                Fcn6_Plots(app)
            else                
                h = findall(app.UIAxes2, 'Tag', 'binTable');
                h.YData = app.binTable.binPower(app.filtTable.FK2);
            end

            delete(d)
            
        end

        % Callback function: imgRoute_Type, layout_mapPlotSourcePanel
        function Plots_RefreshCallback(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            Fcn6_Plots(app)
            delete(d)

        end

        % Image clicked function: tool_DensityButton, tool_ScatterButton
        function Plot1_MainButtonClicked(app, event)
            
            switch event.Source
                case app.tool_ScatterButton
                    if app.hScatter.Visible
                        app.hScatter.Visible = 0;
                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        app.hScatter.Visible = 1;
                        app.hDensity.Visible = 0;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.tool_DensityButton
                    if app.hDensity.Visible
                        app.hDensity.Visible = 0;
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        app.hScatter.Visible = 0;
                        app.hDensity.Visible = 1;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Button pushed function: imgRoute_Color1, imgRoute_Color2, 
        % ...and 1 other component
        function Plot1_ColorCallback(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;

                try
                    switch event.Source
                        case app.imgRoute_Color1
                            set(app.hRoute_outROI, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                        
                        case app.imgRoute_Color2
                            switch app.imgRoute_Type.Value
                                case 'Ponto'
                                    set(app.hRoute_inROI, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                                case 'Linha'
                                    set(app.hRoute_inROI, 'Color', selectedColor)
                            end

                        case app.mapPlot_color1
                            set(app.hCar, 'MarkerFaceColor', selectedColor)
                            app.General.hCar.color = selectedColor;
                                    
                    end
                catch
                end
            end

        end

        % Value changing function: imgRoute_Size, mapPlot_size1
        function Plot1_SizeChangingCallback(app, event)
            
            try
                switch event.Source
                    case app.imgRoute_Size
                        set(app.hRoute_outROI, 'LineWidth', .5*event.Value)

                        switch app.imgRoute_Type.Value
                            case 'Ponto'; set(app.hRoute_inROI, 'SizeData',  50*event.Value)
                            case 'Linha'; set(app.hRoute_inROI, 'LineWidth', .5*event.Value)
                        end

                    case app.mapPlot_size1
                        set(app.hCar, 'SizeData', 10*event.Value)
                        app.General.hCar.size = event.Value;
                end
            catch
            end            

        end

        % Value changed function: tool_mapPlotSize
        function Plot1_SizeChangedCallback(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            set(app.hScatter, 'SizeData', 20*event.Value)
            set(app.hDensity, 'Radius',  100*event.Value)
            drawnow

            delete(d)

        end

        % Value changed function: mapPlot_Type
        function Plot1_MarkerCallback(app, event)
            
            try
                set(app.hCar, 'Marker', event.Value)
                app.General.hCar.marker = event.Value;
            catch
            end

        end

        % Value changed function: Colormap
        function Plot1_ColormapValueChanged(app, event)
            
            colormap(app.UIAxes1, app.Colormap.Value)
            
        end

        % Value changed function: chPowerSource_binTable, 
        % ...and 1 other component
        function Plot2_chPowerSource(app, event)
            
            switch event.Source
                case app.chPowerSource_rawTable; Tag = 'rawTable';
                case app.chPowerSource_binTable; Tag = 'binTable';
            end

            h = findall(app.UIAxes2, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: chPowerSource_smoothed
        function Plot2_chPowerSource_smoothedData(app, event)
            
            if app.chPowerSource_smoothed.Value
                set(app.Smoothed_Grid.Children, 'Enable', 1)
                app.hSmoothedData.Visible = 1;

            else
                set(app.Smoothed_Grid.Children, 'Enable', 0)
                app.hSmoothedData.Visible = 0;
            end            
            
        end

        % Value changed function: Smoothed_Factor, Smoothed_Type
        function Plot2_smoothedDataUpdate(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            app.hSmoothedData.YData = smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value);
            delete(d)
            
        end

        % Button pushed function: tool_ExportButton
        function ExcelFile_ButtonPushed(app, event)
            
            defaultFilename = class.Constants.DefaultFileName(app.CallingApp.menu_userPath.Value, 'Drive-test', app.CallingApp.report_Issue.Value);

            [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'appAnalise (*.xlsx)'}, '', defaultFilename);
            figure(app.UIFigure)
    
            if fileIndex
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

                try
                    tempFilters = app.filterTable(:, 1:2);
                    for ii = 1:height(app.filterTable)
                        switch app.filterTable.subtype{ii}
                            case 'THRESHOLD'; tempFilters.Parameters{ii} = struct('Threshold', app.filterTable.handle{ii}.Position(3));
                            case 'CÍRCULO';   tempFilters.Parameters{ii} = struct('Center',    app.filterTable.handle{ii}.Center,   'Radius',        app.filterTable.handle{ii}.Radius);
                            case 'RETÂNGULO'; tempFilters.Parameters{ii} = struct('Position',  app.filterTable.handle{ii}.Position, 'RotationAngle', app.filterTable.handle{ii}.RotationAngle);
                            case 'POLÍGONO';  tempFilters.Parameters{ii} = struct('Position',  app.filterTable.handle{ii}.Position);
                        end
                    end
    
                    FullFileName = fullfile(filePath, fileName);
        
                    fileID = fopen(replace(FullFileName, '.xlsx', '.cfg'), "wt");
                    fprintf(fileID, '%s', sprintf('EMISSÃO:\n%s\n\n\n', extractAfter(app.emissionsList.Value, ': ')));
                    fprintf(fileID, '%s', sprintf('INFORMAÇÕES GERAIS:\n%s\n\n', strjoin(app.taskMetaData.Value, '\n')));
                    fprintf(fileID, 'FILTRO(S):\n%s\n\n\n',  jsonencode(tempFilters, 'PrettyPrint', true));
                    fprintf(fileID, 'DATA BINNING:\n%s\n%s', jsonencode(struct('Type', app.binTable_Type.Value, 'Factor', app.binTable_Factor.Value, 'Fcn', app.binTable_Fcn.Value)), app.binTable_Info.Text);
                    fclose(fileID);
        
                    writetable(app.specTable,              FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'replacefile',    'Sheet', 'specTable')
                    writetable(app.filtTable(:,[1:3,6:7]), FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'filtTable')
                    writetable(app.binTable,               FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'binTable')

                    kmlwriteline(replace(FullFileName, '.xlsx', '.kml'), app.specTable.Lat, app.specTable.Long, 'Color', 'red', 'LineWidth', 3);

                catch ME
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
                end

                delete(d)
            end

        end

        % Selection changed function: play_FindPeaks_RadioGroup
        function play_FindPeaks_RadioGroupSelectionChanged(app, event)
            
            selectedButton = app.play_FindPeaks_RadioGroup.SelectedObject;
            switch selectedButton
                case app.play_FindPeaks_auto; app.play_FindPeaks_ParametersGrid.RowHeight = {17,22,'1x',0,0,0,0};
                case app.play_FindPeaks_ROI;  app.play_FindPeaks_ParametersGrid.RowHeight = {0,0,0,17,22,25,22};
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.menu_MainGrid = uigridlayout(app.LeftPanelGrid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = 'LT_LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'LT_Dots6.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Pontos de interesse';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'LT_Pin2.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Data binning';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'LT_Detection2.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Layout';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'LT_Layers2.png';
            app.Tab1_Panel = uigridlayout(app.LeftPanelGrid);
            app.emissionListGrid = uigridlayout(app.Tab1_Panel);
            app.emissionListLabel = uilabel(app.emissionListGrid);
            app.emissionListLabel.Text = 'Emissão';
            app.emissionListRefresh = uiimage(app.emissionListGrid);
            app.emissionListRefresh.ImageClickedFcn = createCallbackFcn(app, @Emissions_RefreshListClicked, true);
            app.emissionListRefresh.ImageSource = 'LT_reload.png';
            app.emissionsList = uidropdown(app.Tab1_Panel);
            app.emissionsList.Items = {};
            app.emissionsList.ValueChangedFcn = createCallbackFcn(app, @emissionsList_ValueChanged, true);
            app.emissionsList.Value = {};
            app.taskMetaData = uitextarea(app.Tab1_Panel);
            app.taskMetaData.Editable = 'off';
            app.filter_TreeLabel = uilabel(app.Tab1_Panel);
            app.filter_TreeLabel.Text = 'Filtros';
            app.filter_roiOptions = uitree(app.Tab1_Panel);
            app.filter_roiOptions.SelectionChangedFcn = createCallbackFcn(app, @filter_roiOptions_SelectionChanged, true);
            app.levelFilterNode = uitreenode(app.filter_roiOptions);
            app.levelFilterNode.Icon = 'LT_OCC.png';
            app.levelFilterNode.Text = 'NÍVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.filter_roiOptions);
            app.geoFilterNode.Icon = 'DriveTest_Scatter_OFF.png';
            app.geoFilterNode.Text = 'GEOGRÁFICO';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CÍRCULO';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RETÂNGULO';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLÍGONO';
            app.filter_addImageGrid = uigridlayout(app.Tab1_Panel);
            app.filter_addImage = uiimage(app.filter_addImageGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addImageCallback, true);
            app.filter_addImage.Enable = 'off';
            app.filter_addImage.ImageSource = 'LT_play.png';
            app.filter_Tree = uitree(app.Tab1_Panel);
            app.filter_Tree.SelectionChangedFcn = createCallbackFcn(app, @filter_Tree_SelectionChanged, true);
            app.Tab2_Panel = uigridlayout(app.LeftPanelGrid);
            app.points_Tree = uitree(app.Tab2_Panel);
            app.play_FindPeaks_RadioGroup = uibuttongroup(app.Tab2_Panel);
            app.play_FindPeaks_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_RadioGroupSelectionChanged, true);
            app.play_FindPeaks_auto = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_auto.Text = {'Estações de telecomunicações'; '(RFDataHub)'};
            app.play_FindPeaks_auto.Value = true;
            app.play_FindPeaks_ROI = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_ROI.Text = {'Picos da potência'; 'do canal'};
            app.play_FindPeaks_ParametersPanel = uipanel(app.Tab2_Panel);
            app.play_FindPeaks_ParametersGrid = uigridlayout(app.play_FindPeaks_ParametersPanel);
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel.Text = 'Fonte da informação:';
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace.Items = {'Dados brutos', 'Dados processados (Data Binning)', 'Curva suave'};
            app.play_FindPeaks_Trace.Value = 'Dados processados (Data Binning)';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de '; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância entre'; 'picos (m):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distance.Step = 100;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 1000;
            app.play_FindPeaks_TraceLabel_2 = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel_2.Text = 'Tipo:';
            app.play_FindPeaks_Trace_2 = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace_2.Items = {'Índices dos registros', 'Lista de frequências', 'Prováveis fontes das emissões classificadas'};
            app.play_FindPeaks_Trace_2.Value = 'Prováveis fontes das emissões classificadas';
            app.TextArea = uitextarea(app.play_FindPeaks_ParametersGrid);
            app.points_TreeLabel = uilabel(app.Tab2_Panel);
            app.points_TreeLabel.Text = 'Pontos de interesse';
            app.play_FindPeaks_add = uiimage(app.Tab2_Panel);
            app.play_FindPeaks_add.ImageSource = 'LT_Add.png';
            app.Tab3_Panel = uigridlayout(app.LeftPanelGrid);
            app.binTable_TypeLabel = uilabel(app.Tab3_Panel);
            app.binTable_TypeLabel.Text = 'Tipo';
            app.binTable_Type = uidropdown(app.Tab3_Panel);
            app.binTable_Type.Items = {'Grid retangular - Comprimento bin', 'Grid retangular - Coordenadas geográficas truncadas'};
            app.binTable_Type.ValueChangedFcn = createCallbackFcn(app, @binTable_TypeChanged, true);
            app.binTable_Type.Value = 'Grid retangular - Comprimento bin';
            app.binTable_Grid = uigridlayout(app.Tab3_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; ' '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Varreduras'; '  '};
            app.binTable_Info = uilabel(app.binTable_Grid);
            app.binTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dede'; 'dee'};
            app.Panel = uipanel(app.Tab3_Panel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.binTable_FactorLabel = uilabel(app.GridLayout2);
            app.binTable_FactorLabel.Interpreter = 'html';
            app.binTable_FactorLabel.Text = 'Comprimento <i>bin</i> (metros):';
            app.binTable_Factor = uispinner(app.GridLayout2);
            app.binTable_Factor.Step = 50;
            app.binTable_Factor.Limits = [50 1500];
            app.binTable_Factor.RoundFractionalValues = 'on';
            app.binTable_Factor.ValueDisplayFormat = '%.0f';
            app.binTable_Factor.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Factor.Value = 100;
            app.binTable_FcnLabel = uilabel(app.GridLayout2);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.GridLayout2);
            app.binTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Fcn.Value = 'Média';
            app.Tab4_Panel = uigridlayout(app.LeftPanelGrid);
            app.layout_mapPlotLabel = uilabel(app.Tab4_Panel);
            app.layout_mapPlotLabel.Text = 'Eixo geográfico';
            app.layout_mapPlotLock = uiimage(app.Tab4_Panel);
            app.layout_mapPlotLock.ImageClickedFcn = createCallbackFcn(app, @PlotLink_ImageClicked, true);
            app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';
            app.layout_mapPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_mapPlotGrid = uigridlayout(app.layout_mapPlotPanel);
            app.layout_mapPlotSourceLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotSourceLabel.Text = 'Fonte da informação:';
            app.layout_mapPlotSourcePanel = uibuttongroup(app.layout_mapPlotGrid);
            app.layout_mapPlotSourcePanel.SelectionChangedFcn = createCallbackFcn(app, @Plots_RefreshCallback, true);
            app.layout_mapPlotSource_rawData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_rawData.Text = 'Dados brutos';
            app.layout_mapPlotSource_binData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_binData.Text = 'Dados processados (Data Binning)';
            app.layout_mapPlotSource_binData.Value = true;
            app.mapPlot_Subtitle11 = uilabel(app.layout_mapPlotGrid);
            app.mapPlot_Subtitle11.Text = 'Layout:';
            app.mapPlot_Panel11 = uipanel(app.layout_mapPlotGrid);
            app.mapPlot_Grid11 = uigridlayout(app.mapPlot_Panel11);
            app.imgRoute_TypeLabel = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel.Text = 'Rota:';
            app.imgRoute_Type = uidropdown(app.mapPlot_Grid11);
            app.imgRoute_Type.Items = {'Ponto', 'Linha'};
            app.imgRoute_Type.ValueChangedFcn = createCallbackFcn(app, @Plots_RefreshCallback, true);
            app.imgRoute_Type.Value = 'Linha';
            app.imgRoute_Panel = uipanel(app.mapPlot_Grid11);
            app.imgRoute_Grid = uigridlayout(app.imgRoute_Panel);
            app.imgRoute_Color1 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color1.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.imgRoute_Color1.Text = '';
            app.imgRoute_Color2 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color2.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.imgRoute_Color2.Text = '';
            app.imgRoute_Size = uislider(app.imgRoute_Grid);
            app.imgRoute_Size.Limits = [1 9];
            app.imgRoute_Size.MajorTicks = [];
            app.imgRoute_Size.MajorTickLabels = {''};
            app.imgRoute_Size.ValueChangingFcn = createCallbackFcn(app, @Plot1_SizeChangingCallback, true);
            app.imgRoute_Size.MinorTicks = [];
            app.imgRoute_Size.Value = 1;
            app.imgRoute_TypeLabel_2 = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel_2.Text = 'Veículo:';
            app.mapPlot_Type = uidropdown(app.mapPlot_Grid11);
            app.mapPlot_Type.Items = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            app.mapPlot_Type.ValueChangedFcn = createCallbackFcn(app, @Plot1_MarkerCallback, true);
            app.mapPlot_Type.Value = 's';
            app.mapPlot_Property1Panel = uipanel(app.mapPlot_Grid11);
            app.mapPlot_Property1Grid = uigridlayout(app.mapPlot_Property1Panel);
            app.mapPlot_color1 = uibutton(app.mapPlot_Property1Grid, 'push');
            app.mapPlot_color1.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.mapPlot_color1.Text = '';
            app.mapPlot_size1 = uislider(app.mapPlot_Property1Grid);
            app.mapPlot_size1.Limits = [1 19];
            app.mapPlot_size1.MajorTicks = [];
            app.mapPlot_size1.MajorTickLabels = {''};
            app.mapPlot_size1.ValueChangingFcn = createCallbackFcn(app, @Plot1_SizeChangingCallback, true);
            app.mapPlot_size1.MinorTicks = [];
            app.mapPlot_size1.Value = 10;
            app.ColormapLabel = uilabel(app.mapPlot_Grid11);
            app.ColormapLabel.Text = {'Mapa de'; 'cor:'};
            app.Colormap = uidropdown(app.mapPlot_Grid11);
            app.Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.Colormap.ValueChangedFcn = createCallbackFcn(app, @Plot1_ColormapValueChanged, true);
            app.Colormap.Value = 'jet';
            app.layout_cartesianPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_cartesianPlotTitle.Text = 'Eixo cartesiano';
            app.layout_cartesianPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_cartesianPlotGrid = uigridlayout(app.layout_cartesianPlotPanel);
            app.layout_cartesianPlotLabel = uilabel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotLabel.Text = 'Fonte da informação:';
            app.layout_cartesianPlotSubPanel = uipanel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotSubGrid = uigridlayout(app.layout_cartesianPlotSubPanel);
            app.chPowerSource_rawTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_rawTable.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource, true);
            app.chPowerSource_rawTable.Text = 'Dados brutos';
            app.chPowerSource_rawTable.Value = true;
            app.chPowerSource_binTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_binTable.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource, true);
            app.chPowerSource_binTable.Text = 'Dados processados (Data Binning)';
            app.chPowerSource_smoothed = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_smoothed.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource_smoothedData, true);
            app.chPowerSource_smoothed.Text = 'Curva suave';
            app.chPowerSource_smoothedPanel = uipanel(app.layout_cartesianPlotSubGrid);
            app.Smoothed_Grid = uigridlayout(app.chPowerSource_smoothedPanel);
            app.Smoothed_TypeLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_TypeLabel.Enable = 'off';
            app.Smoothed_TypeLabel.Text = 'Tipo:';
            app.Smoothed_Type = uidropdown(app.Smoothed_Grid);
            app.Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @Plot2_smoothedDataUpdate, true);
            app.Smoothed_Type.Enable = 'off';
            app.Smoothed_Type.Value = 'gaussian';
            app.Smoothed_FactorLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_FactorLabel.Enable = 'off';
            app.Smoothed_FactorLabel.Text = 'Fator:';
            app.Smoothed_Factor = uispinner(app.Smoothed_Grid);
            app.Smoothed_Factor.Step = 0.1;
            app.Smoothed_Factor.Limits = [0 1];
            app.Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @Plot2_smoothedDataUpdate, true);
            app.Smoothed_Factor.Enable = 'off';
            app.Smoothed_Factor.Value = 0.25;
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
            app.Document = uigridlayout(app.Grid);
            app.axesPanel = uipanel(app.Document);
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ExportButton = uibutton(app.toolGrid, 'push');
            app.tool_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelFile_ButtonPushed, true);
            app.tool_ExportButton.Icon = 'Export_24.png';
            app.tool_ExportButton.Tooltip = {'Exportar tabela'};
            app.tool_ExportButton.Text = '';
            app.tool_Serarator1 = uiimage(app.toolGrid);
            app.tool_Serarator1.Enable = 'off';
            app.tool_Serarator1.ImageSource = 'LT_LineV.png';
            app.tool_ScatterButton = uiimage(app.toolGrid);
            app.tool_ScatterButton.ImageClickedFcn = createCallbackFcn(app, @Plot1_MainButtonClicked, true);
            app.tool_ScatterButton.Tooltip = {'Distorção'};
            app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
            app.tool_DensityButton = uiimage(app.toolGrid);
            app.tool_DensityButton.ImageClickedFcn = createCallbackFcn(app, @Plot1_MainButtonClicked, true);
            app.tool_DensityButton.Tooltip = {'Densidade'};
            app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
            app.tool_mapPlotSize = uislider(app.toolGrid);
            app.tool_mapPlotSize.Limits = [1 19];
            app.tool_mapPlotSize.MajorTicks = [1 10 19];
            app.tool_mapPlotSize.MajorTickLabels = {''};
            app.tool_mapPlotSize.ValueChangedFcn = createCallbackFcn(app, @Plot1_SizeChangedCallback, true);
            app.tool_mapPlotSize.MinorTicks = [1.6 2.2 2.8 3.4 4 4.6 5.2 5.8 6.4 7 7.6 8.2 8.8 9.4 10 10.6 11.2 11.8 12.4 13 13.6 14.2 14.8 15.4 16 16.6 17.2 17.8 18.4];
            app.tool_mapPlotSize.Value = 1;
            app.tool_Serarator2 = uiimage(app.toolGrid);
            app.tool_Serarator2.Enable = 'off';
            app.tool_Serarator2.ImageSource = 'LT_LineV.png';
            app.tool_SelectedEmision = uilabel(app.toolGrid);
            app.tool_SelectedEmision.Text = {'R&S FSL'; 'ID1: 30.000 - 108.000 MHz @ 103.500 MHz ⌂ 256.000 kHz'};
            app.tool_nRowsGrid = uigridlayout(app.toolGrid);
            app.tool_nRows = uilabel(app.tool_nRowsGrid);
            app.tool_nRows.Text = {'0 de 0 varreduras'; '85.4 %'};
            app.tool_nRowsIcon = uiimage(app.tool_nRowsGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.ImageSource = 'LT_Filter2.png';
            app.tool_resetButton = uiimage(app.tool_nRowsGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'LT_Dots.png';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end