classdef winAppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid                       matlab.ui.container.GridLayout
        menu_Settings                   matlab.ui.control.Image
        menu_Position                   matlab.ui.control.Image
        menu_searchPath                 matlab.ui.control.Image
        menu_userPathGrid               matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_Button8                    matlab.ui.control.Button
        menu_Button7                    matlab.ui.control.Button
        menu_Button6                    matlab.ui.control.Button
        menu_Separator2                 matlab.ui.control.Image
        menu_Button5                    matlab.ui.control.StateButton
        menu_Button4                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        home_Grid                       matlab.ui.container.GridLayout
        home_Button5                    ccTools.Button
        home_Button4                    ccTools.Button
        home_Button3                    ccTools.Button
        home_Button2                    ccTools.Button
        home_Button1                    ccTools.Button
        Tab2_File                       matlab.ui.container.Tab
        file_Grid                       matlab.ui.container.GridLayout
        file_panelGrid                  matlab.ui.container.GridLayout
        file_FilteringTree              matlab.ui.container.Tree
        file_FilteringAdd               matlab.ui.control.Image
        file_FilteringType3_Description  matlab.ui.control.EditField
        file_FilteringType2_ID          matlab.ui.control.DropDown
        file_FilteringType1_Frequency   matlab.ui.control.DropDown
        file_FilteringTypePanel         matlab.ui.container.ButtonGroup
        file_FilteringType3             matlab.ui.control.RadioButton
        file_FilteringType2             matlab.ui.control.RadioButton
        file_FilteringType1             matlab.ui.control.RadioButton
        file_FilteringLabel             matlab.ui.control.Label
        file_MetadataPanel              matlab.ui.container.Panel
        file_MetadataGrid               matlab.ui.container.GridLayout
        file_Metadata                   matlab.ui.control.HTML
        file_MetadataLabel              matlab.ui.control.Label
        file_docGrid                    matlab.ui.container.GridLayout
        file_Tree                       matlab.ui.container.Tree
        file_TreeLabel                  matlab.ui.control.Label
        file_TitleGrid                  matlab.ui.container.GridLayout
        file_Title                      matlab.ui.control.Label
        file_TitleIcon                  matlab.ui.control.Image
        file_toolGrid                   matlab.ui.container.GridLayout
        file_SpecReadButton             matlab.ui.control.Button
        file_RepositoryButton           matlab.ui.control.Button
        file_OpenFileButton             matlab.ui.control.Button
        Tab3_Playback                   matlab.ui.container.Tab
        play_Grid                       matlab.ui.container.GridLayout
        play_ControlsGrid               matlab.ui.container.GridLayout
        misc_ControlsTab6Grid           matlab.ui.container.GridLayout
        misc_ControlsTab6Image          matlab.ui.control.Image
        misc_ControlsTab6Label          matlab.ui.control.Label
        misc_ControlsTab5Grid           matlab.ui.container.GridLayout
        misc_ControlsTab5Image          matlab.ui.control.Image
        misc_ControlsTab5Label          matlab.ui.control.Label
        misc_ControlsTab4Grid           matlab.ui.container.GridLayout
        misc_ControlsTab4Image          matlab.ui.control.Image
        misc_ControlsTab4Label          matlab.ui.control.Label
        misc_ControlsTab3Grid           matlab.ui.container.GridLayout
        misc_ControlsTab3Image          matlab.ui.control.Image
        misc_ControlsTab3Label          matlab.ui.control.Label
        misc_ControlsTab2Grid           matlab.ui.container.GridLayout
        misc_ControlsTab2Image          matlab.ui.control.Image
        misc_ControlsTab2Label          matlab.ui.control.Label
        misc_ControlsTab1Grid           matlab.ui.container.GridLayout
        misc_ControlsTab1Image          matlab.ui.control.Image
        misc_ControlsTab1Label          matlab.ui.control.Label
        report_ControlsTab3Grid         matlab.ui.container.GridLayout
        report_ControlsTab3Image        matlab.ui.control.Image
        report_ControlsTab3Label        matlab.ui.control.Label
        report_ControlsTab2Info         matlab.ui.container.GridLayout
        Report_Sublabel5                matlab.ui.control.Label
        Panel_41                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Report_ClassificationParameters  matlab.ui.control.TextArea
        Report_ClassificationButton     matlab.ui.control.Button
        Report_Classification           matlab.ui.control.EditField
        Report_ClassificationLabel      matlab.ui.control.Label
        Report_Sublabel6                matlab.ui.control.Label
        Panel_40                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Report_DetectionButton          matlab.ui.control.Button
        Report_Detection                matlab.ui.control.EditField
        Report_DetectionLabel           matlab.ui.control.Label
        Report_BandPanelLabel           matlab.ui.control.Label
        Report_PlaybackOnly             matlab.ui.control.CheckBox
        Report_DetectionParameters      matlab.ui.control.TextArea
        Report_BandPanel                matlab.ui.container.Panel
        Report_BandGrid                 matlab.ui.container.GridLayout
        Report_BandLim2                 matlab.ui.control.Spinner
        Report_BandSliderLabel          matlab.ui.control.Label
        Report_BandLim1                 matlab.ui.control.Spinner
        Report_Sublabel5_2              matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Report_occParameters            matlab.ui.control.TextArea
        Report_occButton                matlab.ui.control.Button
        Report_occMethod                matlab.ui.control.EditField
        Report_occMethodLabel           matlab.ui.control.Label
        report_ControlsTab2Grid         matlab.ui.container.GridLayout
        report_ControlsTab2Image        matlab.ui.control.Image
        report_ControlsTab2Label        matlab.ui.control.Label
        report_ControlsTab1Info         matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        Report_addButton                matlab.ui.control.Image
        Report_Sublabel3                matlab.ui.control.Label
        Panel_37                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Report_NewProject               matlab.ui.control.Button
        ProjectFilename                 matlab.ui.control.TextArea
        RelatrioLabel                   matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        Report_VersionLabel             matlab.ui.control.Label
        Report_Version                  matlab.ui.control.DropDown
        Report_NewProject_2             matlab.ui.control.Button
        Report_TypeLabel                matlab.ui.control.Label
        Report_Type                     matlab.ui.control.DropDown
        Report_Issue                    matlab.ui.control.NumericEditField
        Report_IssueLabel               matlab.ui.control.Label
        Report_SaveProject              matlab.ui.control.Button
        ProjectName                     matlab.ui.control.EditField
        Panel_36                        matlab.ui.container.Panel
        Report_Grid4                    matlab.ui.container.GridLayout
        Report_Spinner4                 matlab.ui.control.Spinner
        Report_Spinner3                 matlab.ui.control.Spinner
        Report_DatePicker2              matlab.ui.control.DatePicker
        Report_Spinner2                 matlab.ui.control.Spinner
        Report_Spinner1                 matlab.ui.control.Spinner
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_Sublabel4                matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        Report_Sublabel1                matlab.ui.control.Label
        Report_ImageWarn                matlab.ui.control.Image
        Report_Tree                     matlab.ui.container.CheckBoxTree
        report_ControlsTab1Grid         matlab.ui.container.GridLayout
        report_ControlsTab1Image        matlab.ui.control.Image
        report_ControlsTab1Label        matlab.ui.control.Label
        play_ControlsTab3Info           matlab.ui.container.GridLayout
        play_FindPeaks_PeakBW           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakBWLabel      matlab.ui.control.Label
        play_FindPeaks_PeakCF           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakCFLabel      matlab.ui.control.Label
        play_FindPeaks_Tree             matlab.ui.container.Tree
        play_FindPeaks_add              matlab.ui.control.Image
        play_FindPeaks_ParametersPanel  matlab.ui.container.Panel
        play_FindPeaks_ParametersGrid   matlab.ui.container.GridLayout
        play_FindPeaks_MaxHoldPanel     matlab.ui.container.Panel
        play_FindPeaks_MaxHoldGrid      matlab.ui.container.GridLayout
        play_FindPeaks_maxOCC           matlab.ui.control.Spinner
        play_FindPeaks_maxOCCLabel      matlab.ui.control.Label
        play_FindPeaks_meanOCC          matlab.ui.control.Spinner
        play_FindPeaks_meanOCCLabel     matlab.ui.control.Label
        play_FindPeaks_Prominence2      matlab.ui.control.Spinner
        play_FindPeaks_Prominence2Label  matlab.ui.control.Label
        play_FindPeaks_MeanPanel        matlab.ui.container.Panel
        play_FindPeaks_MeanGrid         matlab.ui.container.GridLayout
        play_FindPeaks_Prominence1      matlab.ui.control.Spinner
        play_FindPeaks_Prominence1Label  matlab.ui.control.Label
        play_FindPeaks_BW               matlab.ui.control.Spinner
        play_FindPeaks_BWLabel          matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_Class            matlab.ui.control.DropDown
        play_FindPeaks_ClassLabel       matlab.ui.control.Label
        play_FindPeaks_prominence       matlab.ui.control.Spinner
        play_FindPeaks_prominenceLabel  matlab.ui.control.Label
        play_FindPeaks_THR              matlab.ui.control.Spinner
        play_FindPeaks_THRLabel         matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_Algorithm        matlab.ui.control.DropDown
        play_FindPeaks_AlgorithmLabel   matlab.ui.control.Label
        play_FindPeaks_RadioGroup       matlab.ui.container.ButtonGroup
        play_FindPeaks_DataTips         matlab.ui.control.RadioButton
        play_FindPeaks_ROI              matlab.ui.control.RadioButton
        play_FindPeaks_auto             matlab.ui.control.RadioButton
        play_FindPeaks_Label            matlab.ui.control.Label
        play_ControlsTab3Grid           matlab.ui.container.GridLayout
        play_ControlsTab3Image          matlab.ui.control.Image
        play_ControlsTab3Label          matlab.ui.control.Label
        play_ControlsTab2Info           matlab.ui.container.GridLayout
        play_Channel_Tree               matlab.ui.container.Tree
        play_Channel_add                matlab.ui.control.Image
        play_Channel_Panel              matlab.ui.container.Panel
        play_Channel_Grid               matlab.ui.container.GridLayout
        play_Channel_FirstChannel       matlab.ui.control.NumericEditField
        play_Channel_Sample             matlab.ui.control.Label
        play_Channel_BW                 matlab.ui.control.NumericEditField
        play_Channel_BWLabel            matlab.ui.control.Label
        play_Channel_StepWidth          matlab.ui.control.NumericEditField
        play_Channel_StepWidthLabel     matlab.ui.control.Label
        play_Channel_Class              matlab.ui.control.DropDown
        play_Channel_ClassLabel         matlab.ui.control.Label
        play_Channel_nChannel           matlab.ui.control.NumericEditField
        play_Channel_nChannelLabel      matlab.ui.control.Label
        play_Channel_FirstChannelLabel  matlab.ui.control.Label
        play_Channel_nChannels          matlab.ui.control.NumericEditField
        play_Channel_nChannelsLabel     matlab.ui.control.Label
        play_Channel_List               matlab.ui.control.DropDown
        play_Channel_ListLabel          matlab.ui.control.Label
        play_Channel_RadioGroup         matlab.ui.container.ButtonGroup
        play_Channel_Single             matlab.ui.control.RadioButton
        play_Channel_Multiples          matlab.ui.control.RadioButton
        play_Channel_ReferenceList      matlab.ui.control.RadioButton
        play_Channel_Label              matlab.ui.control.Label
        play_ControlsTab2Grid           matlab.ui.container.GridLayout
        play_ControlsTab2Image          matlab.ui.control.Image
        play_ControlsTab2Label          matlab.ui.control.Label
        play_ControlsTab1Info           matlab.ui.container.GridLayout
        play_Customization              matlab.ui.control.CheckBox
        play_Waterfall_Panel            matlab.ui.container.Panel
        play_WaterFallGrid              matlab.ui.container.GridLayout
        play_Waterfall_cLim_Grid2       matlab.ui.container.GridLayout
        play_Waterfall_cLim_Separation  matlab.ui.control.Label
        play_Waterfall_cLim2            matlab.ui.control.Spinner
        play_Waterfall_cLim1            matlab.ui.control.Spinner
        play_Waterfall_cLim_Grid1       matlab.ui.container.GridLayout
        play_Waterfall_cLim_Mode        matlab.ui.control.Image
        play_Waterfall_cLim_Label       matlab.ui.control.Label
        play_Waterfall_Interpolation    matlab.ui.control.DropDown
        play_Waterfall_Interpolation_Label  matlab.ui.control.Label
        play_Waterfall_Colormap         matlab.ui.control.DropDown
        play_Waterfall_ColormapLabel    matlab.ui.control.Label
        play_Waterfall_Timestamp        matlab.ui.control.DropDown
        play_Waterfall_Timestamp_Label  matlab.ui.control.Label
        play_Waterfall_Decimation       matlab.ui.control.DropDown
        play_Waterfall_DecimationGrid   matlab.ui.container.GridLayout
        play_Waterfall_DecimationValue  matlab.ui.control.Label
        play_Waterfall_Decimation_Label  matlab.ui.control.Label
        play_OCC_Panel                  matlab.ui.container.Panel
        play_OCCGrid                    matlab.ui.container.GridLayout
        play_OCC_noisePanel             matlab.ui.container.Panel
        play_OCC_noiseGrid              matlab.ui.container.GridLayout
        play_OCC_noiseUsefulSamples     matlab.ui.control.Spinner
        play_OCC_noiseUsefulSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseTrashSamples      matlab.ui.control.Spinner
        play_OCC_noiseTrashSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseFcn               matlab.ui.control.DropDown
        play_OCC_noiseFcnLabel          matlab.ui.control.Label
        play_OCC_noiseLabel             matlab.ui.control.Label
        play_OCC_ceilFactor             matlab.ui.control.DropDown
        play_OCC_ceilFactorLabel        matlab.ui.control.Label
        play_OCC_Offset                 matlab.ui.control.Spinner
        play_OCC_OffsetLabel            matlab.ui.control.Label
        play_OCC_THRCaptured            matlab.ui.control.DropDown
        play_OCC_THR                    matlab.ui.control.Spinner
        play_OCC_THRLabel               matlab.ui.control.Label
        play_OCC_Orientation            matlab.ui.control.DropDown
        play_OCC_OrientationLabel       matlab.ui.control.Label
        play_OCC_IntegrationTimeCaptured  matlab.ui.control.NumericEditField
        play_OCC_IntegrationTime        matlab.ui.control.DropDown
        play_OCC_IntegrationTimeLabel   matlab.ui.control.Label
        play_OCC_Method                 matlab.ui.control.DropDown
        play_OCC_MethodLabel            matlab.ui.control.Label
        play_Persistance_Panel          matlab.ui.container.Panel
        play_PersistanceGrid            matlab.ui.container.GridLayout
        play_Persistance_cLim_Grid2     matlab.ui.container.GridLayout
        play_Persistance_cLim_Separation  matlab.ui.control.Label
        play_Persistance_cLim2          matlab.ui.control.Spinner
        play_Persistance_cLim1          matlab.ui.control.Spinner
        play_Persistance_cLim_Grid1     matlab.ui.container.GridLayout
        play_Persistance_cLim_Mode      matlab.ui.control.Image
        play_Persistance_cLim_Label     matlab.ui.control.Label
        play_Persistance_Transparency   matlab.ui.control.Spinner
        play_Persistance_TransparencyLabel  matlab.ui.control.Label
        play_Persistance_Colormap       matlab.ui.control.DropDown
        play_Persistance_ColormapLabel  matlab.ui.control.Label
        play_Persistance_Samples        matlab.ui.control.DropDown
        play_Persistance_SamplesLabel   matlab.ui.control.Label
        play_Persistance_Interpolation  matlab.ui.control.DropDown
        play_Persistance_InterpolationLabel  matlab.ui.control.Label
        play_ControlsPanel              matlab.ui.container.ButtonGroup
        play_RadioButton_Waterfall      matlab.ui.control.RadioButton
        play_RadioButton_Occupancy      matlab.ui.control.RadioButton
        play_RadioButton_Persistance    matlab.ui.control.RadioButton
        play_ControlPanelLabel          matlab.ui.control.Label
        play_GeneralPanel               matlab.ui.container.Panel
        play_OthersGrid                 matlab.ui.container.GridLayout
        play_LimitsPanel                matlab.ui.container.Panel
        play_LimitsGrid                 matlab.ui.container.GridLayout
        play_Limits_yLim2               matlab.ui.control.Spinner
        play_Limits_yLimLabel           matlab.ui.control.Label
        play_Limits_yLim1               matlab.ui.control.Spinner
        play_Limits_xLim2               matlab.ui.control.Spinner
        play_Limits_xLimLabel           matlab.ui.control.Label
        play_Limits_xLim1               matlab.ui.control.Spinner
        play_LimitsPanelLabel           matlab.ui.control.Label
        play_TraceIntegration           matlab.ui.control.DropDown
        play_TraceIntegrationLabel      matlab.ui.control.Label
        play_MinPlotTime                matlab.ui.control.Spinner
        play_MinPlotTimeLabel           matlab.ui.control.Label
        play_LineVisibility             matlab.ui.control.DropDown
        play_LineVisibilityLabel        matlab.ui.control.Label
        play_LayoutRatio                matlab.ui.control.DropDown
        play_LayoutRatioLabel           matlab.ui.control.Label
        play_GeneralPanelLabel          matlab.ui.control.Label
        play_ControlsTab1Grid           matlab.ui.container.GridLayout
        play_ControlsTab1Image          matlab.ui.control.Image
        play_ControlsTab1Label          matlab.ui.control.Label
        play_toolGrid                   matlab.ui.container.GridLayout
        misc_LayoutRight                matlab.ui.control.Image
        misc_LayoutLeft                 matlab.ui.control.Image
        play_Separator4                 matlab.ui.control.Image
        report_Button3                  matlab.ui.control.Button
        report_Button2                  matlab.ui.control.Button
        report_Button1                  matlab.ui.control.Button
        play_Separator3                 matlab.ui.control.Image
        play_Waterfall                  matlab.ui.control.StateButton
        play_Occupancy                  matlab.ui.control.StateButton
        play_Persistance                matlab.ui.control.StateButton
        play_MaxHold                    matlab.ui.control.StateButton
        play_Average                    matlab.ui.control.StateButton
        play_MinHold                    matlab.ui.control.StateButton
        play_Separator2                 matlab.ui.control.Image
        play_Timestamp                  matlab.ui.control.Label
        play_SelectedNode               matlab.ui.control.Label
        play_Separator1                 matlab.ui.control.Image
        play_PlaybackSlider             matlab.ui.control.Slider
        play_PlaybackLoop               matlab.ui.control.Button
        play_PlaybackPlay               matlab.ui.control.Button
        play_PlotPanel                  matlab.ui.container.Panel
        play_TreeGrid                   matlab.ui.container.GridLayout
        play_MetadataLabelGrid          matlab.ui.container.GridLayout
        play_TreeDownArrow              matlab.ui.control.Image
        play_TreeUpArrow                matlab.ui.control.Image
        play_MetadataLabel              matlab.ui.control.Label
        play_MetadataPanel              matlab.ui.container.Panel
        play_MetadataGrid               matlab.ui.container.GridLayout
        play_Metadata                   matlab.ui.control.HTML
        play_Tree                       matlab.ui.container.Tree
        play_TreeLabelGrid              matlab.ui.container.GridLayout
        play_Lock                       matlab.ui.control.Image
        play_TreeLabel                  matlab.ui.control.Label
        play_TreeTitleGrid              matlab.ui.container.GridLayout
        play_TreeTitle                  matlab.ui.control.Label
        play_TreeTitleImage             matlab.ui.control.Image
        file_ContextMenu_Tree1          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree1Node   matlab.ui.container.Menu
        file_ContextMenu_Tree2          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree2Node   matlab.ui.container.Menu
        play_FindPeaks_ContextMenu      matlab.ui.container.ContextMenu
        play_FindPeaks_ContextMenu_Drive  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_search  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_edit  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_analog  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_digital  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_del  matlab.ui.container.Menu
        play_Channel_ContextMenu        matlab.ui.container.ContextMenu
        play_Channel_ContextMenu_del    matlab.ui.container.Menu
        report_ContextMenu              matlab.ui.container.ContextMenu
        report_ContextMenu_del          matlab.ui.container.Menu
        report_ContextMenu_ExternalFiles  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        % ## GENERAL ##
        %-----------------------------------------------------------------%
        RootFolder
        General 

        channelObj
        metaData      = class.metaData.empty
        specData      = []
        projData      = struct('image', {''}, 'table', {[]})

        plotFlag      = 0
        plotLayout    = 1
        timeIndex     = 1

        %-----------------------------------------------------------------%
        % ## PLOT HANDLES ##
        %-----------------------------------------------------------------%
        axes1
        axes2
        axes3
        
        Band          = class.Band
        restoreView   = {[0,1], [0,1], [0,1], [0,1]}

        line_ClrWrite                                                       % ClearWrite (main trace)
        line_MinHold
        line_Average
        line_MaxHold
        obj_Persistance                                                     % Persistance Object
    
        mkr_ROI                                                             % Selected marker roi
        mkr_Label                                                           % Markers labels
        
        line_OCC                                                            % OCC roi/line
        line_OCCLabel                                                       % OCC roi/line label ("THR")
            
        img_WaterFall
        line_WaterFallTime                                                  % Waterfall timestamp

        %-----------------------------------------------------------------%
        % ## REPORT ##
        %-----------------------------------------------------------------%
        peaksTable    = table('Size', [0, 23],                                                                                                                                                                                                         ...
                              'VariableTypes', {'cell', 'single', 'single', 'uint16', 'double', 'single', 'double', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Latitude', 'Longitude', 'Index', 'Frequency', 'Truncated', 'BW', 'minLevel', 'meanLevel', 'maxLevel', 'meanOCC', 'maxOCC', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel', 'occMethod', 'Detection', 'Classification'});
        exceptionList = table('Size', [0, 10],                                                                                       ...
                              'VariableTypes', {'cell', 'double', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Frequency', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel'});
        
        %-----------------------------------------------------------------%
        % ## AUXILIAR APPS HANDLES ##
        %-----------------------------------------------------------------%
        auxiliarWin1                                                        % winTreeEdit
        auxiliarWin2                                                        % winDriveTest
        auxiliarWin3                                                        % winFiscaliza
        auxiliarWin4                                                        % winMaskDraw
        auxiliarWin5                                                        % winSettings
        auxiliarWin6                                                        % winRepository
        auxiliarWin7                                                        % winReport
        auxiliarWin8                                                        % winAnatelDB
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            app.UIFigure.Name = sprintf('%s %s', class.Constants.appName, class.Constants.appRelease);
            menu_PositionImageClicked(app)
        end


        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.play_PlotPanel, 4, 1, "Padding", "tight", "TileSpacing", "tight");

            % app.axes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            app.axes1.Layout.TileSpan = [2 1];

            set(app.axes1, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], Interactions=[], XTickLabel={}, ...
                                          ColorScale='log')
            ylabel(app.axes1, 'Nível (dB)')

            % app.axes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 3;

            set(app.axes2, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], Interactions=[], XTickLabel={})
            ylabel(app.axes2, 'Ocupação (%)')


            % app.axes3
            app.axes3 = uiaxes(t);
            app.axes3.Layout.Tile = 4;

            set(app.axes3, Color=[.98,.98,.98], FontName='Helvetica', FontSize=9, FontSmoothing='on', Interactions = [], Box='on')
            xlabel(app.axes3, 'Frequência (MHz)')
            ylabel(app.axes3, 'Amostras')

            % Others aspects...
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            hold(app.axes3, 'on')

            plot_Colormap_axes1(app)
            plot_Colormap_axes3(app)
            
            Interactions = class.Constants.Interactions;
            plotFcn.axesInteractions(app.axes1, Interactions)
            plotFcn.axesInteractions(app.axes2, Interactions)
            plotFcn.axesInteractions(app.axes3, Interactions)

            plotFcn.restoreViewCallback(app)

            addlistener(app.axes1, 'XLim', 'PostSet', @app.plot_xLimitsUpdate);
            addlistener(app.axes1, 'YLim', 'PostSet', @app.plot_yLimitsUpdate);

            linkaxes([app.axes1, app.axes2, app.axes3], 'x')

            plot_axesLayout(app)
        end


        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app)

            app.General = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            
            if isempty(app.General.Merge.Distance)
                app.General.Merge.Distance = Inf;
            end

            if isempty(app.General.Integration.Trace)
                app.General.Integration.Trace = Inf;
            end

            app.General.ver    = startup_Versions("Startup", app.RootFolder);
            app.General.Models = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            app.General.Report = '';

            % openGL
            eval(sprintf('opengl %s', app.General.openGL))
            
            % tempfolder
            if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                mkdir(fullfile(app.RootFolder, 'Temp'))
            end

            % userPath
            userPaths = class.Constants.userPaths;
            if ~isempty(app.General.userPath)
                userPaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userPaths):-1:1
                if ~isfolder(userPaths{ii})
                    userPaths(ii) = [];
                end
            end
            set(app.menu_userPath, 'Items', userPaths, 'Value', userPaths{end})
            app.General.userPath = app.menu_userPath.Value;

            % pythonPath
            if ~isempty(app.General.pythonPath)
                Python = pyenv;
                if ~strcmp(app.General.pythonPath, Python.Executable)
                    try
                        pyenv('Version', app.General.pythonPath);
                    catch
                    end
                end
            end

            % Others...
            app.channelObj = class.ChannelLib(app.RootFolder);
            channelList = {};
            for ii = 1:height(app.channelObj.Channel)
                channelList{end+1} = sprintf('%d: %.3f - %.3f MHz (%s)', ii, app.channelObj.Channel.Band{ii}(1), ...
                                                                         app.channelObj.Channel.Band{ii}(2),     ...
                                                                         app.channelObj.Channel.Name{ii});
            end
            app.play_Channel_List.Items = channelList';
            play_Channel_RadioGroupSelectionChanged(app)
            
            play_FindPeaks_RadioGroupSelectionChanged(app)
            play_FindPeaks_AlgorithmValueChanged(app)
            
            app.play_Persistance_Colormap.Value = app.General.Colormap.Persistance;
            app.play_Waterfall_Colormap.Value   = app.General.Colormap.WaterFall;
            app.Report_Type.Items               = app.General.Models.Name;

            if ismember(num2str(app.General.Integration.Trace), app.play_TraceIntegration.Items)
                app.play_TraceIntegration.Value = num2str(app.General.Integration.Trace);
            else
                app.General.Integration.Trace = Inf;
            end
        end


        function startup_playControlPanel(app)
            app.play_RadioButton_Persistance.Value = 1;
            play_ControlsPanelSelectionChanged(app)

            set(findobj(app.play_PersistanceGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
          % set(findobj(app.play_OCCGrid,         '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
            set(findobj(app.play_WaterFallGrid,   '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
        end


        %-----------------------------------------------------------------%
        % SWITCH MODE OPERATION
        %-----------------------------------------------------------------%
        function ModeOperation(app, btnPushed)

            switch btnPushed
                case app.menu_Button1
                    idx = 1;

                case app.menu_Button2
                    idx = 2;

                otherwise
                    idx = 3;
                    
                    switch btnPushed
                        case app.menu_Button3
                            app.play_ControlsGrid.RowHeight = {22,'1x',22,0,22,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0};
                            set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=0)

                        case app.menu_Button4
                            app.play_ControlsGrid.RowHeight = {0,0,0,0,0,0,22,'1x',22,0,22,00,0,0,0,0,0,0,0,0,0,0,0};
                            set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=1)

                        case app.menu_Button5
                            app.play_ControlsGrid.RowHeight = {0,0,0,0,0,0,0,0,0,0,0,0,22,'1x',22,0,22,0,22,0,22,0,22,0};
                            set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=0)
                    end
            end

            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);
            drawnow            
        end
        

        %-----------------------------------------------------------------%
        % Modo "ARQUIVO(S)" 
        %-----------------------------------------------------------------%        
        function file_TreeBuilding(app)

            if ~isempty(app.file_Tree.Children)
                delete(app.file_Tree.Children)
            end

            if ~isempty(app.metaData)
                file_FilterOptions(app)
                file_FilterCheck(app)

                filteredNodes = [];

                for ii = 1:numel(app.metaData)
                    [~, fileName, fileExt] = fileparts(app.metaData(ii).File);
                    
                    fileNode = uitreenode(app.file_Tree, 'Text',        [fileName fileExt],                         ...
                                                         'NodeData',    struct('level', 1, 'idx1', ii, 'idx2', []), ...
                                                         'ContextMenu', app.file_ContextMenu_Tree1);

                    receiverRawList = {app.metaData(ii).Data.Receiver};
                    [receiverList, ~, receiverIndex] = unique(receiverRawList);

                    if (numel(receiverList) == 1) && (numel(app.metaData(ii).Data) == 1)
                        fileNode.NodeData.idx2 = 1;
                    end
                    
                    for jj = 1:numel(receiverList)
                        idx = find(receiverIndex == jj)';

                        receiverNode = uitreenode(fileNode, 'Text',        fcn.treeReceiverName(receiverList{jj}),         ...
                                                            'NodeData',    struct('level', 2, 'idx1', ii, 'idx2', idx),    ...
                                                            'Icon',        fcn.treeNodeIcon('Receiver', receiverList{jj}), ...
                                                            'ContextMenu', app.file_ContextMenu_Tree1);
                        
                        for kk = idx
                            if ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.specDataTypes)
                                nodeTextNote = '';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'SpectralData');

                            elseif ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.occDataTypes)
                                nodeTextNote = ' (Ocupação)';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'Occupancy');

                            else
                                error('winAppAnalise:UnexpectedDataType', 'Unexpeted data type %d', app.metaData(ii).Data(kk).MetaData.DataType)
                            end

                            dataNode = uitreenode(receiverNode, 'Text',        sprintf('ID %d: %.3f - %.3f MHz%s', app.metaData(ii).Data(kk).RelatedFiles.ID(1),                       ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStart .* 1e-6,               ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStop .* 1e-6, nodeTextNote), ...
                                                                'NodeData',    struct('level', 3, 'idx1', ii, 'idx2', kk),                                                             ...
                                                                'Icon',        nodeIcon,                                                                                               ...
                                                                'ContextMenu', app.file_ContextMenu_Tree1);

                            if ~app.metaData(ii).Data(kk).Enable
                                dataNode.Icon = '';
                                filteredNodes = [filteredNodes, dataNode];
                            end
                        end
                    end
                end

                if ~isempty(filteredNodes)
                    addStyle(app.file_Tree, uistyle('FontColor', [.65,.65,.65]), 'node', filteredNodes)
                end

                app.file_Tree.SelectedNodes = app.file_Tree.Children(1);
                file_TreeSelectionChanged(app)

            else
                app.file_Metadata.HTMLSource              = ' ';
                app.file_FilteringType1_Frequency.Items   = {};
                app.file_FilteringType2_ID.Items          = {};
                app.file_FilteringType3_Description.Value = '';
            end
        end


        %-----------------------------------------------------------------%
        function file_FilterOptions(app)
            bandList = table('Size', [0,3], ...
                             'VariableTypes', {'double', 'double', 'string'}, ...
                             'VariableNames', {'FreqStart', 'FreqStop', 'Band'});
            IDList   = [];
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)

                    FreqStart = app.metaData(ii).Data(jj).MetaData.FreqStart;
                    FreqStop  = app.metaData(ii).Data(jj).MetaData.FreqStop;

                    bandList(end+1,:) = {FreqStart, FreqStop, sprintf('%.3f - %.3f MHz', FreqStart/1e+6, FreqStop/1e+6)};
                    IDList(end+1,1)   = app.metaData(ii).Data(jj).RelatedFiles.ID(1);
                end
            end
            bandList = sortrows(bandList, {'FreqStart', 'FreqStop'});

            app.file_FilteringType1_Frequency.Items = unique(bandList.Band, "rows", "stable");
            app.file_FilteringType2_ID.Items        = unique(string(sort(IDList)), "rows", "stable");
        end


        %-----------------------------------------------------------------%
        function file_FilterCheck(app)
            hFilter = allchild(app.file_FilteringTree);

            if isempty(hFilter)
                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = true;
                    end
                end

            else
                filterTextList = strjoin({hFilter.Text}, '\n');                
                filterParser   = struct2table(regexp(filterTextList, '(?<Type>(FREQUÊNCIA|ID|DESCRIÇÃO))[:] (?<Sentence>.*)', 'names', 'dotexceptnewline'));
                if ~iscell(filterParser.Sentence)
                    filterParser.Sentence = {filterParser.Sentence};
                end

                filterSentence_Frequency   = filterParser.Sentence(filterParser.Type == "FREQUÊNCIA");
                filterSentence_ID          = str2double(filterParser.Sentence(filterParser.Type == "ID"));
                filterSentence_Description = filterParser.Sentence(filterParser.Type == "DESCRIÇÃO");

                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = false;

                        % FREQUÊNCIA
                        if ~isempty(filterSentence_Frequency)
                            dataSentence = sprintf('%.3f - %.3f MHz', app.metaData(ii).Data(jj).MetaData.FreqStart .* 1e-6, ...
                                                                      app.metaData(ii).Data(jj).MetaData.FreqStop  .* 1e-6);
                            if ismember(dataSentence, filterSentence_Frequency)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % ID
                        if ~isempty(filterSentence_ID)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.ID(1);

                            if ismember(dataSentence, filterSentence_ID)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % DESCRIÇÃO
                        if ~isempty(filterSentence_Description)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.Description{1};

                            if any(cellfun(@(x) contains(dataSentence, x, "IgnoreCase", true), replace(filterSentence_Description, '"', '')))
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function file_DataReaderError(app)            
            app.specData = [];
            
            app.menu_Button3.Enable = 0;
            app.menu_Button4.Enable = 0;
            app.menu_Button5.Enable = 0;
            
            app.menu_Button2.Value  = 1;
            menu_ButtonModePushed(app, struct('Source', app.menu_Button2)) 
        end


        %-----------------------------------------------------------------%
        % Modo "PLAYBACK/REPORT" 
        %-----------------------------------------------------------------%
        function play_TreeBuilding(app)

            if ~isempty(app.play_Tree.Children)
                delete(app.play_Tree.Children)
            end
            app.play_Metadata.HTMLSource       = ' ';
            
            receiverRawList = {app.specData.Receiver};
            [receiverList, ~, receiverIndex] = unique(receiverRawList);            
            for ii = 1:numel(receiverList)
                idx1 = find(receiverIndex == ii)';

                receiverNode = uitreenode(app.play_Tree, 'Text', receiverList{ii}, ...
                                                               'NodeData', idx1,         ...
                                                               'Icon', fcn.treeNodeIcon('Receiver', receiverList{ii}));
                                
                for jj = idx1
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        nodeTextNote = '';
                        nodeIcon     = fcn.treeNodeIcon('DataType', 'SpectralData');
                        
                    else
                        nodeTextNote = ' (Ocupação)';
                        nodeIcon     = fcn.treeNodeIcon('DataType', 'Occupancy');
                    end

                    dataNode = uitreenode(receiverNode, 'Text', sprintf('ID %d: %.3f - %.3f MHz%s', app.specData(jj).RelatedFiles.ID(1),        ...
                                                                                                    app.specData(jj).MetaData.FreqStart / 1e+6, ...
                                                                                                    app.specData(jj).MetaData.FreqStop  / 1e+6, ...
                                                                                                    nodeTextNote),                              ...
                                                        'Icon', nodeIcon, 'NodeData', jj);

                    % TEMPO DE OBSERVAÇÃO
                    uitreenode(dataNode, 'Text', sprintf('%s - %s', datestr(app.specData(jj).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'),  ...
                                                                    datestr(app.specData(jj).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')), ...
                                           'NodeData', jj);
                        
                    % GPS
                    if ~app.specData(jj).GPS.Status
                        uitreenode(dataNode, 'Text',     'GPS', ...
                                             'NodeData', jj,    ...
                                             'Icon',     'LT_redWarn.png');
                    else
                        gpsNode = uitreenode(dataNode, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).GPS.Latitude,  ...
                                                                                          app.specData(jj).GPS.Longitude, ...
                                                                                          app.specData(jj).GPS.Location), ...
                                                       'NodeData', jj);
                        if app.specData(jj).GPS.Status == -1
                            gpsNode.Icon = 'LT_manual.png';
                        end
                    end

                    % OCC
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        if ~isempty(app.specData(jj).UserData.occMethod.RelatedThreadIndex)
                            occNode = uitreenode(dataNode, 'Text',     'Fluxo(s) de ocupação', ...
                                                           'NodeData', jj);
                            
                            idx2 = app.specData(jj).UserData.occMethod.RelatedThreadIndex;
                            for kk = idx2
                                occChildThreadNode = uitreenode(occNode, 'Text', sprintf('Threshold: %d %s (ID %d)', app.specData(kk).MetaData.Threshold,  ...
                                                                                                                     app.specData(kk).MetaData.LevelUnit,  ...
                                                                                                                     app.specData(kk).RelatedFiles.ID(1)), ...
                                                                         'NodeData', jj);

                                if app.specData(jj).UserData.occMethod.SelectedThreadIndex == kk
                                    occChildThreadNode.Icon = 'LT_selected.png';
                                end                     
                            end

                            occChildPlaybackNode = uitreenode(occNode, 'Text', 'Ocupação a ser aferida', 'NodeData', jj);
                            if isempty(app.specData(jj).UserData.occMethod.SelectedThreadIndex)
                                occChildPlaybackNode.Icon = 'LT_selected.png';
                            end
                        end
                    end
                end
            end

            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii));
            end
        end


        %-----------------------------------------------------------------%
        function generation = play_findGenerationTreeNode(app)
            referenceNode = app.play_Tree.SelectedNodes;
            generation    = 0;
            while true
                if referenceNode.Parent ~= app.play_Tree
                    referenceNode = referenceNode.Parent;
                    generation = generation+1;
                else
                    break
                end
            end
        end


        %-----------------------------------------------------------------%
        function occInfo = play_OCCInfo(app)
            occInfo = struct('Method',                  app.play_OCC_Method.Value,                      ...
                             'IntegrationTime',         str2double(app.play_OCC_IntegrationTime.Value), ...
                             'IntegrationTimeCaptured', app.play_OCC_IntegrationTimeCaptured.Value,     ...
                             'THR',                     app.play_OCC_THR.Value,                         ...
                             'THRCaptured',             str2double(app.play_OCC_THRCaptured.Value),     ...
                             'Offset',                  app.play_OCC_Offset.Value,                      ...
                             'ceilFactor',              app.play_OCC_ceilFactor.Value,                  ...
                             'noiseFcn',                app.play_OCC_noiseFcn.Value,                    ...
                             'noiseTrashSamples',       app.play_OCC_noiseTrashSamples.Value/100,       ...
                             'noiseUsefulSamples',      app.play_OCC_noiseUsefulSamples.Value/100);

            switch occInfo.Method
                case 'Linear fixo (COLETA)'; occInfo = rmfield(occInfo, {'IntegrationTime',         'THR',         'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear fixo';          occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THRCaptured', 'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear adaptativo';    occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured', 'ceilFactor'});
                case 'Envoltória do ruído';  occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured'});
            end
        end


        %-----------------------------------------------------------------%
        function occIndex = play_OCCIndex(app, idx)
            occInfo  = play_OCCInfo(app);
            occIndex = find(cellfun(@(x) isequal(x, occInfo), {app.specData(idx).UserData.occCache.Info}));
            
            if isempty(occIndex)
                occIndex = numel(app.specData(idx).UserData.occCache)+1;
                occTHR   = class.OCC.Threshold(app.specData(idx), occInfo);

                switch occInfo.Method
                    case 'Linear fixo (COLETA)'
                        occData = app.specData(app.specData(idx).UserData.occMethod.SelectedThreadIndex).Data;

                    otherwise
                        app.specData(idx).UserData.occMethod.SelectedThreadIndex = [];
                        occData = class.OCC.Analysis(app.specData(idx), occInfo, occTHR);
                end

                app.specData(idx).UserData.occCache(occIndex) = struct('Info',  occInfo, ...
                                                                       'THR',   occTHR,  ...
                                                                       'Data', {occData});
            end

            app.specData(idx).UserData.occMethod.CacheIndex   = occIndex;
        end


        %-----------------------------------------------------------------%
        function selectedTreeNode = play_SelectedTreeNode(app, idx)
            selectedTreeNode = [];
            for ii = 1:numel(app.play_Tree.Children)
                for jj = 1:numel(app.play_Tree.Children(ii).Children)
                    if app.play_Tree.Children(ii).Children(jj).NodeData == idx
                        selectedTreeNode = app.play_Tree.Children(ii).Children(jj);
                        break
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_OCCNewPlot(app, EventSource)
            % Layout
            [idx, ~, LevelUnit] = plot_updateLine(app);

            switch EventSource
                case app.play_OCC_Method
                    switch app.play_OCC_Method.Value
                        case 'Linear fixo (COLETA)'
                            app.specData(idx).UserData.occMethod.SelectedThreadIndex = app.specData(idx).UserData.occMethod.RelatedThreadIndex(1);
    
                            selectedRealIndex = app.specData(idx).UserData.occMethod.SelectedThreadIndex;
                            app.play_OCC_IntegrationTimeCaptured.Value = mean(app.specData(selectedRealIndex).RelatedFiles.RevisitTime)/60;
                            app.play_OCC_THRCaptured.Value             = num2str(app.specData(selectedRealIndex).MetaData.Threshold);

                            selectedTreeNode = play_SelectedTreeNode(app, idx);            
                            set(selectedTreeNode.Children(end).Children, Icon='')
                            selectedTreeNode.Children(end).Children(end).Icon = 'LT_selected.png';

                        otherwise
                            if ~isempty(app.specData(idx).UserData.occMethod.RelatedThreadIndex) && app.play_Occupancy.Value
                                app.specData(idx).UserData.occMethod.SelectedThreadIndex = [];

                                selectedTreeNode = play_SelectedTreeNode(app, idx);            
                                set(selectedTreeNode.Children(end).Children, Icon='')
                                selectedTreeNode.Children(end).Children(end).Icon = 'LT_selected.png';
                            end

                    end
                    layoutFcn.occVisibility(app, LevelUnit)

                case app.play_OCC_THRCaptured
                    % A ideia aqui é atualizar a representação gráfica da
                    % árvore sem precisar recriar todos os seus nós. Recriar 
                    % a árvore, diga-se, é um aspecto que não deve ser feito 
                    % porque tornaria inválido o handle do nó selecionado da 
                    % árvore, o qual fica armazenado na propriedade "UserData" 
                    % de app.play_PlotPanel.
                    selectedVirtualIndex = find(strcmp(app.play_OCC_THRCaptured.Items, app.play_OCC_THRCaptured.Value), 1);
                    selectedRealIndex    = app.specData(idx).UserData.occMethod.RelatedThreadIndex(selectedVirtualIndex);

                    selectedTreeNode = play_SelectedTreeNode(app, idx);
                    set(selectedTreeNode.Children(end).Children, Icon='')
                    selectedTreeNode.Children(end).Children(selectedVirtualIndex).Icon = 'LT_selected.png';
                    
                    app.specData(idx).UserData.occMethod.SelectedThreadIndex = selectedRealIndex;
                    app.play_OCC_IntegrationTimeCaptured.Value = mean(app.specData(selectedRealIndex).RelatedFiles.RevisitTime)/60;
            end

            % Plot
            if app.play_Occupancy.Value
                occIndex = play_OCCIndex(app, idx);
                plotFcn.OCC(app, idx, 'Creation', LevelUnit, occIndex)
            end
        end


        %-----------------------------------------------------------------%
        function play_Channel_TreeBuilding(app, idx)
            
            if ~isempty(app.play_Channel_Tree.Children)
                delete(app.play_Channel_Tree.Children)
            end

            channelLibIndex = app.specData(idx).UserData.channelLibIndex;
            if ~isempty(channelLibIndex)
                for ii = channelLibIndex'
                    uitreenode(app.play_Channel_Tree, 'Text', sprintf('%.3f - %.3f MHz (%s)',             ...
                                                                      app.channelObj.Channel.Band{ii}(1), ...
                                                                      app.channelObj.Channel.Band{ii}(2), ...
                                                                      app.channelObj.Channel.Name{ii}),   ...
                                                      'NodeData', ii, 'ContextMenu', app.play_Channel_ContextMenu);
                end
            end

            channelManual = app.specData(idx).UserData.channelManual;
            manualNodes   = [];
            for ii = 1:height(channelManual)
                if channelManual.FirstChannel(ii) == channelManual.LastChannel(ii)
                    treeText = sprintf('%.3f MHz', channelManual.FirstChannel(ii));
                else
                    treeText = sprintf('%.3f - %.3f MHz', channelManual.FirstChannel(ii), channelManual.LastChannel(ii));
                end

                manualNodes = [manualNodes, uitreenode(app.play_Channel_Tree, 'Text', treeText,               ...
                                                                              'NodeData', -1, 'UserData', ii, ...
                                                                              'ContextMenu', app.play_Channel_ContextMenu)];
            end

            if ~isempty(manualNodes)
                addStyle(app.play_Channel_Tree, uistyle('FontColor', 'red'), 'node', manualNodes)
            end
        end


        %-----------------------------------------------------------------%
        function play_Channel_TreeSelectionChanged(app)
            
            if numel(app.play_Channel_Tree.SelectedNodes) == 1
                chIndex = app.play_Channel_Tree.SelectedNodes.NodeData;
                
                if chIndex > 0
                    app.play_Channel_ReferenceList.Value = 1;
                    play_Channel_RadioGroupSelectionChanged(app)

                    app.play_Channel_List.Value = app.play_Channel_List.Items{chIndex};
                    play_Channel_ListValueChanged(app)

                else
                    idx = app.play_PlotPanel.UserData.NodeData;
                    chManualIndex = app.play_Channel_Tree.SelectedNodes.UserData;

                    if app.specData(idx).UserData.channelManual.StepWidth(chManualIndex) > 0
                        app.play_Channel_Multiples.Value = 1;
                    else
                        app.play_Channel_Single.Value    = 1;
                    end
                    play_Channel_RadioGroupSelectionChanged(app)

                    app.play_Channel_FirstChannel.Value  = app.specData(idx).UserData.channelManual.FirstChannel(chManualIndex);
                    app.play_Channel_nChannel.Value      = app.specData(idx).UserData.channelManual.LastChannel(chManualIndex);
                    app.play_Channel_StepWidth.Value     = max(-1, app.specData(idx).UserData.channelManual.StepWidth(chManualIndex) * 1000);
                    app.play_Channel_BW.Value            = max(-1, app.specData(idx).UserData.channelManual.ChannelBW(chManualIndex) * 1000);
                    app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name(app.specData(idx).UserData.channelManual.FindPeaksIndex(chManualIndex));
        
                    if app.play_Channel_StepWidth.Value > 0
                        FreqList = app.specData(idx).UserData.channelManual.FirstChannel(chManualIndex):app.specData(idx).UserData.channelManual.StepWidth(chManualIndex):app.specData(idx).UserData.channelManual.LastChannel(chManualIndex);
                    else
                        FreqList = app.play_Channel_FirstChannel.Value;
                    end
        
                    app.play_Channel_nChannels.Value     = numel(FreqList);
                    play_ChannelListSample(app, FreqList)
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_ChannelListSample(app, FreqList)

            if numel(FreqList) <= 4
                app.play_Channel_Sample.Text = strjoin(string(FreqList) + " MHz", ', ');
            else
                app.play_Channel_Sample.Text = strjoin(string(FreqList(1:2)) + " MHz", ', ') + " ... " + strjoin(string(FreqList(end-1:end)) + " MHz", ', ');
            end
        end


        %-----------------------------------------------------------------%
        function play_ChannelAddValidations(app, idx)

            if app.play_Channel_ReferenceList.Value
                chIndex    = str2double(extractBefore(app.play_Channel_List.Value, ':'));
                BandLimits = app.channelObj.Channel.Band{chIndex};

                Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits);
                if Flag == 1
                    app.specData(idx).UserData.channelLibIndex = unique([app.specData(idx).UserData.channelLibIndex; chIndex]);
                end

            else % 'Faixa de frequência' | 'Canal'
                BandLimits = [app.play_Channel_FirstChannel.Value - app.play_Channel_BW.Value/2000, ...
                              app.play_Channel_nChannel.Value     + app.play_Channel_BW.Value/2000];

                Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits);
                if Flag == 1
                    newChannelID = sprintf('%.3f - %.3f MHz (StepWidth: %.1f kHz; BW: %.1f kHz)', app.play_Channel_FirstChannel.Value, ...
                                                                                                  app.play_Channel_nChannel.Value,     ...
                                                                                                  app.play_Channel_StepWidth.Value,    ...
                                                                                                  app.play_Channel_BW.Value);
                    newChannelFlag = any(strcmp(app.specData(idx).UserData.channelManual.Name, newChannelID));
                    if ~newChannelFlag
                        FindPeaksIndex = find(strcmp(app.channelObj.FindPeaks.Name, app.play_Channel_Class.Value), 1);
                        app.specData(idx).UserData.channelManual(end+1,:) = {newChannelID,                            ...
                                                                             {BandLimits},                            ...
                                                                             app.play_Channel_FirstChannel.Value,     ...
                                                                             app.play_Channel_nChannel.Value,         ...
                                                                             app.play_Channel_StepWidth.Value / 1000, ...
                                                                             app.play_Channel_BW.Value        / 1000, ...
                                                                             {}, 'Inclusão manual', FindPeaksIndex};
                    else
                        Flag = -4;
                    end
                end
            end
        
            switch Flag
                case  1; play_Channel_TreeBuilding(app,  idx)
                case -1; layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'A frequência central do n-ésimo canal deve ser superior à do 1º canal.')
                case -2; layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'O passo da varredura não pode ser menor ou igual a 0 Hertz.')
                case -3; layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Fluxo de espectro e canalização não se sobrepõem.')
                case -4; layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Canalização já incluída.')
            end
        end


        %-----------------------------------------------------------------%
        function Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits)

            FreqStart  = app.specData(idx).MetaData.FreqStart/1e+6;
            FreqStop   = app.specData(idx).MetaData.FreqStop /1e+6;
            
            Flag = 1;            
            if app.play_Channel_Multiples.Value
                if ~issorted(BandLimits) || (app.play_Channel_nChannel.Value < app.play_Channel_FirstChannel.Value)                                                                             
                    Flag = -1;
                elseif app.play_Channel_StepWidth.Value <= 0
                    Flag = -2;
                end
            else
                if~(((FreqStart >= BandLimits(1)) && (FreqStart <= BandLimits(2))) || ((FreqStart <= BandLimits(1)) && (FreqStop  >= BandLimits(1))))
                    Flag = -3;
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_EmissionList(app, idx, selectedEmission)

            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end

            if ~isempty(app.specData(idx).UserData.Emissions)
                for ii = 1:height(app.specData(idx).UserData.Emissions)
                    if app.specData(idx).UserData.Emissions.isTruncated(ii); Icon = 'play_Truncated.png';
                    else;                                                    Icon = 'play_Untruncated.png';
                    end

                    uitreenode(app.play_FindPeaks_Tree, 'Text', sprintf('%d: %.3f MHz ⌂ %.3f kHz', ii, app.specData(idx).UserData.Emissions{ii,2}, app.specData(idx).UserData.Emissions{ii,3}), ...
                                                        'NodeData', ii, 'Icon', Icon, 'ContextMenu', app.play_FindPeaks_ContextMenu);
                end

                app.play_FindPeaks_Tree.SelectedNodes = app.play_FindPeaks_Tree.Children(selectedEmission);
            end
            play_FindPeaks_TreeSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        function plot_axesLayout(app)

            if app.play_Occupancy.Value && app.play_Waterfall.Value
                app.play_LayoutRatio.Items = {'2:1:1', '1:2:1', '1:1:2'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};
                
                xlabel(app.axes2, '')
                app.axes2.XTickLabel = {};

            elseif app.play_Occupancy.Value
                app.play_LayoutRatio.Items = {'3:1:0', '1:1:0', '1:3:0'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};

                xlabel(app.axes2, 'Frequência (MHz)')
                app.axes2.XTickLabelMode = 'auto';

            elseif app.play_Waterfall.Value
                app.play_LayoutRatio.Items = {'3:0:1', '1:0:1', '1:0:3'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};

            else
                app.play_LayoutRatio.Items = {'1:0:0'};
                
                xlabel(app.axes1, 'Frequência (MHz)')
                app.axes1.XTickLabelMode = 'auto';
            end

            plot_axesPosition(app)
            play_LineVisibilityValueChanged(app)
        end


        %-----------------------------------------------------------------%
        function plot_axesPosition(app)
            tiledSpan = str2double(strsplit(app.play_LayoutRatio.Value, ':'));
            tiledSpan = (4/sum(tiledSpan)) * tiledSpan;

            tiledPos = 1;
            for ii = 1:3
                if tiledSpan(ii) == 0
                    eval(sprintf('set(app.axes%d,          Visible=0)', ii))
                    eval(sprintf('set(app.axes%d.Children, Visible=0)', ii))
                    eval(sprintf('set(app.axes%d.Toolbar,  Visible=0)', ii))

                else
                    eval(sprintf('set(app.axes%d,          Visible=1)', ii))
                    eval(sprintf('set(app.axes%d.Children, Visible=1)', ii))
                    eval(sprintf('set(app.axes%d.Toolbar,  Visible=1)', ii))

                    eval(sprintf('app.axes%d.Layout.Tile = tiledPos;', ii))
                    eval(sprintf('app.axes%d.Layout.TileSpan = [tiledSpan(ii) 1];', ii))
                end

                tiledPos = tiledPos+tiledSpan(ii);
            end
        end


        %-----------------------------------------------------------------%
        function plot_Colormap_axes1(app)
            colormap(app.axes1, app.play_Persistance_Colormap.Value);
            app.axes1.Colormap(1,:) = [0,0,0];
        end


        %-----------------------------------------------------------------%
        function plot_Colormap_axes3(app)
            colormap(app.axes3, app.play_Waterfall_Colormap.Value);
            app.axes3.Colormap(1,:) = [0,0,0];
        end


        %-----------------------------------------------------------------%
        function plot_xLimitsUpdate(app, src, evt)

            app.play_Limits_xLim1.Value = round(app.axes1.XLim(1), 3);
            app.play_Limits_xLim2.Value = round(app.axes1.XLim(2), 3);

        end
                
        
        %-----------------------------------------------------------------%
        function plot_yLimitsUpdate(app, src, evt)

            app.play_Limits_yLim1.Value = round(double(app.axes1.YLim(1)), 1);
            app.play_Limits_yLim2.Value = round(double(app.axes1.YLim(2)), 1);

        end


        %-----------------------------------------------------------------%
        function [idx, nSweeps] = plot_updateIndex(app)
            idx = app.play_Tree.SelectedNodes.NodeData;
            nSweeps = numel(app.specData(idx).Data{1});
            
            plotFcn.StartUp(app, idx)
            plotFcn.Draw(app, idx)
          % plot_axesLayout(app)

            app.plotFlag = 1;
        end


        %-----------------------------------------------------------------%
        function [idx, newArray, LevelUnit] = plot_updateLine(app)
            idx = [];
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;
            end

            newArray  = app.specData(idx).Data{2}(:,app.timeIndex);
            LevelUnit = app.specData(idx).MetaData.LevelUnit;
        end


        %-----------------------------------------------------------------%
        function plot_resetLines(app)
            if ~isempty(app.line_MinHold)
                plot_deleteLine(app, 'MinHold')
                play_ControlButtonPushed(app, struct('Source', app.play_MinHold))
            end

            if ~isempty(app.line_Average)
                plot_deleteLine(app, 'Average')
                play_ControlButtonPushed(app, struct('Source', app.play_Average))
            end

            if ~isempty(app.line_MaxHold)
                plot_deleteLine(app, 'MaxHold')
                play_ControlButtonPushed(app, struct('Source', app.play_MaxHold))
            end
        end


        %-----------------------------------------------------------------%
        function plot_deleteLine(app, Tag)
            switch Tag
                case 'MinHold'
                    delete(app.line_MinHold)
                    app.line_MinHold = [];

                case 'Average'
                    delete(app.line_Average)
                    app.line_Average = [];

                case 'MaxHold'
                    delete(app.line_MaxHold)
                    app.line_MaxHold = [];
            end
        end


        %-----------------------------------------------------------------%
        function plot_MainLoop(app, idx, nSweeps)

            while app.timeIndex <= nSweeps
                % A variável app.plotFlag pode assumir os valores -1 | 0 | 1.
                % - -1: alteração de fluxo espectral
                % -  0: finalização do plot
                % -  1: atualização do plot
                switch app.plotFlag
                    case -1; [idx, nSweeps] = plot_updateIndex(app);
                    case  0; return
                end
                sweepTic = tic;
                
                plotFcn.Draw(app, idx)                
                % Misc_DriveTestUpdate(app)
                
                pause(max(app.play_MinPlotTime.Value/1000-toc(sweepTic), .001))

                app.play_Timestamp.Text   = sprintf('%d de %d\n%s', app.timeIndex, nSweeps, app.specData(idx).Data{1}(app.timeIndex));
                app.play_PlaybackSlider.Value  = 100 * app.timeIndex/nSweeps;
                
                % Reload Flag
                if app.timeIndex == nSweeps
                    if app.play_PlaybackLoop.Tag == "loop"
                        app.timeIndex = 1;
                    else
                        break
                    end
                else
                    app.timeIndex = app.timeIndex+1;
                end                
            end
            
            play_PlayButtonPushed(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                % PATH SEARCH
                appName = class.Constants.appName;

                if isdeployed
                    [~, result]    = system('path');
                    app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                        error('Não identificado o local de instalação do <i>app</i>.')
                    end                
                else
                    app.RootFolder = fileparts(mfilename('fullpath'));
                end

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')

                % LOG (DIARY)
                diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))                
                diary on
                fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now)))

                % STARTUP
                cd(app.RootFolder)
                
                startup_WindowPosition(app)
                startup_AxesCreation(app)
                startup_ConfigFiles(app)
                startup_playControlPanel(app)

                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);

            catch ME
                fprintf('%s\n', jsonencode(ME))
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % RUNNING PROJECT CHECK
            if ~isempty(char(app.ProjectFilename.Value)) && app.Report_ImageWarn.Visible
                Selection = uiconfirm(app.UIFigure, sprintf('<font style="font-size:12;">O projeto aberto - registrado no arquivo <b>"%s"</b> - foi alterado.\n\nDeseja descartar essas alterações? Caso não, favor salvá-las no modo "RELATÓRIO".</font>', char(app.ProjectFilename.Value)), ...
                                      'appAnalise', 'Options', {'Sim', 'Não'}, 'DefaultOption', 2, 'Interpreter', 'html');
                if strcmp(Selection, 'Não')
                    return
                end
            end

            % LOG
            fprintf(sprintf('<EndTime>%s</EndTime>\n</LogEntry>\n', datestr(now)))
            diary off
            
            log = fileread(fullfile(app.RootFolder, 'Debug', '_log.txt'));
            if contains(log, 'error', 'IgnoreCase', true)
                writematrix(log, fullfile(app.RootFolder, 'Debug', '_error.txt'), "QuoteStrings", "none", "WriteMode", "append")
                writematrix("",  fullfile(app.RootFolder, 'Debug', '_log.txt'),   "QuoteStrings", "none", "WriteMode", "overwrite")
            end

            % DELETE TEMP FILES
            delete(fullfile(app.RootFolder, 'Temp', '~Report*.html'))
            delete(fullfile(app.RootFolder, 'Temp', '~Image*.*'))

            delete(fullfile(app.menu_userPath.Value, '~Report*.html'))
            delete(fullfile(app.menu_userPath.Value, '~Image*.*'))

            % DELETE APPS
            % Misc_DeleteAllWindows(app)
            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 3 other components
        function menu_ButtonModePushed(app, event)

            if event.Source.Value
                set(findall(groot, 'Parent', app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', event.Source.Tag), 'Value', 0)
            else
                event.Source.Value = 1;
                return
            end

            ModeOperation(app, event.Source)
            
        end

        % Callback function: menu_Button6, menu_Button7, menu_Button8, 
        % ...and 1 other component
        function menu_ButtonExternalAppPushed(app, event)
            
            switch event.Source
                case app.menu_Button6;  h = app.auxWin7;
                case app.menu_Button7;  h = app.auxWin3;
                case app.menu_Button8;  h = app.auxWin8;
                case app.menu_Settings; h = app.auxWin5;
            end

            if isempty(h)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = '<font style="font-size:12;">Em andamento...</font>';

                switch event.Source
                    case app.menu_Button7
                        try
                            app.auxWin3 = auxApp.winCredential(app, app.UIFigure.Position, app.Report_Issue.Value);
                        catch ME
                            MessageBox(app, 'error', ME.message)
                        end

                    case app.menu_Button6;  app.auxWin7 = auxApp.winTemplate(app);
                    case app.menu_Button8;  app.auxWin8 = auxApp.winAnatelDB_old(app);
                    case app.menu_Settings; app.auxWin5 = auxApp.winConfig(app);
                end
                drawnow nocallbacks

                delete(d)                
            else
                figure(h.UIFigure)
            end

        end

        % Value changed function: menu_userPath
        function menu_userPathValueChanged(app, event)
            
            app.General.userPath = app.menu_userPath.Value;        

            if ~ismember(app.menu_userPath.Value, class.Constants.userPaths)
                fcn.GeneralSettings(app.General, app.RootFolder)
            end
            
        end

        % Image clicked function: menu_searchPath
        function menu_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.UIFigure)
            
            if newUserPath
                idx = find(strcmp(app.menu_userPath.Items, newUserPath), 1);
                
                if isempty(idx)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    menu_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(idx);
                end
            end
            
        end

        % Image clicked function: menu_Position
        function menu_PositionImageClicked(app, event)

            app.UIFigure.Position(3:4) = class.Constants.windowSize;
            fcn.winPosition(app)
            
        end

        % Callback function: home_Button1, home_Button2, home_Button3, 
        % ...and 2 other components
        function mosaic_ButtonPushed(app, event)
            
            switch event.Source
                case app.home_Button1; app.menu_Button2.Value = 1; menu_ButtonModePushed(app, struct('Source', app.menu_Button2))
                case app.home_Button2; menu_ButtonExternalAppPushed(app, struct('Source', app.menu_Button6))
                case app.home_Button3; menu_ButtonExternalAppPushed(app, struct('Source', app.menu_Button7))
                case app.home_Button4; menu_ButtonExternalAppPushed(app, struct('Source', app.menu_Button8))
                case app.home_Button5; menu_ButtonExternalAppPushed(app, struct('Source', app.menu_Settings))
            end

        end

        % Button pushed function: file_OpenFileButton
        function file_ButtonPushed_OpenFile(app, event)

            [fileName, filePath, fileExt] = uigetfile({'*.bin',    'appColeta/Logger (*.bin)';        ...
                                                       '*.dbm',    'CellPlan (*.dbm)';                ...
                                                       '*.sm1809', 'SM1809 (*.sm1809)';               ...
                                                       '*.csv',    'Argus (*.csv)';                   ...
                                                       '*.mat',    'appAnalise/appColetaV1 (*.mat)'}, ...
                                                       'MultiSelect', 'on');
            figure(app.UIFigure)
            
            if ~fileExt
                return
            end
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            
            if ~iscell(fileName)
                fileName = {fileName};
            end
            
            RepeteadFiles = {};
            for ii = 1:numel(fileName)
                d.Message = sprintf('<font style="font-size:12;">Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d</font>', fileName{ii}, ii, numel(fileName));
                
                fileFullPath = fullfile(filePath, fileName{ii});
                RelatedFiles = app.metaData.RelatedFiles;
                   
                switch fileExt
                    case {1, 2, 3, 4}
                        if ~any(contains(RelatedFiles, fileName(ii)))
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = 'Spectral data';
                        else
                            RepeteadFiles(end+1) = fileName(ii);
                            continue
                        end
                        
                    case 5
                        lastwarn('')
                        load(fileFullPath, '-mat', 'prj_Type', 'prj_RelatedFiles')
                        [~, warnID] = lastwarn;
                        
                        % Validações...
                        if strcmp(warnID, 'MATLAB:load:variableNotFound')
                            msg = sprintf('O arquivo indicado a seguir não possui a variável "prj_Type", não sendo, portanto, um arquivo gerado pelo appAnálise ou appColeta.\n• %s', fileName{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        elseif ismember(fileFullPath, {app.metaData.File})
                            msg = sprintf('O arquivo indicado a seguir já tinha sido lido.\n• %s', fileName{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        elseif any(contains(RelatedFiles, prj_RelatedFiles))
                            msg = sprintf(['O arquivo indicado a seguir não será lido por já ter sido lido ao menos um arquivo relacionado ao ' ...
                                           'projeto appAnálise.\n• %s\n\nArquivo(s) relacionado(s) ao projeto appAnálise já lido(s):\n%s'],   ...
                                           fileName{ii}, strjoin(cellfun(@(x) sprintf('• %s', x), RelatedFiles(contains(RelatedFiles, prj_RelatedFiles)), 'UniformOutput', false), '\n'));
                            MessageBox(app, 'warning', msg)
                            
                            continue

                        elseif ~isempty(app.metaData) && strcmp(prj_Type{1}, 'Project data') && ismember('Project data', {app.metaData.Type})
                            msg = sprintf('O arquivo indicado a seguir não será lido porque já foram lidos os metadados de outro projeto appAnálise.\n• %s', fileName{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        else
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = char(prj_Type);
                        end
                end
                
                try
                    Read_MetaData(app.metaData(idx), app.RootFolder)

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'error', getReport(ME))

                    switch ME.identifier
                        case {'metaData:Read:NotImplementedReader', 'metaData:Read:EmptySpectralData'}
                            app.metaData(idx) = [];
                            ErrorFlag = 0;

                        otherwise
                            app.metaData = class.metaData.empty;
                            ErrorFlag = 1;
                    end
                    fclose all;

                    if ErrorFlag
                        break
                    end
                end
            end
            
            if ~isempty(RepeteadFiles)
                msg = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                
                for jj = 1:numel(RepeteadFiles)
                    msg = sprintf('%s\n• %s', msg, RepeteadFiles{jj});
                end
                MessageBox(app, 'warning', msg)
            end

            file_TreeBuilding(app)
            focus(app.file_Tree)

        end

        % Button pushed function: file_RepositoryButton
        function file_ButtonPushed_Repository(app, event)
            
            MessageBox(app, 'error', 'Funcionalidade pendente de implementação!')

        end

        % Button pushed function: file_SpecReadButton
        function file_ButtonPushed_SpecRead(app, event)
            
            % Verificar se há ao menos um fluxo a ser lido...
            flag = false;
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)
                    if app.metaData(ii).Data(jj).Enable
                        flag = true;
                        break
                    end
                end
            end

            if ~flag
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não há nenhum fluxo de informação a ser lido...');
                return
            end

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            
            try
                fcn.Read_specData(app, d)
            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))

                file_DataReaderError(app)
                return
            end

            % Constroi a árvore de fluxos espectrais, deixando selecionado
            % o primeiro dos fluxos.            
            play_TreeBuilding(app)
            app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
            play_TreeSelectionChanged(app)

            % Habilita botões do menu principal - PLAYBACK, REPORT e MISC -
            % abrindo programaticamente o modo PLAYBACK.
            set(app.menu_Button3, Enable=1, Value=1) 
            set(app.menu_Button4, Enable=1)
            set(app.menu_Button5, Enable=1)
            menu_ButtonModePushed(app, struct('Source', app.menu_Button3))

            focus(app.play_Tree)

        end

        % Selection changed function: file_Tree
        function file_TreeSelectionChanged(app, event)
            
            if ~isempty(app.metaData)
                % Expande os nós selecionados da árvore:
                collapse(app.file_Tree)
                for ii = 1:numel(app.file_Tree.SelectedNodes)
                    idx = app.file_Tree.SelectedNodes(ii).NodeData.idx1;
                    expand(app.file_Tree.Children(idx), 'all')
                end

                % Caso seja selecionado apenas um nó, apresenta-se os
                % metadados relacionados, além de habilitar os botões do 
                % toolbar.
                if numel(app.file_Tree.SelectedNodes) == 1
                    fileSummary = fcn.SelectedNodeSummary(app, 'app.metaData', {app.file_Tree.SelectedNodes.NodeData.idx1, app.file_Tree.SelectedNodes.NodeData.idx2});
                    app.file_Metadata.HTMLSource = fcn.metadataInfo(fileSummary, 'delete');
                else
                    app.file_Metadata.HTMLSource = ' ';
                end

                if ~app.plotFlag
                    app.file_SpecReadButton.Enable = 1;
                end

            else
                % Desabilita o painel de metadados, além de desabilitar os
                % botões do toolbar.
                app.file_Metadata.HTMLSource   = ' ';
                app.file_SpecReadButton.Enable = 0;
            end
            
        end

        % Selection changed function: file_FilteringTypePanel
        function file_FilteringTypeChanged(app, event)

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1; app.file_panelGrid.RowHeight(6:8) = {22,0,0};
                case app.file_FilteringType2; app.file_panelGrid.RowHeight(6:8) = {0,22,0};
                case app.file_FilteringType3; app.file_panelGrid.RowHeight(6:8) = {0,0,22};
            end

        end

        % Image clicked function: file_FilteringAdd
        function file_FilteringAddClicked(app, event)
            
            h = allchild(app.file_FilteringTree);

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1                                % Faixa de frequência
                    if isempty(app.file_FilteringType1_Frequency.Value)
                        return
                    end
                    newFilterText = sprintf('FREQUÊNCIA: %s', app.file_FilteringType1_Frequency.Value);
                    
                case app.file_FilteringType2                                % ID
                    if isempty(app.file_FilteringType2_ID.Value)
                        return
                    end
                    newFilterText = sprintf('ID: %s', app.file_FilteringType2_ID.Value);
                    
                case app.file_FilteringType3                                % Descrição
                    app.file_FilteringType3_Description.Value = upper(strtrim(app.file_FilteringType3_Description.Value));
                    
                    if isempty(app.file_FilteringType3_Description.Value)
                        return
                    else
                        newFilterText = sprintf('DESCRIÇÃO: "%s"', app.file_FilteringType3_Description.Value);
                    end
            end

            if ~isempty(h) && ismember(newFilterText, {h.Text})
                return
            end

            uitreenode(app.file_FilteringTree, 'Text',        newFilterText, ...
                                               'ContextMenu', app.file_ContextMenu_Tree2);
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree1Node
        function file_ContextMenu_delTree1NodeSelected(app, event)
            
            idxTable = table('Size', [0, 3], 'VariableTypes', {'double', 'double', 'cell'}, 'VariableNames', {'level', 'idx1', 'idx2'});
            for ii = 1:numel(app.file_Tree.SelectedNodes)
                idx = find(idxTable.idx1 == app.file_Tree.SelectedNodes(ii).NodeData.idx1, 1);
                if isempty(idx)
                    idxTable(end+1,:)   = {app.file_Tree.SelectedNodes(ii).NodeData.level, app.file_Tree.SelectedNodes(ii).NodeData.idx1, {app.file_Tree.SelectedNodes(ii).NodeData.idx2}};
                else
                    idxTable(idx,[1,3]) = {min([idxTable{idx,1}, app.file_Tree.SelectedNodes(ii).NodeData.level]), {unique([cell2mat(idxTable{idx,3}), app.file_Tree.SelectedNodes(ii).NodeData.idx2])}};
                end
            end

            idxTable = sortrows(idxTable, 'idx1');
            for kk = height(idxTable):-1:1
                idx1 = idxTable.idx1(kk);
                idx2 = idxTable.idx2{kk};

                switch idxTable.level(kk)
                    case 1
                        app.metaData(idx1) = [];

                    otherwise
                        if isequal(idxTable.idx2{kk}, 1:numel(app.metaData(idx1).Data))
                            app.metaData(idx1) = [];
                        else
                            app.metaData(idx1).Data(idx2)    = [];
                            app.metaData(idx1).Samples(idx2) = [];
                            app.metaData(idx1).Memory        = EstimatedMemory(app.metaData, idx1);
                        end
                end
            end
            
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree2Node
        function file_ContextMenu_delTree2NodeSelected(app, event)
            
            if ~isempty(app.file_FilteringTree.SelectedNodes)
                delete(app.file_FilteringTree.SelectedNodes)
                file_TreeBuilding(app)
            end

        end

        % Image clicked function: misc_ControlsTab1Image, 
        % ...and 11 other components
        function play_TabGroupVisibility(app, event)

            switch event.Source
                case app.play_ControlsTab1Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {'1x',0,0,0,0,0,0,0,0,0,0,0};
                case app.play_ControlsTab2Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,'1x',0,0,0,0,0,0,0,0,0,0};
                case app.play_ControlsTab3Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,'1x',0,0,0,0,0,0,0,0,0};
                case app.report_ControlsTab1Image; app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,'1x',0,0,0,0,0,0,0,0};
                case app.report_ControlsTab2Image; app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,'1x',0,0,0,0,0,0,0};
                case app.report_ControlsTab3Image; app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,'1x',0,0,0,0,0,0};
                case app.misc_ControlsTab1Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,'1x',0,0,0,0,0};
                case app.misc_ControlsTab2Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,0,'1x',0,0,0,0};
                case app.misc_ControlsTab3Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,0,0,'1x',0,0,0};
                case app.misc_ControlsTab4Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,0,0,0,'1x',0,0};
                case app.misc_ControlsTab5Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,0,0,0,0,'1x',0};
                case app.misc_ControlsTab6Image;   app.play_ControlsGrid.RowHeight(2:2:24) = {0,0,0,0,0,0,0,0,0,0,0,'1x'};
            end

        end

        % Image clicked function: misc_LayoutLeft, misc_LayoutRight
        function play_PanelsVisibility(app, event)
            
            switch event.Source
                case app.misc_LayoutLeft
                    if app.play_Grid.ColumnWidth{1}
                        app.play_Grid.ColumnWidth{1} = 0;
                    else
                        app.play_Grid.ColumnWidth{1} = 325;
                    end

                case app.misc_LayoutRight
                    if app.play_Grid.ColumnWidth{3}
                        app.play_Grid.ColumnWidth{3} = 0;
                    else
                        app.play_Grid.ColumnWidth{3} = 325;
                    end
            end

        end

        % Selection changed function: play_Tree
        function play_TreeSelectionChanged(app, event)
            
            idx = [];
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = unique([app.play_Tree.SelectedNodes.NodeData]);
            end

            if numel(idx) == 1
                % Garante que apenas ficará selecionado um único nó da árvore.
                % Posteriormente, ajusta visualização da árvore, expandindo
                % os nós relacionados ao nó selecionado.
                
                app.play_Tree.SelectedNodes = app.play_Tree.SelectedNodes(1);
                play_TreeUpDownArrowsClicked(app, struct('Source', app.play_TreeDownArrow))

                % Em relação ao FLUXO ANTERIORMENTE SELECIONADO, atualiza-se
                % o controle do playback, caso habilitado.
                
                if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                    play_CustomizationValueChanged(app)
                end

                % Ao plotar dados relacionados a um fluxo de espectro, salva-se
                % o handle do nó selecionado da árvore na propriedade "UserData"
                % do painel app.play_PlotPanel. A propriedade "NodeData" deste 
                % nó armazena o índice do app.specData. Se for alterada a seleção 
                % da árvore, e isso resultar na escolha de um único fluxo de 
                % espectro, atualiza-se o plot.

                if app.plotFlag
                    if ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        app.plotFlag = -1;
                    end
                else
                    if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        plotFcn.StartUp(app, idx)
                        plotFcn.Draw(app, idx)
                        plot_axesLayout(app)
                    end
                end

            else
                app.play_Metadata.HTMLSource = ' ';
            end
            drawnow
            
        end

        % Image clicked function: play_TreeDownArrow, play_TreeUpArrow
        function play_TreeUpDownArrowsClicked(app, event)
            
            switch event.Source
                case app.play_TreeDownArrow
                    collapse(app.play_Tree, 'all')
                    for ii = 1:numel(app.play_Tree.Children)
                        expand(app.play_Tree.Children(ii))
                    end

                    generation = play_findGenerationTreeNode(app);
                    switch generation
                        case 0
                            expand(app.play_Tree.SelectedNodes)
                        case 1
                            expand(app.play_Tree.SelectedNodes, 'all')
                        case 2
                            expand(app.play_Tree.SelectedNodes.Parent, 'all')
                        case 3
                            expand(app.play_Tree.SelectedNodes.Parent.Parent, 'all')
                    end

                case app.play_TreeUpArrow
                    collapse(app.play_Tree, 'all')
            end

            focus(app.play_Tree)
            
        end

        % Button pushed function: play_PlaybackPlay
        function play_PlayButtonPushed(app, event)
            
            idx = [];
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;
            end

            if idx && ~app.plotFlag
                app.plotFlag = 1;
                app.play_PlaybackPlay.Icon = 'LT_stop.png';
                app.file_SpecReadButton.Enable = 0;
                
                plot_MainLoop(app, idx, numel(app.specData(idx).Data{1}))

            else
                app.plotFlag = 0;
                app.play_PlaybackPlay.Icon = 'LT_play.png';
                app.file_SpecReadButton.Enable = 1;
            end

        end

        % Button pushed function: play_PlaybackLoop
        function play_PlaybackLoopButtonPushed(app, event)
            
            switch app.play_PlaybackLoop.Tag
                case 'loop';   set(app.play_PlaybackLoop, Tag='direct', Icon='LT_playback_straight.png')
                case 'direct'; set(app.play_PlaybackLoop, Tag='loop',   Icon='LT_playback_loop.png')
            end            

        end

        % Value changing function: play_PlaybackSlider
        function play_PlaybackSliderValueChanging(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            nSweeps = numel(app.specData(idx).Data{1});
            
            app.timeIndex = round(event.Value/100 * nSweeps);
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > nSweeps
                app.timeIndex = nSweeps;
            end

            if ~app.plotFlag
                if ~isempty(app.obj_Persistance)
                    plotFcn.Persistance(app, idx, 'Update')
                end
    
                app.line_ClrWrite.YData = app.specData(idx).Data{2}(:, app.timeIndex)';
    
                for ii = 1:numel(app.mkr_Label)
                    app.mkr_Label(ii).Position(2) = app.line_ClrWrite.YData(app.line_ClrWrite.MarkerIndices(ii));
                end
    
                if app.play_Waterfall.Value && ~isempty(app.line_WaterFallTime) && strcmp(app.play_Waterfall_Timestamp.Value, 'on')
                    app.line_WaterFallTime.YData = [app.specData(idx).Data{1}(app.timeIndex), app.specData(idx).Data{1}(app.timeIndex)];
                end

                app.play_Timestamp.Text = sprintf('%d de %d\n%s', app.timeIndex, nSweeps, app.specData(idx).Data{1}(app.timeIndex));
            end
            
%             event = struct(event);
%             if ~isfield(event, 'EventSource')
%                 Misc_DriveTestUpdate(app)
%             end
            
        end

        % Value changed function: play_Average, play_LayoutRatio, 
        % ...and 5 other components
        function play_ControlButtonPushed(app, event)
            
            [idx, ~, LevelUnit] = plot_updateLine(app);

            if ~isempty(idx)
                switch event.Source
                    case app.play_MinHold
                        if app.play_MinHold.Value
                            plotFcn.minHold(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'MinHold')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_Average
                        if app.play_Average.Value
                            plotFcn.Average(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'Average')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_MaxHold
                        if app.play_MaxHold.Value
                            plotFcn.maxHold(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'MaxHold')
                        end

                    %-----------------------------------------------------%
                    case app.play_Persistance
                        if app.play_Persistance.Value
                            app.play_RadioButton_Persistance.Value = 1;
                            play_ControlsPanelSelectionChanged(app)

                            plotFcn.Persistance(app, idx, 'Creation')
                        else
                            plotFcn.Persistance(app, idx, 'Delete')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_Occupancy
                        if app.play_Occupancy.Value
                            app.play_RadioButton_Occupancy.Value = 1;
                            play_ControlsPanelSelectionChanged(app)

                            occIndex = play_OCCIndex(app, idx);
                            plotFcn.OCC(app, idx, 'Creation', LevelUnit, occIndex)
                        else
                            plotFcn.OCC(app, idx, 'Delete',   LevelUnit, -1)
                        end
                        layoutFcn.occVisibility(app, LevelUnit)
                        plot_axesLayout(app)
    
                    %-----------------------------------------------------%
                    case app.play_Waterfall
                        if app.play_Waterfall.Value
                            app.play_RadioButton_Waterfall.Value = 1;
                            play_ControlsPanelSelectionChanged(app)
                        end
                        plotFcn.WaterFall(app, idx, 'Creation', LevelUnit)
                        plot_axesLayout(app)
    
                    %-----------------------------------------------------%
                    case app.play_LayoutRatio
                        plot_axesPosition(app)
                end
                drawnow nocallbacks
            end

        end

        % Image clicked function: play_Lock
        function play_LockImageClicked(app, event)
            
            if app.play_Tree.Enable
                app.play_Tree.Enable = 0;
                app.play_Lock.ImageSource = 'LT_lockClose.png';
            else
                app.play_Tree.Enable = 1;
                app.play_Lock.ImageSource = 'LT_lockOpen.png';
            end

        end

        % Value changed function: play_LineVisibility
        function play_LineVisibilityValueChanged(app, event)
            
            try
                set(app.line_ClrWrite, 'Visible', app.play_LineVisibility.Value)
                set(app.mkr_Label,     'Visible', app.play_LineVisibility.Value)
            catch
            end

        end

        % Value changed function: play_TraceIntegration
        function play_TraceIntegrationValueChanged(app, event)

            app.General.Integration.Trace = str2double(event.Value);

            if isinf(app.General.Integration.Trace) || strcmp(event.PreviousValue, 'Inf')
                plot_resetLines(app)
            end

        end

        % Value changed function: play_Limits_xLim1, play_Limits_xLim2
        function play_xLimitsValueChanged(app, event)
            
            FreqRange = app.play_Limits_xLim2.Value - app.play_Limits_xLim1.Value;
            if FreqRange > 0
                app.axes1.XLim = [app.play_Limits_xLim1.Value, app.play_Limits_xLim2.Value];
            else
                switch event.Source.Tag
                    case 'FreqStart'; app.play_Limits_xLim1.Value = event.PreviousValue;
                    case 'FreqStop';  app.play_Limits_xLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: play_Limits_yLim1, play_Limits_yLim2
        function play_yLimitsValueChanged(app, event)

            LevelRange = app.play_Limits_yLim2.Value - app.play_Limits_yLim1.Value;
            if LevelRange > 0
                app.axes1.YLim = [app.play_Limits_yLim1.Value, app.play_Limits_yLim2.Value];
            else
                switch event.Source.Tag
                    case 'MinLevel'; app.play_Limits_yLim1.Value = event.PreviousValue;
                    case 'MaxLevel'; app.play_Limits_yLim2.Value = event.PreviousValue;
                end
            end

        end

        % Selection changed function: play_ControlsPanel
        function play_ControlsPanelSelectionChanged(app, event)
            
            switch app.play_ControlsPanel.SelectedObject
                case app.play_RadioButton_Persistance; app.play_ControlsTab1Info.RowHeight(5:7) = {'1x',0,0};
                case app.play_RadioButton_Occupancy;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,'1x',0};
                case app.play_RadioButton_Waterfall;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,0,'1x'};
            end
            
        end

        % Value changed function: play_Customization
        function play_CustomizationValueChanged(app, event)
            
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;

                if app.play_Customization.Value
                    [ParentTag, DataIndex] = plotFcn.DataTipSearch(app, 'all');

                    if isempty(app.play_OCC_THRCaptured.Value)
                        occTHRCaptured = [];
                    else
                        occTHRCaptured = app.play_OCC_THRCaptured.Value;
                    end
    
                    app.specData(idx).UserData.customPlayback =                                                                                           ...
                        struct('Type', 'manual',                                                                                                          ...
                               'Parameters', struct('Controls',    struct('play_MinHold',                     app.play_MinHold.Value,                     ...
                                                                          'play_Average',                     app.play_Average.Value,                     ...
                                                                          'play_MaxHold',                     app.play_MaxHold.Value,                     ...
                                                                          'play_Persistance',                 app.play_Persistance.Value,                 ...
                                                                          'play_Occupancy',                   app.play_Occupancy.Value,                   ...
                                                                          'play_Waterfall',                   app.play_Waterfall.Value,                   ...
                                                                          'play_LayoutRatio',                 app.play_LayoutRatio.Value),                ...
                                                    'Persistance', struct('play_Persistance_Interpolation',   app.play_Persistance_Interpolation.Value,   ...
                                                                          'play_Persistance_Samples',         app.play_Persistance_Samples.Value,         ...
                                                                          'play_Persistance_Transparency',    app.play_Persistance_Transparency.Value),   ...
                                                    'Waterfall',   struct('play_Waterfall_Decimation',        app.play_Waterfall_Decimation.Value),       ...
                                                    'Datatip',     struct('ParentTag',                        ParentTag,                                  ...
                                                                          'DataIndex',                        DataIndex)));
                else
                    app.specData(idx).UserData.customPlayback = struct('Type', 'auto', 'Parameters', []);
                end
            end
            
        end

        % Callback function: play_Persistance_Colormap, 
        % ...and 4 other components
        function play_Persistance_Callbacks(app, event)
            
            if app.play_Persistance.Value
                switch event.Source
                    case app.play_Persistance_Interpolation
                        app.obj_Persistance.handle.Interpolation = app.play_Persistance_Interpolation.Value;
    
                    case app.play_Persistance_Samples
                        delete(app.obj_Persistance.handle)
                        app.obj_Persistance = [];

                        play_ControlButtonPushed(app, struct('Source', app.play_Persistance))

                    case app.play_Persistance_Transparency
                        app.obj_Persistance.handle.CData(isnan(app.obj_Persistance.handle.CData)) = 0; 
                        app.obj_Persistance.handle.AlphaData = double(logical(app.obj_Persistance.handle.CData)) * app.play_Persistance_Transparency.Value;

                    case app.play_Persistance_Colormap
                        plot_Colormap_axes1(app)

                    case app.play_Persistance_cLim_Mode
                        app.axes1.CLimMode = 'auto';
                        app.play_Persistance_cLim1.Value = app.axes1.CLim(1);
                        app.play_Persistance_cLim2.Value = app.axes1.CLim(2);
                end                
                drawnow nocallbacks
            end
            
        end

        % Value changed function: play_Persistance_cLim1, 
        % ...and 1 other component
        function play_Persistance_cLimValueChanged(app, event)

            LevelRange = app.play_Persistance_cLim2.Value - app.play_Persistance_cLim1.Value;
            if LevelRange > 0
                app.axes1.CLim = [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value];
            else
                switch event.Source
                    case app.play_Persistance_cLim1; app.play_Persistance_cLim1.Value = event.PreviousValue;
                    case app.play_Persistance_cLim2; app.play_Persistance_cLim2.Value = event.PreviousValue;
                end
            end

        end

        % Callback function: play_Waterfall_Colormap, 
        % ...and 4 other components
        function play_Waterfall_Callbacks(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>'; 

            switch event.Source
                case app.play_Waterfall_Decimation
                    idx = app.play_PlotPanel.UserData.NodeData;
                    LevelUnit = app.specData(idx).MetaData.LevelUnit;

                    [~, ~, XData, YData] = plotFcn.DataTipSearch(app, 'app.axes3');

                    plotFcn.WaterFall(app, idx, 'Delete',   LevelUnit)
                    plotFcn.WaterFall(app, idx, 'Creation', LevelUnit)

                    for ii = 1:numel(XData)
                        datatip(app.img_WaterFall, XData{ii}, YData{ii});
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_Timestamp
                    switch app.play_Waterfall_Timestamp.Value
                        case 'on'
                            idx = app.play_PlotPanel.UserData.NodeData;
                            app.line_WaterFallTime = line(app.axes3, [app.Band.xArray(1), app.Band.xArray(end)], [app.specData(idx).Data{1}(app.timeIndex), app.specData(idx).Data{1}(app.timeIndex)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                                    'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'WaterFall_time');

                        case 'off'
                            delete(app.line_WaterFallTime) 
                            app.line_WaterFallTime = [];
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_Colormap
                    plot_Colormap_axes3(app)

                %---------------------------------------------------------%
                case app.play_Waterfall_Interpolation
                    app.img_WaterFall.MeshStyle = app.play_Waterfall_Interpolation.Value;

                %---------------------------------------------------------%
                case app.play_Waterfall_cLim_Mode
                    app.axes3.CLim = app.restoreView{4};

                    app.play_Waterfall_cLim1.Value = app.axes3.CLim(1);
                    app.play_Waterfall_cLim2.Value = app.axes3.CLim(2);
            end

            delete(d)
            drawnow nocallbacks

        end

        % Value changed function: play_Waterfall_cLim1, 
        % ...and 1 other component
        function play_Waterfall_cLimValueChanged(app, event)

            LevelRange = app.play_Waterfall_cLim2.Value - app.play_Waterfall_cLim1.Value;
            if LevelRange > 0
                app.axes3.CLim = [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value];
            else
                switch event.Source
                    case app.play_Waterfall_cLim1; app.play_Waterfall_cLim1.Value = event.PreviousValue;
                    case app.play_Waterfall_cLim2; app.play_Waterfall_cLim2.Value = event.PreviousValue;
                end
            end
            
        end

        % Value changed function: play_OCC_IntegrationTime, 
        % ...and 8 other components
        function play_Occupancy_Callbacks(app, event)
            
            play_OCCNewPlot(app, event.Source)
            
        end

        % Selection changed function: play_Channel_RadioGroup
        function play_Channel_RadioGroupSelectionChanged(app, event)
            
            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_ReferenceList
                    app.play_Channel_List.Enable         = 1;
                    set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), Enable=0)
                    app.play_Channel_nChannels.Enable    = 1;
                    play_Channel_ListValueChanged(app)

                case app.play_Channel_Multiples
                    app.play_Channel_List.Enable         = 0;
                    set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), Enable=1)
                    app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;

                case app.play_Channel_Single
                    app.play_Channel_List.Enable         = 0;
                    
                    app.play_Channel_FirstChannel.Enable = 1;
                    app.play_Channel_BW.Enable           = 1;

                    set(app.play_Channel_nChannel,  Enable=0, Value=app.play_Channel_FirstChannel.Value)
                    set(app.play_Channel_StepWidth, Enable=0, Value=-1)
                    app.play_Channel_nChannels.Value     = 1;
                    app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;
            end
            
        end

        % Value changed function: play_Channel_List
        function play_Channel_ListValueChanged(app, event)

            idx = str2double(extractBefore(app.play_Channel_List.Value, ':'));

            app.play_Channel_FirstChannel.Value = app.channelObj.Channel.FirstChannel(idx);
            app.play_Channel_nChannel.Value     = app.channelObj.Channel.LastChannel(idx);
            app.play_Channel_StepWidth.Value    = max(-1, app.channelObj.Channel.StepWidth(idx) * 1000);
            app.play_Channel_BW.Value           = max(-1, app.channelObj.Channel.ChannelBW(idx) * 1000);
            app.play_Channel_Class.Items        = app.channelObj.FindPeaks.Name(app.channelObj.Channel.FindPeaksIndex(idx));

            if ~isempty(app.channelObj.Channel.FreqList{idx})
                FreqList = app.channelObj.Channel.FreqList{idx};
            else
                FreqList = app.channelObj.Channel.FirstChannel(idx):app.channelObj.Channel.StepWidth(idx):app.channelObj.Channel.LastChannel(idx);
            end

            app.play_Channel_nChannels.Value    = numel(FreqList);
            play_ChannelListSample(app, FreqList)
            
            
        end

        % Value changed function: play_Channel_FirstChannel, 
        % ...and 2 other components
        function play_Channel_FirstChannelValueChanged(app, event)
            
            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_Multiples
                    if (app.play_Channel_nChannel.Value >= app.play_Channel_FirstChannel.Value) && (app.play_Channel_StepWidth.Value > 0)
                        FreqList = app.play_Channel_FirstChannel.Value:app.play_Channel_StepWidth.Value/1000:app.play_Channel_nChannel.Value;
                        
                        app.play_Channel_nChannels.Value = numel(FreqList);
                        play_ChannelListSample(app, FreqList)
                    else
                        app.play_Channel_nChannels.Value = -1;
                    end

                case app.play_Channel_Single
                    app.play_Channel_nChannel.Value = app.play_Channel_FirstChannel.Value;
            end
            
        end

        % Selection changed function: play_Channel_Tree
        function play_Channel_TreeCallback(app, event)
            
            play_Channel_TreeSelectionChanged(app)
            
        end

        % Image clicked function: play_Channel_add
        function play_Channel_addChannel(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            play_ChannelAddValidations(app, idx)

        end

        % Menu selected function: play_Channel_ContextMenu_del
        function play_Channel_delChannel(app, event)
            
            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                chIndex = [app.play_Channel_Tree.SelectedNodes.NodeData];
                % Inicialmente, apaga as faixas importadas do arquivo
                % "ChannelLib.json"
                if any(chIndex > 0)
                    app.specData(idx).UserData.channelLibIndex = setdiff(app.specData(idx).UserData.channelLibIndex, chIndex(chIndex>0)');
                end

                % E agora as faixas ou canais incluídas manualmente...
                if any(chIndex < 0)
                    chManualIndex = [app.play_Channel_Tree.SelectedNodes(chIndex<0).UserData];
                    app.specData(idx).UserData.channelManual(chManualIndex,:) = [];
                end
                
                play_Channel_TreeBuilding(app,  idx)
            end

        end

        % Selection changed function: play_FindPeaks_RadioGroup
        function play_FindPeaks_RadioGroupSelectionChanged(app, event)
            
            switch app.play_FindPeaks_RadioGroup.SelectedObject
                case app.play_FindPeaks_auto
                    play_FindPeaks_AlgorithmValueChanged(app)

                otherwise % ROI | DataTips
                    app.play_ControlsTab3Info.RowHeight{3} = 0;
            end
            
        end

        % Selection changed function: play_FindPeaks_Tree
        function play_FindPeaks_TreeSelectionChanged(app, event)
            
            if numel(app.play_FindPeaks_Tree.SelectedNodes) == 1
                idx1 = app.play_PlotPanel.UserData.NodeData;
                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                set(app.play_FindPeaks_PeakCF, 'Value', round(app.specData(idx1).UserData.Emissions.FreqCenter(idx2), 3), 'Enable', 1)
                set(app.play_FindPeaks_PeakBW, 'Value', round(app.specData(idx1).UserData.Emissions.BW(idx2), 3),         'Enable', 1)

                if strcmp(app.play_FindPeaks_Tree.SelectedNodes.Icon, 'play_Truncated.png')
                    app.play_FindPeaks_ContextMenu_digital.Enable = 0;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 1;
                else
                    app.play_FindPeaks_ContextMenu_digital.Enable = 1;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 0;
                end
                app.play_FindPeaks_ContextMenu_search.Enable = 1;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 1;

                if exist('event', 'var')
                    plotFcn.clrWrite(app, idx1, 'TreeSelectionChanged', [])
                end

            else
                set(app.play_FindPeaks_PeakCF, 'Value', -1, 'Enable', 0)
                set(app.play_FindPeaks_PeakBW, 'Value', -1, 'Enable', 0)

                set(app.play_FindPeaks_ContextMenu_edit.Children, 'Enable', 1)
                app.play_FindPeaks_ContextMenu_search.Enable = 0;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 0;
            end
            
        end

        % Image clicked function: play_FindPeaks_add
        function play_FindPeaks_addEmission(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            
            % Identificação dos novos valores de frequência, largura de
            % banda e índice.
            switch app.play_FindPeaks_RadioGroup.SelectedObject
                %---------------------------------------------------------%
                case app.play_FindPeaks_auto
                    switch app.play_FindPeaks_Algorithm.Value
                        case 'FindPeaks'
                            Attributes = struct('Fcn',        app.play_FindPeaks_Trace.Value,      ...
                                                'NPeaks',     app.play_FindPeaks_Numbers.Value,    ...
                                                'THR',        app.play_FindPeaks_THR.Value,        ...
                                                'Prominence', app.play_FindPeaks_prominence.Value, ...
                                                'Distance',   app.play_FindPeaks_distance.Value,   ...
                                                'BW',         app.play_FindPeaks_BW.Value);

                            [newIndex, newFreq, newBW] = Detection_findpeaks(app, idx, Attributes);

                        case 'FindPeaks+OCC (appAnálise v. 1.00)'
                            Attributes = struct('Distance',    app.play_FindPeaks_distance.Value, ...
                                                'BW',          app.play_FindPeaks_BW.Value,       ...
                                                'averageFcn',  app.C1_Fcn.Value,                  ...
                                                'Prominence1', app.play_FindPeaks_Prominence1.Value,           ...
                                                'Prominence2', app.play_FindPeaks_Prominence2.Value,           ...
                                                'meanOCC',     app.play_FindPeaks_meanOCC.Value,              ...
                                                'maxOCC',      app.play_FindPeaks_maxOCC.Value);
                            
                            [newIndex, newFreq, newBW] = Detection_algorithm1(app, idx, Attributes);
                    end
    
                    newBW  = newBW * 1000;
                    Method = sprintf('%s - %s', app.play_FindPeaks_Algorithm.Value, jsonencode(Attributes));


                %---------------------------------------------------------%
                case app.play_FindPeaks_ROI
                    for ii = 1:numel(app.axes1.Toolbar.Children)
                        if isprop(app.axes1.Toolbar.Children(ii), 'Value') && app.axes1.Toolbar.Children(ii).Value
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Operação não realizada! É preciso desabilitar qualquer interação com o eixo 1 dos plots...');
                            return
                        end
                    end

                    app.play_FindPeaks_add.Enable = 0;
                    mkrTemp  = drawrectangle(app.axes1, Color=[0.40,0.73,0.88], LineWidth=1, Tag='mkrTemp');
                    app.play_FindPeaks_add.Enable = 1;
    
                    newFreq  = mkrTemp.Position(1) + mkrTemp.Position(3)/2; % Em MHz
                    newBW    = mkrTemp.Position(3) * 1000;                  % Em kHz
                    newIndex = round((newFreq .* 1e+6 - app.Band.bCoef) ./ app.Band.aCoef);
                    Method   = 'Detecção manual';

                    if ismember(newIndex, [1, app.specData(idx).MetaData.DataPoints])
                    
                       delete(mkrTemp)
                       return
                    end


                %---------------------------------------------------------%
                case app.play_FindPeaks_DataTips
                    hDataTips = findobj('Type', 'DataTip');
                    if isempty(hDataTips)
                        return
                    end
    
                    newFreq = [];
                    ii = 0;
                    while ii < numel(hDataTips)
                        ii = ii+1;
                        if isequal(hDataTips(ii).Parent.Parent.Parent.Parent, app.play_PlotPanel)
                            newFreq(end+1,1) = double(round(hDataTips(ii).X, 3));   % Em MHz
                        end
                    end
                    newBW    = zeros(numel(newFreq), 1);
                    newIndex = round((newFreq .* 1e+6 - app.Band.bCoef) ./ app.Band.aCoef);
                    Method   = 'Detecção manual';
            end
            
            % Inclusão das novas informações na variável app.specData e 
            % ajuste do plot.
            if ~isempty(newIndex)
                app.specData(idx).UserData.Emissions(end+1:end+numel(newIndex),:) = table(newIndex, newFreq, newBW, ones(numel(newIndex),1), repmat({Method}, numel(newIndex), 1));

                [~, uniqueIndex] = unique(app.specData(idx).UserData.Emissions.idx);
                app.specData(idx).UserData.Emissions = app.specData(idx).UserData.Emissions(uniqueIndex,:);
                
                selectedEmission = find(app.specData(idx).UserData.Emissions.idx == newIndex(1), 1);
                plotFcn.clrWrite(app, idx, 'AddButtonPushed', selectedEmission)

            else
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado pico que atenda as condições estabelecidas.');
            end
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_del
        function play_FindPeaks_delEmission(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            
            for ii = numel(app.play_FindPeaks_Tree.SelectedNodes):-1:1
                app.specData(idx).UserData.Emissions(app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData,:) = [];
            end

            plotFcn.clrWrite(app, idx, 'DeleteButtonPushed', 1)
            
        end

        % Value changed function: play_FindPeaks_PeakBW, 
        % ...and 1 other component
        function play_FindPeaks_editEmission(app, event)
            
            idx1 = app.play_PlotPanel.UserData.NodeData;
            idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            switch event.Source
                case app.play_FindPeaks_PeakCF
                    if (app.play_FindPeaks_PeakCF.Value*1e+6 < app.specData(idx1).MetaData.FreqStart) || ...
                       (app.play_FindPeaks_PeakCF.Value*1e+6 > app.specData(idx1).MetaData.FreqStop)
                    
                       app.play_FindPeaks_PeakCF.Value = round(app.specData(idx1).UserData.Emissions.Frequency(idx2), 3);
                       return
                    end
                    
                    newIndex = round((app.play_FindPeaks_PeakCF.Value*1e+6 - app.Band.bCoef)/app.Band.aCoef);
                    app.play_FindPeaks_PeakCF.Value = app.Band.xArray(newIndex);
                    
                    app.specData(idx1).UserData.Emissions(idx2,[1:2, 5]) = {newIndex, app.play_FindPeaks_PeakCF.Value, 'Detecção manual'};
                    app.specData(idx1).UserData.Emissions = sortrows(app.specData(idx1).UserData.Emissions, 'idx');
                                
                    selectedEmission = find(app.specData(idx1).UserData.Emissions.idx == newIndex, 1);
                    plotFcn.clrWrite(app, idx1, 'PeakValueChanged', selectedEmission)

                case app.play_FindPeaks_PeakBW
                    app.specData(idx1).UserData.Emissions(idx2,[3, 5]) = {app.play_FindPeaks_PeakBW.Value, 'Detecção manual'};
                    plotFcn.clrWrite(app, idx1, 'PeakValueChanged', idx2)
            end
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_analog, 
        % ...and 1 other component
        function play_FindPeaks_TruncatedEmission(app, event)

            idx1 = app.play_PlotPanel.UserData.NodeData;

            for ii = 1:numel(app.play_FindPeaks_Tree.SelectedNodes)
                idx2 = app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData;
    
                switch event.Source.Text
                    case 'Truncar frequência'
                        app.specData(idx1).UserData.Emissions.isTruncated(idx2) = 1;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon          = 'play_Truncated.png'; 
    
                    case 'Não truncar'
                        app.specData(idx1).UserData.Emissions.isTruncated(idx2) = 0;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon          = 'play_Untruncated.png';
                end
            end
            play_FindPeaks_TreeSelectionChanged(app)
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_search
        function play_FindPeaks_Classification(app, event)
            
            global AnatelDB

            idx0 = app.play_Tree.SelectedNodes.NodeData;
            idx1 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;            
            
            Data = app.specData(idx0);
            if Data.Emissions.Type(idx1)
                Truncated = Misc_TruncatedFrequency(Data, Data.Emissions.Frequency(idx1));
            else
                Truncated = Data.Emissions.Frequency(idx1);
            end            

            idx2 = find(abs(AnatelDB.Frequency - Truncated) <= 1e-5);
            if ~isempty(idx2)
                auxDistance = geo_lldistkm_v2(struct('lat', Data.gps.Latitude, 'lon', Data.gps.Longitude), AnatelDB(idx2, 3:4));
                [Distance, idx3] = min(auxDistance);
                Description = char(AnatelDB.Description(idx2(idx3)));

                if Data.Emissions.Type(idx1)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n' ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   Data.Emissions.Frequency(idx1), Truncated, numel(idx2), Truncated, Distance, Description);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n' ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   Data.Emissions.Frequency(idx1), numel(idx2), Truncated, Distance, Description);
                end

            else
                if Data.Emissions.Type(idx1)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n' ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   Data.Emissions.Frequency(idx1), Truncated, Truncated);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n' ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   Data.Emissions.Frequency(idx1), Truncated);
                end
            end

            MessageBox(app, 'info', msg)

        end

        % Menu selected function: play_FindPeaks_ContextMenu_Drive
        function play_FindPeaks_DriveTest(app, event)
            
            % VALIDAÇÕES
            idx1 = app.play_Tree.SelectedNodes.NodeData;

            if ~contains(app.specData(idx1).FileFormat, 'RFlookBin')
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas arquivos gerados pelo appColeta.';
                    MessageBox(app, 'warning', msg)                    
                    return

            elseif sum(cellfun(@(x) x.Count, app.specData(idx1).RelatedGPS)) <= 1
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro que possua registrado as coordenadas geográficas de ao menos dois locais de monitoração.';
                    MessageBox(app, 'warning', msg)                    
                    return

            elseif sum(cellfun(@(x) x.Count, app.specData(idx1).RelatedGPS)) ~= app.specData(idx1).Samples
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro cujo número de coordenadas geográficas registrado seja igual ao número de varreduras (relação um para um).';
                    MessageBox(app, 'warning', msg)                    
                    return
            end
            
            Tag = sprintf('%s & ThreadID %.0f: %.3f - %.3f MHz & idx=%.0f', app.specData(idx1).Node, ...
                                                                            app.specData(idx1).ThreadID, ...
                                                                            app.specData(idx1).MetaData.FreqStart / 1e+6, ...
                                                                            app.specData(idx1).MetaData.FreqStop  / 1e+6, ...
                                                                            idx1);

            h = findall(groot, 'Type', 'figure', 'Tag', Tag);
            h(~isvalid(h)) = [];

            if isempty(h)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = '<font style="font-size:12;">Em andamento...</font>';

                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                app.auxiliarWin2 = auxApp.winDriveTest(app, idx1, idx2, Tag);
                drawnow nocallbacks

                delete(d)
            else
                figure(h(1))
            end

        end

        % Value changed function: play_FindPeaks_Algorithm
        function play_FindPeaks_AlgorithmValueChanged(app, event)
            
            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.play_ControlsTab3Info.RowHeight{3} = 174;
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {25,22,0};
                    
                    app.play_FindPeaks_prominenceLabel.Visible = 1;
                    app.play_FindPeaks_prominence.Visible      = 1;

                    app.play_FindPeaks_ClassLabel.Visible = 0;
                    app.play_FindPeaks_Class.Visible      = 0;

                case 'FindPeaks+OCC (appAnálise v. 1.00)'
                    app.play_ControlsTab3Info.RowHeight{3} = 262;
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {0,0,140};

                    app.play_FindPeaks_prominenceLabel.Visible = 0;
                    app.play_FindPeaks_prominence.Visible      = 0;

                    app.play_FindPeaks_ClassLabel.Visible = 1;
                    app.play_FindPeaks_Class.Visible      = 1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise R2022c';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Tooltip = {''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Tab1_Home.Title = 'HOME';
            app.home_Grid = uigridlayout(app.Tab1_Home);
            app.home_Button1 = ccTools.Button(app.home_Grid);
            app.home_Button1.Text = 'Leitura de|arquivos';
            app.home_Button1.Description = '<i>Playback</i>, detecção e classificação de emissões, aferição da<br>ocupação por <i>bin</i>, geração de relatório e máscara espectral  ';
            app.home_Button1.Icon = 'LT_OpenFile.png';
            app.home_Button1.VerticalAlign = 'top';
            app.home_Button1.RowTextSpacing = 14;
            app.home_Button1.IconWidth = 36;
            app.home_Button1.IconHeight = 36;
            app.home_Button1.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button2 = ccTools.Button(app.home_Grid);
            app.home_Button2.Text = 'Modelos de|relatórios';
            app.home_Button2.Description = 'Criação e edição de<br>modelos de relatórios';
            app.home_Button2.Icon = 'LR_Template.png';
            app.home_Button2.VerticalAlign = 'top';
            app.home_Button2.RowTextSpacing = 14;
            app.home_Button2.IconWidth = 36;
            app.home_Button2.IconHeight = 36;
            app.home_Button2.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button3 = ccTools.Button(app.home_Grid);
            app.home_Button3.Text = 'API|Fiscaliza';
            app.home_Button3.Description = 'Relato de inspeções,<br><i>upload</i> de relatório';
            app.home_Button3.Icon = 'LR_redmine.png';
            app.home_Button3.VerticalAlign = 'top';
            app.home_Button3.RowTextSpacing = 138;
            app.home_Button3.IconWidth = 36;
            app.home_Button3.IconHeight = 36;
            app.home_Button3.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button4 = ccTools.Button(app.home_Grid);
            app.home_Button4.Text = 'API|anateldb';
            app.home_Button4.Description = 'Consulta à base de dados com registros de estações<br>(Mosaico, STEL, SRD, AISWEB, GEOAIS e ICAO)';
            app.home_Button4.Icon = 'LR_mosaic.png';
            app.home_Button4.VerticalAlign = 'top';
            app.home_Button4.RowTextSpacing = 14;
            app.home_Button4.IconWidth = 36;
            app.home_Button4.IconHeight = 36;
            app.home_Button4.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button5 = ccTools.Button(app.home_Grid);
            app.home_Button5.Text = 'Configurações|gerais';
            app.home_Button5.Description = 'Atualização de componentes,<br>customizações de <i>plots</i> etc';
            app.home_Button5.Icon = 'LT_Dots.png';
            app.home_Button5.VerticalAlign = 'top';
            app.home_Button5.RowTextSpacing = 14;
            app.home_Button5.IconWidth = 36;
            app.home_Button5.IconHeight = 36;
            app.home_Button5.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.Tab2_File = uitab(app.TabGroup);
            app.Tab2_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab2_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.file_OpenFileButton = uibutton(app.file_toolGrid, 'push');
            app.file_OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @file_ButtonPushed_OpenFile, true);
            app.file_OpenFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_OpenFile2.png');
            app.file_OpenFileButton.Text = 'Arquivo local';
            app.file_RepositoryButton = uibutton(app.file_toolGrid, 'push');
            app.file_RepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @file_ButtonPushed_Repository, true);
            app.file_RepositoryButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_database2.png');
            app.file_RepositoryButton.Text = 'repoSFI';
            app.file_SpecReadButton = uibutton(app.file_toolGrid, 'push');
            app.file_SpecReadButton.ButtonPushedFcn = createCallbackFcn(app, @file_ButtonPushed_SpecRead, true);
            app.file_SpecReadButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_playback2.png');
            app.file_SpecReadButton.Enable = 'off';
            app.file_SpecReadButton.Text = 'Leitura espectro';
            app.file_docGrid = uigridlayout(app.file_Grid);
            app.file_TitleGrid = uigridlayout(app.file_docGrid);
            app.file_TitleGrid.Tag = 'COLORLOCKED';
            app.file_TitleIcon = uiimage(app.file_TitleGrid);
            app.file_TitleIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_AddFiles.png');
            app.file_Title = uilabel(app.file_TitleGrid);
            app.file_Title.Text = 'ARQUIVOS';
            app.file_TreeLabel = uilabel(app.file_docGrid);
            app.file_TreeLabel.Text = 'Lista de arquivos';
            app.file_Tree = uitree(app.file_docGrid);
            app.file_Tree.Multiselect = 'on';
            app.file_Tree.SelectionChangedFcn = createCallbackFcn(app, @file_TreeSelectionChanged, true);
            app.file_panelGrid = uigridlayout(app.file_Grid);
            app.file_MetadataLabel = uilabel(app.file_panelGrid);
            app.file_MetadataLabel.Text = 'Metadados';
            app.file_MetadataPanel = uipanel(app.file_panelGrid);
            app.file_MetadataGrid = uigridlayout(app.file_MetadataPanel);
            app.file_Metadata = uihtml(app.file_MetadataGrid);
            app.file_Metadata.HTMLSource = ' ';
            app.file_FilteringLabel = uilabel(app.file_panelGrid);
            app.file_FilteringLabel.Text = 'Filtros';
            app.file_FilteringTypePanel = uibuttongroup(app.file_panelGrid);
            app.file_FilteringTypePanel.SelectionChangedFcn = createCallbackFcn(app, @file_FilteringTypeChanged, true);
            app.file_FilteringType1 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType1.Text = 'Faixa de Frequência';
            app.file_FilteringType1.Value = true;
            app.file_FilteringType2 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType2.Text = 'ID';
            app.file_FilteringType3 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType3.Text = 'Descrição';
            app.file_FilteringType1_Frequency = uidropdown(app.file_panelGrid);
            app.file_FilteringType1_Frequency.Items = {};
            app.file_FilteringType1_Frequency.Value = {};
            app.file_FilteringType2_ID = uidropdown(app.file_panelGrid);
            app.file_FilteringType2_ID.Items = {};
            app.file_FilteringType2_ID.Value = {};
            app.file_FilteringType3_Description = uieditfield(app.file_panelGrid, 'text');
            app.file_FilteringAdd = uiimage(app.file_panelGrid);
            app.file_FilteringAdd.ImageClickedFcn = createCallbackFcn(app, @file_FilteringAddClicked, true);
            app.file_FilteringAdd.ImageSource = 'LT_Add.png';
            app.file_FilteringTree = uitree(app.file_panelGrid);
            app.file_FilteringTree.Multiselect = 'on';
            app.Tab3_Playback = uitab(app.TabGroup);
            app.Tab3_Playback.Title = 'PLAYBACK';
            app.play_Grid = uigridlayout(app.Tab3_Playback);
            app.play_TreeGrid = uigridlayout(app.play_Grid);
            app.play_TreeTitleGrid = uigridlayout(app.play_TreeGrid);
            app.play_TreeTitleGrid.Tag = 'COLORLOCKED';
            app.play_TreeTitleImage = uiimage(app.play_TreeTitleGrid);
            app.play_TreeTitleImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Classification.png');
            app.play_TreeTitle = uilabel(app.play_TreeTitleGrid);
            app.play_TreeTitle.Text = 'DADOS';
            app.play_TreeLabelGrid = uigridlayout(app.play_TreeGrid);
            app.play_TreeLabel = uilabel(app.play_TreeLabelGrid);
            app.play_TreeLabel.Text = 'Fluxos espectrais';
            app.play_Lock = uiimage(app.play_TreeLabelGrid);
            app.play_Lock.ImageClickedFcn = createCallbackFcn(app, @play_LockImageClicked, true);
            app.play_Lock.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_lockOpen.png');
            app.play_Tree = uitree(app.play_TreeGrid);
            app.play_Tree.Multiselect = 'on';
            app.play_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_TreeSelectionChanged, true);
            app.play_MetadataPanel = uipanel(app.play_TreeGrid);
            app.play_MetadataGrid = uigridlayout(app.play_MetadataPanel);
            app.play_Metadata = uihtml(app.play_MetadataGrid);
            app.play_Metadata.HTMLSource = ' ';
            app.play_MetadataLabelGrid = uigridlayout(app.play_TreeGrid);
            app.play_MetadataLabel = uilabel(app.play_MetadataLabelGrid);
            app.play_MetadataLabel.Text = 'Metadados';
            app.play_TreeUpArrow = uiimage(app.play_MetadataLabelGrid);
            app.play_TreeUpArrow.ImageClickedFcn = createCallbackFcn(app, @play_TreeUpDownArrowsClicked, true);
            app.play_TreeUpArrow.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_upArrow_Zoom.png');
            app.play_TreeDownArrow = uiimage(app.play_MetadataLabelGrid);
            app.play_TreeDownArrow.ImageClickedFcn = createCallbackFcn(app, @play_TreeUpDownArrowsClicked, true);
            app.play_TreeDownArrow.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_downArrow_Zoom.png');
            app.play_PlotPanel = uipanel(app.play_Grid);
            app.play_toolGrid = uigridlayout(app.play_Grid);
            app.play_PlaybackPlay = uibutton(app.play_toolGrid, 'push');
            app.play_PlaybackPlay.ButtonPushedFcn = createCallbackFcn(app, @play_PlayButtonPushed, true);
            app.play_PlaybackPlay.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.play_PlaybackPlay.Text = '';
            app.play_PlaybackLoop = uibutton(app.play_toolGrid, 'push');
            app.play_PlaybackLoop.ButtonPushedFcn = createCallbackFcn(app, @play_PlaybackLoopButtonPushed, true);
            app.play_PlaybackLoop.Tag = 'loop';
            app.play_PlaybackLoop.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_playback_loop.png');
            app.play_PlaybackLoop.Text = '';
            app.play_PlaybackSlider = uislider(app.play_toolGrid);
            app.play_PlaybackSlider.MajorTicks = [0 50 100];
            app.play_PlaybackSlider.MajorTickLabels = {''};
            app.play_PlaybackSlider.ValueChangingFcn = createCallbackFcn(app, @play_PlaybackSliderValueChanging, true);
            app.play_PlaybackSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.play_Separator1 = uiimage(app.play_toolGrid);
            app.play_Separator1.Enable = 'off';
            app.play_Separator1.ImageSource = 'LT_LineV.png';
            app.play_SelectedNode = uilabel(app.play_toolGrid);
            app.play_SelectedNode.Text = {'R&S FSL'; 'ID1: 30.000 - 108.000 MHz'};
            app.play_Timestamp = uilabel(app.play_toolGrid);
            app.play_Timestamp.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.play_Separator2 = uiimage(app.play_toolGrid);
            app.play_Separator2.Enable = 'off';
            app.play_Separator2.ImageSource = 'LT_LineV.png';
            app.play_MinHold = uibutton(app.play_toolGrid, 'state');
            app.play_MinHold.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.play_MinHold.Text = '';
            app.play_Average = uibutton(app.play_toolGrid, 'state');
            app.play_Average.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.play_Average.Text = '';
            app.play_MaxHold = uibutton(app.play_toolGrid, 'state');
            app.play_MaxHold.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.play_MaxHold.Text = '';
            app.play_Persistance = uibutton(app.play_toolGrid, 'state');
            app.play_Persistance.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Persistance.Icon = fullfile(pathToMLAPP, 'Icons', 'LR_Persistance.png');
            app.play_Persistance.Text = '';
            app.play_Occupancy = uibutton(app.play_toolGrid, 'state');
            app.play_Occupancy.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Occupancy.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_OCC.png');
            app.play_Occupancy.Text = '';
            app.play_Waterfall = uibutton(app.play_toolGrid, 'state');
            app.play_Waterfall.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Waterfall.Icon = fullfile(pathToMLAPP, 'Icons', 'LR_waterfall.png');
            app.play_Waterfall.Text = '';
            app.play_Separator3 = uiimage(app.play_toolGrid);
            app.play_Separator3.Enable = 'off';
            app.play_Separator3.ImageSource = 'LT_LineV.png';
            app.report_Button1 = uibutton(app.play_toolGrid, 'push');
            app.report_Button1.Tag = 'REPORT';
            app.report_Button1.Icon = 'LT_list.png';
            app.report_Button1.Enable = 'off';
            app.report_Button1.Tooltip = {'Análise de emissões'};
            app.report_Button1.Text = '';
            app.report_Button2 = uibutton(app.play_toolGrid, 'push');
            app.report_Button2.Tag = 'REPORT';
            app.report_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_zoom.png');
            app.report_Button2.Enable = 'off';
            app.report_Button2.Tooltip = {'Prévia de relatório'};
            app.report_Button2.Text = '';
            app.report_Button3 = uibutton(app.play_toolGrid, 'push');
            app.report_Button3.Tag = 'REPORT';
            app.report_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_report.png');
            app.report_Button3.Enable = 'off';
            app.report_Button3.Tooltip = {'Gerar relatório'};
            app.report_Button3.Text = '';
            app.play_Separator4 = uiimage(app.play_toolGrid);
            app.play_Separator4.Enable = 'off';
            app.play_Separator4.ImageSource = 'LT_LineV.png';
            app.misc_LayoutLeft = uiimage(app.play_toolGrid);
            app.misc_LayoutLeft.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.misc_LayoutLeft.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_layoutLeft.png');
            app.misc_LayoutRight = uiimage(app.play_toolGrid);
            app.misc_LayoutRight.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.misc_LayoutRight.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_layoutRight.png');
            app.play_ControlsGrid = uigridlayout(app.play_Grid);
            app.play_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Label = uilabel(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Label.Text = 'PLAYBACK';
            app.play_ControlsTab1Image = uiimage(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_playback.png');
            app.play_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.play_GeneralPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_GeneralPanelLabel.Text = 'Aspectos gerais';
            app.play_GeneralPanel = uipanel(app.play_ControlsTab1Info);
            app.play_OthersGrid = uigridlayout(app.play_GeneralPanel);
            app.play_LayoutRatioLabel = uilabel(app.play_OthersGrid);
            app.play_LayoutRatioLabel.Text = {'Razão aspecto '; 'eixos:'};
            app.play_LayoutRatio = uidropdown(app.play_OthersGrid);
            app.play_LayoutRatio.Items = {'1:0:0'};
            app.play_LayoutRatio.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_LayoutRatio.Value = '1:0:0';
            app.play_LineVisibilityLabel = uilabel(app.play_OthersGrid);
            app.play_LineVisibilityLabel.Text = 'Visibilidade ClearWrite:';
            app.play_LineVisibility = uidropdown(app.play_OthersGrid);
            app.play_LineVisibility.Items = {'on', 'off'};
            app.play_LineVisibility.ValueChangedFcn = createCallbackFcn(app, @play_LineVisibilityValueChanged, true);
            app.play_LineVisibility.Value = 'on';
            app.play_MinPlotTimeLabel = uilabel(app.play_OthersGrid);
            app.play_MinPlotTimeLabel.Text = {'Tempo mínimo'; 'escrita (ms):'};
            app.play_MinPlotTime = uispinner(app.play_OthersGrid);
            app.play_MinPlotTime.Step = 25;
            app.play_MinPlotTime.Limits = [50 1000];
            app.play_MinPlotTime.RoundFractionalValues = 'on';
            app.play_MinPlotTime.ValueDisplayFormat = '%.0f';
            app.play_MinPlotTime.Value = 100;
            app.play_TraceIntegrationLabel = uilabel(app.play_OthersGrid);
            app.play_TraceIntegrationLabel.Text = 'Fator integração:';
            app.play_TraceIntegration = uidropdown(app.play_OthersGrid);
            app.play_TraceIntegration.Items = {'3', '10', '100', 'Inf'};
            app.play_TraceIntegration.ValueChangedFcn = createCallbackFcn(app, @play_TraceIntegrationValueChanged, true);
            app.play_TraceIntegration.Value = 'Inf';
            app.play_LimitsPanelLabel = uilabel(app.play_OthersGrid);
            app.play_LimitsPanelLabel.Text = 'Limites';
            app.play_LimitsPanel = uipanel(app.play_OthersGrid);
            app.play_LimitsGrid = uigridlayout(app.play_LimitsPanel);
            app.play_Limits_xLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim1.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim1.Tag = 'FreqStart';
            app.play_Limits_xLim1.Tooltip = {''};
            app.play_Limits_xLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_xLimLabel.Text = 'Frequência';
            app.play_Limits_xLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim2.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim2.Tag = 'FreqStop';
            app.play_Limits_xLim2.Tooltip = {''};
            app.play_Limits_yLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim1.Step = 5;
            app.play_Limits_yLim1.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim1.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim1.Tag = 'MinLevel';
            app.play_Limits_yLim1.Tooltip = {''};
            app.play_Limits_yLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_yLimLabel.Text = 'Nível';
            app.play_Limits_yLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim2.Step = 5;
            app.play_Limits_yLim2.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim2.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim2.Tag = 'MaxLevel';
            app.play_Limits_yLim2.Tooltip = {''};
            app.play_ControlPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_ControlPanelLabel.Text = 'Controles';
            app.play_ControlsPanel = uibuttongroup(app.play_ControlsTab1Info);
            app.play_ControlsPanel.SelectionChangedFcn = createCallbackFcn(app, @play_ControlsPanelSelectionChanged, true);
            app.play_RadioButton_Persistance = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Persistance.Text = 'Persistência';
            app.play_RadioButton_Persistance.Value = true;
            app.play_RadioButton_Occupancy = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Occupancy.Text = 'Ocupação';
            app.play_RadioButton_Waterfall = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Waterfall.Text = 'Waterfall';
            app.play_Persistance_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_PersistanceGrid = uigridlayout(app.play_Persistance_Panel);
            app.play_Persistance_InterpolationLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_InterpolationLabel.Text = 'Interpolação:';
            app.play_Persistance_Interpolation = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Interpolation.Items = {'nearest', 'bilinear'};
            app.play_Persistance_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Interpolation.Enable = 'off';
            app.play_Persistance_Interpolation.Value = 'nearest';
            app.play_Persistance_SamplesLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_SamplesLabel.Text = 'Janela:';
            app.play_Persistance_Samples = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Samples.Items = {'128', '256', '512', 'full'};
            app.play_Persistance_Samples.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Samples.Enable = 'off';
            app.play_Persistance_Samples.Value = 'full';
            app.play_Persistance_ColormapLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Persistance_Colormap = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Colormap.Items = {'winter', 'parula', 'turbo'};
            app.play_Persistance_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Colormap.Enable = 'off';
            app.play_Persistance_Colormap.Value = 'winter';
            app.play_Persistance_TransparencyLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_TransparencyLabel.Text = 'Transparência:';
            app.play_Persistance_Transparency = uispinner(app.play_PersistanceGrid);
            app.play_Persistance_Transparency.Step = 0.05;
            app.play_Persistance_Transparency.Limits = [0.2 1];
            app.play_Persistance_Transparency.ValueDisplayFormat = '%.2f';
            app.play_Persistance_Transparency.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Transparency.Enable = 'off';
            app.play_Persistance_Transparency.Value = 1;
            app.play_Persistance_cLim_Label = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Label.Text = 'Limites (%):';
            app.play_Persistance_cLim_Grid1 = uigridlayout(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Mode = uiimage(app.play_Persistance_cLim_Grid1);
            app.play_Persistance_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_cLim_Mode.Enable = 'off';
            app.play_Persistance_cLim_Mode.ImageSource = 'LT_refresh.png';
            app.play_Persistance_cLim_Grid2 = uigridlayout(app.play_PersistanceGrid);
            app.play_Persistance_cLim1 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim1.Step = 0.1;
            app.play_Persistance_cLim1.Limits = [0 Inf];
            app.play_Persistance_cLim1.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim1.Enable = 'off';
            app.play_Persistance_cLim1.Tooltip = {''};
            app.play_Persistance_cLim2 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim2.Limits = [0 Inf];
            app.play_Persistance_cLim2.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim2.Enable = 'off';
            app.play_Persistance_cLim2.Tooltip = {''};
            app.play_Persistance_cLim_Separation = uilabel(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim_Separation.Enable = 'off';
            app.play_Persistance_cLim_Separation.Text = '-';
            app.play_OCC_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_OCCGrid = uigridlayout(app.play_OCC_Panel);
            app.play_OCC_MethodLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_MethodLabel.Text = 'Tipo de threshold:';
            app.play_OCC_Method = uidropdown(app.play_OCCGrid);
            app.play_OCC_Method.Items = {'Linear fixo (COLETA)', 'Linear fixo', 'Linear adaptativo', 'Envoltória do ruído'};
            app.play_OCC_Method.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Method.Value = 'Linear fixo (COLETA)';
            app.play_OCC_IntegrationTimeLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_IntegrationTimeLabel.Text = 'Integração (min):';
            app.play_OCC_IntegrationTime = uidropdown(app.play_OCCGrid);
            app.play_OCC_IntegrationTime.Items = {'1', '5', '15', '30', '60'};
            app.play_OCC_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_IntegrationTime.Tag = 'Factor';
            app.play_OCC_IntegrationTime.Value = '15';
            app.play_OCC_IntegrationTimeCaptured = uieditfield(app.play_OCCGrid, 'numeric');
            app.play_OCC_IntegrationTimeCaptured.Limits = [0 Inf];
            app.play_OCC_IntegrationTimeCaptured.ValueDisplayFormat = '%.0f';
            app.play_OCC_IntegrationTimeCaptured.Editable = 'off';
            app.play_OCC_IntegrationTimeCaptured.Visible = 'off';
            app.play_OCC_OrientationLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OrientationLabel.Text = 'Orientação:';
            app.play_OCC_Orientation = uidropdown(app.play_OCCGrid);
            app.play_OCC_Orientation.Items = {'bin'};
            app.play_OCC_Orientation.Value = 'bin';
            app.play_OCC_THRLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_THRLabel.Tag = 'THR';
            app.play_OCC_THRLabel.Text = 'Valor (dBm):';
            app.play_OCC_THR = uispinner(app.play_OCCGrid);
            app.play_OCC_THR.RoundFractionalValues = 'on';
            app.play_OCC_THR.ValueDisplayFormat = '%d';
            app.play_OCC_THR.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THR.Tag = 'THR';
            app.play_OCC_THR.Value = -80;
            app.play_OCC_THRCaptured = uidropdown(app.play_OCCGrid);
            app.play_OCC_THRCaptured.Items = {};
            app.play_OCC_THRCaptured.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THRCaptured.Tag = 'Factor';
            app.play_OCC_THRCaptured.Visible = 'off';
            app.play_OCC_THRCaptured.Value = {};
            app.play_OCC_OffsetLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OffsetLabel.Tag = 'Offset';
            app.play_OCC_OffsetLabel.Visible = 'off';
            app.play_OCC_OffsetLabel.Text = 'OffSet (dB):';
            app.play_OCC_Offset = uispinner(app.play_OCCGrid);
            app.play_OCC_Offset.Limits = [3 30];
            app.play_OCC_Offset.RoundFractionalValues = 'on';
            app.play_OCC_Offset.ValueDisplayFormat = '%d';
            app.play_OCC_Offset.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Offset.Tag = 'Offset';
            app.play_OCC_Offset.Visible = 'off';
            app.play_OCC_Offset.Value = 12;
            app.play_OCC_ceilFactorLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_ceilFactorLabel.Tag = 'Factor';
            app.play_OCC_ceilFactorLabel.Visible = 'off';
            app.play_OCC_ceilFactorLabel.Text = 'Ceifamento:';
            app.play_OCC_ceilFactor = uidropdown(app.play_OCCGrid);
            app.play_OCC_ceilFactor.Items = {'1𝜎', '2𝜎', '3𝜎'};
            app.play_OCC_ceilFactor.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_ceilFactor.Tag = 'Factor';
            app.play_OCC_ceilFactor.Visible = 'off';
            app.play_OCC_ceilFactor.Value = '2𝜎';
            app.play_OCC_noiseLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_noiseLabel.Visible = 'off';
            app.play_OCC_noiseLabel.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.play_OCC_noisePanel = uipanel(app.play_OCCGrid);
            app.play_OCC_noisePanel.Visible = 'off';
            app.play_OCC_noiseGrid = uigridlayout(app.play_OCC_noisePanel);
            app.play_OCC_noiseFcnLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcnLabel.Text = {'Função'; 'estatística:'};
            app.play_OCC_noiseFcn = uidropdown(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcn.Items = {'mean', 'median'};
            app.play_OCC_noiseFcn.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseFcn.Value = 'mean';
            app.play_OCC_noiseTrashSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamplesLabel.Text = {'Amostras'; 'descartadas (%):'};
            app.play_OCC_noiseTrashSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamples.Limits = [0 10];
            app.play_OCC_noiseTrashSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamplesLabel.Text = {'Amostras'; 'úteis (%):'};
            app.play_OCC_noiseUsefulSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamples.Limits = [10 90];
            app.play_OCC_noiseUsefulSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamples.Value = 20;
            app.play_Waterfall_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_WaterFallGrid = uigridlayout(app.play_Waterfall_Panel);
            app.play_Waterfall_DecimationGrid = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_Decimation_Label = uilabel(app.play_Waterfall_DecimationGrid);
            app.play_Waterfall_Decimation_Label.Text = 'Decimação:';
            app.play_Waterfall_DecimationValue = uilabel(app.play_Waterfall_DecimationGrid);
            app.play_Waterfall_DecimationValue.Text = '1';
            app.play_Waterfall_Decimation = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Decimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.play_Waterfall_Decimation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Decimation.Enable = 'off';
            app.play_Waterfall_Decimation.Value = 'auto';
            app.play_Waterfall_Timestamp_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_Timestamp_Label.Text = 'Timestamp:';
            app.play_Waterfall_Timestamp = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Timestamp.Items = {'on', 'off'};
            app.play_Waterfall_Timestamp.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Timestamp.Enable = 'off';
            app.play_Waterfall_Timestamp.Value = 'on';
            app.play_Waterfall_ColormapLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Waterfall_Colormap = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.play_Waterfall_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Colormap.Enable = 'off';
            app.play_Waterfall_Colormap.Value = 'winter';
            app.play_Waterfall_Interpolation_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_Interpolation_Label.Text = 'Direção:';
            app.play_Waterfall_Interpolation = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Interpolation.Items = {'row', 'both'};
            app.play_Waterfall_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Interpolation.Enable = 'off';
            app.play_Waterfall_Interpolation.Value = 'both';
            app.play_Waterfall_cLim_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Label.Text = 'Limites (dB):';
            app.play_Waterfall_cLim_Grid1 = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Mode = uiimage(app.play_Waterfall_cLim_Grid1);
            app.play_Waterfall_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_cLim_Mode.Enable = 'off';
            app.play_Waterfall_cLim_Mode.ImageSource = 'LT_refresh.png';
            app.play_Waterfall_cLim_Grid2 = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_cLim1 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim1.Step = 5;
            app.play_Waterfall_cLim1.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim1.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim1.Enable = 'off';
            app.play_Waterfall_cLim1.Tooltip = {''};
            app.play_Waterfall_cLim2 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim2.Step = 5;
            app.play_Waterfall_cLim2.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim2.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim2.Enable = 'off';
            app.play_Waterfall_cLim2.Tooltip = {''};
            app.play_Waterfall_cLim_Separation = uilabel(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim_Separation.Enable = 'off';
            app.play_Waterfall_cLim_Separation.Text = '-';
            app.play_Customization = uicheckbox(app.play_ControlsTab1Info);
            app.play_Customization.ValueChangedFcn = createCallbackFcn(app, @play_CustomizationValueChanged, true);
            app.play_Customization.Text = 'Customizar principais controles do plot (visibilidade e razão de aspecto dos eixos, decimação do Waterfall, DataTips etc).';
            app.play_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab2Label = uilabel(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Label.Text = 'CANAIS';
            app.play_ControlsTab2Image = uiimage(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Channel.png');
            app.play_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.play_Channel_Label = uilabel(app.play_ControlsTab2Info);
            app.play_Channel_Label.Text = 'Inclusão de canais';
            app.play_Channel_RadioGroup = uibuttongroup(app.play_ControlsTab2Info);
            app.play_Channel_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_RadioGroupSelectionChanged, true);
            app.play_Channel_ReferenceList = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_ReferenceList.Text = {'Canalização'; 'de referência'};
            app.play_Channel_ReferenceList.Value = true;
            app.play_Channel_Multiples = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Multiples.Text = {'Faixa de'; 'frequência'};
            app.play_Channel_Single = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Single.Text = 'Canal';
            app.play_Channel_Panel = uipanel(app.play_ControlsTab2Info);
            app.play_Channel_Grid = uigridlayout(app.play_Channel_Panel);
            app.play_Channel_ListLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ListLabel.Text = 'Referência:';
            app.play_Channel_List = uidropdown(app.play_Channel_Grid);
            app.play_Channel_List.Items = {};
            app.play_Channel_List.ValueChangedFcn = createCallbackFcn(app, @play_Channel_ListValueChanged, true);
            app.play_Channel_List.Value = {};
            app.play_Channel_nChannelsLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_nChannelsLabel.Text = {'Qtd.'; 'canais:'};
            app.play_Channel_nChannels = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_nChannels.Limits = [-1 Inf];
            app.play_Channel_nChannels.RoundFractionalValues = 'on';
            app.play_Channel_nChannels.ValueDisplayFormat = '%d';
            app.play_Channel_nChannels.Editable = 'off';
            app.play_Channel_nChannels.Value = 1;
            app.play_Channel_FirstChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_FirstChannelLabel.Text = {'Frequência central'; '1º canal (MHz):'};
            app.play_Channel_nChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_nChannelLabel.Text = {'Frequência central'; 'n-ésimo canal:'};
            app.play_Channel_nChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_nChannel.Limits = [0 Inf];
            app.play_Channel_nChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_nChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_ClassLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_Channel_Class = uidropdown(app.play_Channel_Grid);
            app.play_Channel_Class.Items = {};
            app.play_Channel_Class.Value = {};
            app.play_Channel_StepWidthLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_StepWidthLabel.Text = {'Espaçamento entre'; 'canais (kHz):'};
            app.play_Channel_StepWidth = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_StepWidth.Limits = [-1 Inf];
            app.play_Channel_StepWidth.ValueDisplayFormat = '%.1f';
            app.play_Channel_StepWidth.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_BWLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_BWLabel.Text = {'Largura do canal'; '(kHz):'};
            app.play_Channel_BW = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_BW.Limits = [-1 Inf];
            app.play_Channel_BW.ValueDisplayFormat = '%.1f';
            app.play_Channel_Sample = uilabel(app.play_Channel_Grid);
            app.play_Channel_Sample.Text = '101.700 MHz, 101.900 MHz, 102.100 MHz, 102.300 MHz, 102.500 MHz, 102.700 MHz...';
            app.play_Channel_FirstChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_FirstChannel.Limits = [0 Inf];
            app.play_Channel_FirstChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_FirstChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_add = uiimage(app.play_ControlsTab2Info);
            app.play_Channel_add.ImageClickedFcn = createCallbackFcn(app, @play_Channel_addChannel, true);
            app.play_Channel_add.ImageSource = 'LT_Add.png';
            app.play_Channel_Tree = uitree(app.play_ControlsTab2Info);
            app.play_Channel_Tree.Multiselect = 'on';
            app.play_Channel_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_TreeCallback, true);
            app.play_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab3Label = uilabel(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Label.Text = 'EMISSÕES';
            app.play_ControlsTab3Image = uiimage(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.play_ControlsTab3Info = uigridlayout(app.play_ControlsGrid);
            app.play_FindPeaks_Label = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_Label.Text = 'Detecção de emissões';
            app.play_FindPeaks_RadioGroup = uibuttongroup(app.play_ControlsTab3Info);
            app.play_FindPeaks_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_RadioGroupSelectionChanged, true);
            app.play_FindPeaks_auto = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_auto.Text = 'Automática';
            app.play_FindPeaks_auto.Value = true;
            app.play_FindPeaks_ROI = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_ROI.Text = 'ROI';
            app.play_FindPeaks_DataTips = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_DataTips.Text = 'DataTips';
            app.play_FindPeaks_ParametersPanel = uipanel(app.play_ControlsTab3Info);
            app.play_FindPeaks_ParametersGrid = uigridlayout(app.play_FindPeaks_ParametersPanel);
            app.play_FindPeaks_AlgorithmLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_AlgorithmLabel.Text = 'Algoritmo:';
            app.play_FindPeaks_Algorithm = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Algorithm.Items = {'FindPeaks', 'FindPeaks+OCC (appAnálise v. 1.00)'};
            app.play_FindPeaks_Algorithm.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_AlgorithmValueChanged, true);
            app.play_FindPeaks_Algorithm.Value = 'FindPeaks';
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace.Items = {'MinHold', 'Média', 'MaxHold'};
            app.play_FindPeaks_Trace.Value = 'Média';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de '; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_THRLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THRLabel.Text = {'Threshold'; '(dB):'};
            app.play_FindPeaks_THR = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THR.Step = 10;
            app.play_FindPeaks_THR.RoundFractionalValues = 'on';
            app.play_FindPeaks_THR.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_THR.Value = -Inf;
            app.play_FindPeaks_prominenceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominenceLabel.Text = {'Proeminência '; '(dB):'};
            app.play_FindPeaks_prominence = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominence.Step = 10;
            app.play_FindPeaks_prominence.Limits = [1 Inf];
            app.play_FindPeaks_prominence.RoundFractionalValues = 'on';
            app.play_FindPeaks_prominence.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_prominence.Value = 30;
            app.play_FindPeaks_ClassLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_ClassLabel.Visible = 'off';
            app.play_FindPeaks_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_FindPeaks_Class = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Class.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.play_FindPeaks_Class.Visible = 'off';
            app.play_FindPeaks_Class.Value = 'Genérico';
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância entre'; 'picos (kHz):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distance.Step = 25;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 25;
            app.play_FindPeaks_BWLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_BW = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BW.Step = 10;
            app.play_FindPeaks_BW.Limits = [0 Inf];
            app.play_FindPeaks_BW.RoundFractionalValues = 'on';
            app.play_FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_BW.Value = 10;
            app.play_FindPeaks_MeanPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MeanPanel.Title = 'MÉDIA';
            app.play_FindPeaks_MeanGrid = uigridlayout(app.play_FindPeaks_MeanPanel);
            app.play_FindPeaks_Prominence1Label = uilabel(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence1 = uispinner(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1.Step = 10;
            app.play_FindPeaks_Prominence1.Limits = [1 Inf];
            app.play_FindPeaks_Prominence1.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence1.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence1.Value = 10;
            app.play_FindPeaks_MaxHoldPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MaxHoldPanel.Title = 'MAXHOLD';
            app.play_FindPeaks_MaxHoldGrid = uigridlayout(app.play_FindPeaks_MaxHoldPanel);
            app.play_FindPeaks_Prominence2Label = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence2 = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2.Step = 10;
            app.play_FindPeaks_Prominence2.Limits = [1 Inf];
            app.play_FindPeaks_Prominence2.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence2.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence2.Value = 30;
            app.play_FindPeaks_meanOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCCLabel.Text = {'Ocupação'; 'média (%):'};
            app.play_FindPeaks_meanOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCC.Step = 10;
            app.play_FindPeaks_meanOCC.Limits = [0 100];
            app.play_FindPeaks_meanOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_meanOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_meanOCC.Value = 1;
            app.play_FindPeaks_maxOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCCLabel.Text = {'Ocupação'; 'máxima (%):'};
            app.play_FindPeaks_maxOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCC.Step = 10;
            app.play_FindPeaks_maxOCC.Limits = [0 100];
            app.play_FindPeaks_maxOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_maxOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_maxOCC.Value = 10;
            app.play_FindPeaks_add = uiimage(app.play_ControlsTab3Info);
            app.play_FindPeaks_add.ImageClickedFcn = createCallbackFcn(app, @play_FindPeaks_addEmission, true);
            app.play_FindPeaks_add.ImageSource = 'LT_Add.png';
            app.play_FindPeaks_Tree = uitree(app.play_ControlsTab3Info);
            app.play_FindPeaks_Tree.Multiselect = 'on';
            app.play_FindPeaks_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_TreeSelectionChanged, true);
            app.play_FindPeaks_PeakCFLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakCFLabel.Text = 'Frequência central (MHz):';
            app.play_FindPeaks_PeakCF = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakCF.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakCF.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakCF.Enable = 'off';
            app.play_FindPeaks_PeakBWLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakBWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_PeakBW = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakBW.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakBW.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakBW.Enable = 'off';
            app.report_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab1Label = uilabel(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Label.Text = 'RELATÓRIO';
            app.report_ControlsTab1Image = uiimage(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_report.png');
            app.report_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.Report_Tree = uitree(app.report_ControlsTab1Info, 'checkbox');
            app.GridLayout8 = uigridlayout(app.report_ControlsTab1Info);
            app.Report_ImageWarn = uiimage(app.GridLayout8);
            app.Report_ImageWarn.Tooltip = {'Pendente salvar o projeto'};
            app.Report_ImageWarn.ImageSource = 'LT_redWarn.png';
            app.Report_Sublabel1 = uilabel(app.GridLayout8);
            app.Report_Sublabel1.Text = 'Projeto';
            app.Report_Sublabel4 = uilabel(app.report_ControlsTab1Info);
            app.Report_Sublabel4.Text = 'Período de observação a processar';
            app.Panel_36 = uipanel(app.report_ControlsTab1Info);
            app.Report_Grid4 = uigridlayout(app.Panel_36);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.Value = 59;
            app.Panel_37 = uipanel(app.report_ControlsTab1Info);
            app.GridLayout7 = uigridlayout(app.Panel_37);
            app.ProjectName = uieditfield(app.GridLayout7, 'text');
            app.ProjectName.Value = 'Projeto 001';
            app.Report_SaveProject = uibutton(app.GridLayout7, 'push');
            app.Report_SaveProject.Icon = 'LT_save.png';
            app.Report_SaveProject.Tooltip = {'Salvar projeto'};
            app.Report_SaveProject.Text = '';
            app.Panel_38 = uipanel(app.GridLayout7);
            app.GridLayout21 = uigridlayout(app.Panel_38);
            app.Report_IssueLabel = uilabel(app.GridLayout21);
            app.Report_IssueLabel.Text = 'Inspeção:';
            app.Report_Issue = uieditfield(app.GridLayout21, 'numeric');
            app.Report_Issue.Limits = [-1 Inf];
            app.Report_Issue.RoundFractionalValues = 'on';
            app.Report_Issue.ValueDisplayFormat = '%d';
            app.Report_Issue.Value = -1;
            app.Report_Type = uidropdown(app.GridLayout21);
            app.Report_Type.Items = {};
            app.Report_Type.Value = {};
            app.Report_TypeLabel = uilabel(app.GridLayout21);
            app.Report_TypeLabel.Text = 'Modelo:';
            app.Report_NewProject_2 = uibutton(app.GridLayout21, 'push');
            app.Report_NewProject_2.Icon = 'LT_attach0.png';

            app.Report_NewProject_2.Tooltip = {'Arquivos externos'};
            app.Report_NewProject_2.Text = '';
            app.Report_Version = uidropdown(app.GridLayout21);
            app.Report_Version.Items = {'Preliminar', 'Definitiva'};
            app.Report_Version.Value = 'Preliminar';
            app.Report_VersionLabel = uilabel(app.GridLayout21);
            app.Report_VersionLabel.Text = 'Versão:';
            app.RelatrioLabel = uilabel(app.GridLayout7);
            app.RelatrioLabel.Text = 'Relatório:';
            app.ProjectFilename = uitextarea(app.GridLayout7);
            app.ProjectFilename.Editable = 'off';
            app.Report_NewProject = uibutton(app.GridLayout7, 'push');
            app.Report_NewProject.Icon = 'LT_AddFiles.png';
            app.Report_NewProject.Tooltip = {'Novo projeto'};
            app.Report_NewProject.Text = '';
            app.GridLayout22 = uigridlayout(app.report_ControlsTab1Info);
            app.Report_Sublabel3 = uilabel(app.GridLayout22);
            app.Report_Sublabel3.Text = 'Fluxo(s) de dados a processar';
            app.Report_addButton = uiimage(app.GridLayout22);
            app.Report_addButton.Enable = 'off';
            app.Report_addButton.Tooltip = {''};
            app.Report_addButton.ImageSource = 'LT_Add.png';
            app.report_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab2Label = uilabel(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Label.Text = 'ALGORITMOS';
            app.report_ControlsTab2Image = uiimage(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.report_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.Panel_39 = uipanel(app.report_ControlsTab2Info);
            app.GridLayout18 = uigridlayout(app.Panel_39);
            app.Report_occMethodLabel = uilabel(app.GridLayout18);
            app.Report_occMethodLabel.Text = 'Método:';
            app.Report_occMethod = uieditfield(app.GridLayout18, 'text');
            app.Report_occMethod.Editable = 'off';
            app.Report_occButton = uibutton(app.GridLayout18, 'push');
            app.Report_occButton.Icon = 'LT_edit.png';
            app.Report_occButton.Enable = 'off';
            app.Report_occButton.Tooltip = {''};
            app.Report_occButton.Text = '';
            app.Report_occParameters = uitextarea(app.GridLayout18);
            app.Report_occParameters.Editable = 'off';
            app.Report_Sublabel5_2 = uilabel(app.report_ControlsTab2Info);
            app.Report_Sublabel5_2.Text = 'Detecção de emissões';
            app.Panel_40 = uipanel(app.report_ControlsTab2Info);
            app.GridLayout16 = uigridlayout(app.Panel_40);
            app.Report_BandPanel = uipanel(app.GridLayout16);
            app.Report_BandGrid = uigridlayout(app.Report_BandPanel);
            app.Report_BandLim1 = uispinner(app.Report_BandGrid);
            app.Report_BandLim1.Step = 0.001;
            app.Report_BandLim1.Limits = [-1 -0.999];
            app.Report_BandLim1.ValueDisplayFormat = '%.3f';
            app.Report_BandLim1.Tag = 'FreqStart';
            app.Report_BandLim1.Enable = 'off';
            app.Report_BandLim1.Tooltip = {''};
            app.Report_BandLim1.Value = -1;
            app.Report_BandSliderLabel = uilabel(app.Report_BandGrid);
            app.Report_BandSliderLabel.Enable = 'off';
            app.Report_BandSliderLabel.Text = 'x';
            app.Report_BandLim2 = uispinner(app.Report_BandGrid);
            app.Report_BandLim2.Step = 0.001;
            app.Report_BandLim2.Limits = [-1 -0.999];
            app.Report_BandLim2.ValueDisplayFormat = '%.3f';
            app.Report_BandLim2.Tag = 'FreqStop';
            app.Report_BandLim2.Enable = 'off';
            app.Report_BandLim2.Tooltip = {''};
            app.Report_BandLim2.Value = -1;
            app.Report_DetectionParameters = uitextarea(app.GridLayout16);
            app.Report_DetectionParameters.Tag = 'Detection';
            app.Report_DetectionParameters.Editable = 'off';
            app.Report_PlaybackOnly = uicheckbox(app.GridLayout16);
            app.Report_PlaybackOnly.Enable = 'off';
            app.Report_PlaybackOnly.Text = 'Restringir emissões às identificadas no modo PLAYBACK.';
            app.Report_BandPanelLabel = uilabel(app.GridLayout16);
            app.Report_BandPanelLabel.Text = 'Faixa sob análise:';
            app.Report_DetectionLabel = uilabel(app.GridLayout16);
            app.Report_DetectionLabel.Text = 'Algoritmo:';
            app.Report_Detection = uieditfield(app.GridLayout16, 'text');
            app.Report_Detection.Tag = 'Detection';
            app.Report_Detection.Editable = 'off';
            app.Report_DetectionButton = uibutton(app.GridLayout16, 'push');
            app.Report_DetectionButton.Tag = 'Detection';
            app.Report_DetectionButton.Icon = 'LT_edit.png';
            app.Report_DetectionButton.Enable = 'off';
            app.Report_DetectionButton.Tooltip = {''};
            app.Report_DetectionButton.Text = '';
            app.Report_Sublabel6 = uilabel(app.report_ControlsTab2Info);
            app.Report_Sublabel6.Text = 'Classificação de emissões';
            app.Panel_41 = uipanel(app.report_ControlsTab2Info);
            app.GridLayout20 = uigridlayout(app.Panel_41);
            app.Report_ClassificationLabel = uilabel(app.GridLayout20);
            app.Report_ClassificationLabel.Text = 'Algoritmo:';
            app.Report_Classification = uieditfield(app.GridLayout20, 'text');
            app.Report_Classification.Editable = 'off';
            app.Report_ClassificationButton = uibutton(app.GridLayout20, 'push');
            app.Report_ClassificationButton.Icon = 'LT_edit.png';
            app.Report_ClassificationButton.Enable = 'off';
            app.Report_ClassificationButton.Tooltip = {''};
            app.Report_ClassificationButton.Text = '';
            app.Report_ClassificationParameters = uitextarea(app.GridLayout20);
            app.Report_ClassificationParameters.Editable = 'off';
            app.Report_Sublabel5 = uilabel(app.report_ControlsTab2Info);
            app.Report_Sublabel5.Text = 'Ocupação';
            app.report_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab3Label = uilabel(app.report_ControlsTab3Grid);
            app.report_ControlsTab3Label.Text = 'FISCALIZA';
            app.report_ControlsTab3Image = uiimage(app.report_ControlsTab3Grid);
            app.report_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_redmine.png');
            app.misc_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab1Label = uilabel(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Label.Text = 'LOCAL DA MONITORAÇÃO';
            app.misc_ControlsTab1Image = uiimage(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Instr_Global.png');
            app.misc_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab2Label = uilabel(app.misc_ControlsTab2Grid);
            app.misc_ControlsTab2Label.Text = 'MESCLAR / DUPLICAR / EXCLUIR';
            app.misc_ControlsTab2Image = uiimage(app.misc_ControlsTab2Grid);
            app.misc_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'merge_icon_135411.png');
            app.misc_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab3Label = uilabel(app.misc_ControlsTab3Grid);
            app.misc_ControlsTab3Label.Text = 'UNIDADE DE MEDIDA';
            app.misc_ControlsTab3Image = uiimage(app.misc_ControlsTab3Grid);
            app.misc_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_link.png');
            app.misc_ControlsTab4Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab4Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab4Label = uilabel(app.misc_ControlsTab4Grid);
            app.misc_ControlsTab4Label.Text = 'FLUXO DE OCUPAÇÃO';
            app.misc_ControlsTab4Image = uiimage(app.misc_ControlsTab4Grid);
            app.misc_ControlsTab4Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab4Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_OCC.png');
            app.misc_ControlsTab5Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab5Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab5Label = uilabel(app.misc_ControlsTab5Grid);
            app.misc_ControlsTab5Label.Text = 'EXPORTAR (ARQUIVO / RELATÓRIO BÁSICO)';
            app.misc_ControlsTab5Image = uiimage(app.misc_ControlsTab5Grid);
            app.misc_ControlsTab5Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab5Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Export_24.png');
            app.misc_ControlsTab6Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab6Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab6Label = uilabel(app.misc_ControlsTab6Grid);
            app.misc_ControlsTab6Label.Text = 'MÁSCARA ESPECTRAL';
            app.misc_ControlsTab6Image = uiimage(app.misc_ControlsTab6Grid);
            app.misc_ControlsTab6Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.misc_ControlsTab6Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_mask.png');
            app.menu_Panel = uipanel(app.GridLayout);
            app.menu_Grid = uigridlayout(app.menu_Panel);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_ButtonModePushed, true);
            app.menu_Button1.Tag = 'HOME';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_home.png');
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_ButtonModePushed, true);
            app.menu_Button2.Tag = 'FILE';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_OpenFile.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_ButtonModePushed, true);
            app.menu_Button3.Tag = 'PLAYBACK';
            app.menu_Button3.Enable = 'off';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_playback.png');
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_ButtonModePushed, true);
            app.menu_Button4.Tag = 'REPORT';
            app.menu_Button4.Enable = 'off';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_report.png');
            app.menu_Button4.Text = '';
            app.menu_Button5 = uibutton(app.menu_Grid, 'state');
            app.menu_Button5.ValueChangedFcn = createCallbackFcn(app, @menu_ButtonModePushed, true);
            app.menu_Button5.Tag = 'MISC';
            app.menu_Button5.Enable = 'off';
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_misc.png');
            app.menu_Button5.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button6 = uibutton(app.menu_Grid, 'push');
            app.menu_Button6.ButtonPushedFcn = createCallbackFcn(app, @menu_ButtonExternalAppPushed, true);
            app.menu_Button6.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_template.png');
            app.menu_Button6.Tooltip = {''};
            app.menu_Button6.Text = '';
            app.menu_Button7 = uibutton(app.menu_Grid, 'push');
            app.menu_Button7.ButtonPushedFcn = createCallbackFcn(app, @menu_ButtonExternalAppPushed, true);
            app.menu_Button7.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_redmine.png');
            app.menu_Button7.Tooltip = {''};
            app.menu_Button7.Text = '';
            app.menu_Button8 = uibutton(app.menu_Grid, 'push');
            app.menu_Button8.ButtonPushedFcn = createCallbackFcn(app, @menu_ButtonExternalAppPushed, true);
            app.menu_Button8.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_mosaic.png');
            app.menu_Button8.Tooltip = {''};
            app.menu_Button8.Text = '';
            app.menu_userPathGrid = uigridlayout(app.menu_Grid);
            app.menu_userPath = uidropdown(app.menu_userPathGrid);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @menu_userPathValueChanged, true);
            app.menu_userPath.Value = 'Path';
            app.menu_searchPath = uiimage(app.menu_Grid);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @menu_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_zoom.png');
            app.menu_Position = uiimage(app.menu_Grid);
            app.menu_Position.ImageClickedFcn = createCallbackFcn(app, @menu_PositionImageClicked, true);
            app.menu_Position.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_layout1.png');
            app.menu_Settings = uiimage(app.menu_Grid);
            app.menu_Settings.ImageClickedFcn = createCallbackFcn(app, @menu_ButtonExternalAppPushed, true);
            app.menu_Settings.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_Dots.png');
            app.file_ContextMenu_Tree1 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree1Node = uimenu(app.file_ContextMenu_Tree1);
            app.file_ContextMenu_delTree1Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree1NodeSelected, true);
            app.file_ContextMenu_delTree1Node.Text = 'Excluir';
            app.file_ContextMenu_Tree2 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree2Node = uimenu(app.file_ContextMenu_Tree2);
            app.file_ContextMenu_delTree2Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree2NodeSelected, true);
            app.file_ContextMenu_delTree2Node.Text = 'Excluir';
            app.play_FindPeaks_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_FindPeaks_ContextMenu_Drive = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_Drive.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_DriveTest, true);
            app.play_FindPeaks_ContextMenu_Drive.Text = 'Drive-test';
            app.play_FindPeaks_ContextMenu_search = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_search.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_Classification, true);
            app.play_FindPeaks_ContextMenu_search.Text = 'Consultar';
            app.play_FindPeaks_ContextMenu_edit = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_edit.Text = 'Editar';
            app.play_FindPeaks_ContextMenu_analog = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_analog.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_analog.Text = 'Não truncar';
            app.play_FindPeaks_ContextMenu_digital = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_digital.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_digital.Text = 'Truncar frequência';
            app.play_FindPeaks_ContextMenu_del = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_delEmission, true);
            app.play_FindPeaks_ContextMenu_del.Text = 'Excluir';
            app.play_Channel_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_Channel_ContextMenu_del = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_delChannel, true);
            app.play_Channel_ContextMenu_del.Text = 'Excluir';
            app.report_ContextMenu = uicontextmenu(app.UIFigure);
            app.report_ContextMenu_del = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_del.Separator = 'on';
            app.report_ContextMenu_del.Text = 'Excluir';
            app.report_ContextMenu_ExternalFiles = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_ExternalFiles.Text = 'Arquivos externos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppAnalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end