classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        VariablesLabel    matlab.ui.control.Label
        InfoLabel         matlab.ui.control.Label
        Button2           matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        Grid2             matlab.ui.container.GridLayout
        Variables_Label1  matlab.ui.control.Label
        Variables_Label2  matlab.ui.control.Label
        Variables_Label3  matlab.ui.control.Label
        CheckBox7         matlab.ui.control.CheckBox
        CheckBox6         matlab.ui.control.CheckBox
        CheckBox5         matlab.ui.control.CheckBox
        CheckBox4         matlab.ui.control.CheckBox
        CheckBox3         matlab.ui.control.CheckBox
        CheckBox2         matlab.ui.control.CheckBox
        CheckBox1         matlab.ui.control.CheckBox
        Button1           matlab.ui.control.Button
        Info              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, RootFolder)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            [MatlabVersion, ReleaseDate] = version;
            MatlabRoot                   = matlabroot;
            InstalledProducts            = ver;
            MatlabProducts = {};
            for ii = 1:numel(InstalledProducts)
                MatlabProducts = [MatlabProducts; sprintf('%s v. %s', InstalledProducts(ii).Name, InstalledProducts(ii).Version)];
            end
            
            Python = pyenv;
            
            % inserir relação de toolboxes que precisam.
            % Verificar versão da biblioteca do Fiscaliza.
            
            global AnatelDB_ExtractDate
            if isempty(AnatelDB_ExtractDate)
                Misc_DataBaseRead(RootFolder)
            end
            
            app.Info.Value = sprintf(['appAnálise\n• v. 0.99\n• %s\n\n'                    ...
                                      'Matlab\n• v. %s (Release date: %s)\n• %s\n• %s\n\n' ...
                                      'Python\n• v. %s\n• %s\n• %s\n• %s\n\n'              ...
                                      'Base de dados offline da Agência (Mosaico,  SRD e STEL) atualizada no dia %s.'], ...
                                      RootFolder,     ...
                                      MatlabVersion,  ...
                                      ReleaseDate,    ...
                                      strjoin(MatlabProducts, ', '), ...
                                      MatlabRoot,     ...
                                      Python.Version, ...
                                      Python.Home,    ...
                                      Python.Library, ...
                                      Python.Executable, ...
                                      AnatelDB_ExtractDate);
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'Samples', app.CallingApp.Samples)
                openvar('Samples')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'traceInfo', app.CallingApp.traceInfo)
                openvar('traceInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
            if app.CheckBox7.Value
                assignin('base', 'reportInfo', app.CallingApp.reportInfo)
                openvar('reportInfo')
            end
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5, CheckBox6, CheckBox7
        function CheckBox1ValueChanged(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value || app.CheckBox7.Value
                app.Button2.Enable = 1;
            else
                app.Button2.Enable = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Info = uitextarea(app.Grid1);
            app.Info.Value = {'General info...'};
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.Icon = 'refreshWhite.png';
            app.Button1.Enable = 'off';
            app.Button1.Text = 'Atualizar';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'metaData';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = 'Samples';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = 'specData';
            app.CheckBox3.Value = true;
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = 'traceInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = 'peaksTable';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = 'exceptionList';
            app.CheckBox7 = uicheckbox(app.Grid2);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = 'reportInfo';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas à funcionalidade "Relatório":';
            app.Variables_Label2 = uilabel(app.Grid2);
            app.Variables_Label2.Text = 'Relacionadas à funcionalidade "Playback":';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas à leitura dos arquivos:';
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'Media_PlayWhite.png';
            app.Button2.Text = 'Explorar dados';
            app.InfoLabel = uilabel(app.Grid1);
            app.InfoLabel.Text = 'Informações gerais';
            app.VariablesLabel = uilabel(app.Grid1);
            app.VariablesLabel.Text = 'Visualização das principais variáveis do appAnálise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end